classdef lockinConnect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConnectDeviceUIFigure          matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DeviceTab                      matlab.ui.container.Tab
        LockinAmplifierModelButtonGroup  matlab.ui.container.ButtonGroup
        SanfordResearchSystemModelSR865Button  matlab.ui.control.RadioButton
        SanfordResearchSystemModelSR860Button  matlab.ui.control.RadioButton
        SanfordResearchSystemModelSR830Button  matlab.ui.control.RadioButton
        SanfordResearchSystemModelSR530Button  matlab.ui.control.RadioButton
        SerialTab                      matlab.ui.container.Tab
        StopBitsDropDown               matlab.ui.control.DropDown
        StopBitsDropDownLabel          matlab.ui.control.Label
        ConnectDeviceSerialPortButton  matlab.ui.control.Button
        TerminatorDropDown             matlab.ui.control.DropDown
        TerminatorDropDownLabel        matlab.ui.control.Label
        ParityDropDown                 matlab.ui.control.DropDown
        ParityDropDownLabel            matlab.ui.control.Label
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        PortDropDown                   matlab.ui.control.DropDown
        PortDropDownLabel              matlab.ui.control.Label
        GPIBTab                        matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        ConnectDeviceGPIBButton        matlab.ui.control.Button
        USBTab                         matlab.ui.container.Tab
        DescriptionTextArea            matlab.ui.control.TextArea
        DescriptionTextAreaLabel       matlab.ui.control.Label
        ConnectDeviceUSBButton         matlab.ui.control.Button
        ResourceNameDropDown           matlab.ui.control.DropDown
        ResourceNameDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        isLockinAppOpen;
        lockinModelSelected;
    end
    
    methods (Access = private)
        
        %--------------------------------
        % FUNCTION:
        function lockinSelection(app)

            % Resource List (Serial Ports, GPIB, ...)
            deviceList = visadevlist;

            % Check if Serial Ports Available
            serialPortList = cellstr(seriallist());
            if ~isempty(serialPortList)
                app.PortDropDown.Items = cellstr(seriallist());
                app.PortDropDown.Value = app.PortDropDown.Items{1};
                conditionSerialPort = true;
            else
                conditionSerialPort = false;
            end
            app.PortDropDown.Enable = conditionSerialPort;
            app.PortDropDownLabel.Enable = conditionSerialPort;
            app.BaudrateDropDown.Enable = conditionSerialPort;
            app.BaudrateDropDownLabel.Enable = conditionSerialPort;
            app.ParityDropDown.Enable = conditionSerialPort;
            app.ParityDropDownLabel.Enable = conditionSerialPort;
            app.StopBitsDropDown.Enable = conditionSerialPort;
            app.StopBitsDropDownLabel.Enable = conditionSerialPort;
            app.TerminatorDropDown.Enable = conditionSerialPort;
            app.TerminatorDropDownLabel.Enable = conditionSerialPort;
            app.ConnectDeviceSerialPortButton.Enable = conditionSerialPort;
            
            % Set Serial Options
            if app.SanfordResearchSystemModelSR530Button.Value
                % Serial Port Baudrate
                app.BaudrateDropDown.Items = {'300' '600' '1200' '240' '4800' '9600' '19200'};
                app.BaudrateDropDown.Value = '19200';
                
                % Serial Port Parity
                app.ParityDropDown.Items = {'none' 'odd' 'even'};
                app.ParityDropDown.Value = 'none';
                
                % Serial Port Terminator
                app.TerminatorDropDown.Items = {'CR' 'LF'};
                app.TerminatorDropDown.Value = 'CR';

                % Serial Port Stop Bits
                app.StopBitsDropDown.Items = {'1' '2'};
                app.StopBitsDropDown.Value = '2';
                app.StopBitsDropDown.Enable = conditionSerialPort;
                app.StopBitsDropDownLabel.Enable = conditionSerialPort;
                
            elseif app.SanfordResearchSystemModelSR830Button.Value
                % Serial Port Baudrate
                app.BaudrateDropDown.Items = {'300' '600' '1200' '240' '4800' '9600' '19200'};
                app.BaudrateDropDown.Value = '19200';
                
                % Serial Port Parity
                app.ParityDropDown.Items = {'none' 'odd' 'even'};
                app.ParityDropDown.Value = 'none';
                
                % Serial Port Terminator
                app.TerminatorDropDown.Items = {'CR' 'LF'};
                app.TerminatorDropDown.Value = 'CR';

                % Serial Port Stop Bits
                app.StopBitsDropDown.Items = {'1'};
                app.StopBitsDropDown.Value = '1';
                app.StopBitsDropDown.Enable = false;
                app.StopBitsDropDownLabel.Enable = false;
            
            elseif app.SanfordResearchSystemModelSR860Button.Value || app.SanfordResearchSystemModelSR865Button.Value
                % Serial Port Baudrate
                app.BaudrateDropDown.Items = {'300' '600' '1200' '240' '4800' '9600' '19200' '38400' '115200' '230400' '460800'};
                app.BaudrateDropDown.Value = '19200';
                
                % Serial Port Parity
                app.ParityDropDown.Items = {'none' 'odd' 'even'};
                app.ParityDropDown.Value = 'none';
                
                % Serial Port Terminator
                app.TerminatorDropDown.Items = {'CR' 'LF' 'CR/LF' 'LF/CR'};
                app.TerminatorDropDown.Value = 'LF';

                % Serial Port Stop Bits
                app.StopBitsDropDown.Items = {'1'};
                app.StopBitsDropDown.Value = '1';
                app.StopBitsDropDown.Enable = false;
                app.StopBitsDropDownLabel.Enable = false;
                
            end

            % Check if GPIB Ports are Available
                % Filter out GPIB connections
                deviceListNr = size(deviceList,1);
                gpibList = {};
                for i = 1:deviceListNr
                    if strcmpi(deviceList.Type, 'gpib')
                        gpibList(i,:) = deviceList(:,i);
                    end
                end

                % 


            % Check if USB Resource Source are available
            switch lower(app.lockinModelSelected)
                case {'sr860' 'sr865'}
                    visaUSB = instrfind('Type', 'visa-usb');
                    resourceNames = cellstr(visaUSB.RsrcName);
                    
                    if ~isempty(resourceNames)
                        app.ResourceNameDropDown.Items = resourceNames;
                        app.ResourceNameDropDown.Value = app.ResourceNameDropDown.Items{1};
        
                        conditionUSB = true;
                    else
                        conditionUSB = false;
                    end
                    app.ResourceNameDropDown.Enable = conditionUSB;
                    app.ResourceNameDropDownLabel.Enable = conditionUSB;
                    app.DescriptionTextArea.Enable = conditionUSB;
                    app.DeviceAddressEditFieldLabel.Enable = conditionUSB;
                    app.ConnectDeviceUSBButton.Enable = conditionUSB;
            end
            
        end
        
        %--------------------------------
        % FUNCTION:
        function gpibVendorSelection(app)

            % Check if GPIB is available
            switch lower(app.VendorDropDown.Value)
                case {'ni' 'national instruments'}
                    gpibVendor = 'ni';
                    
                case {'keysight' 'agilent'}
                    gpibVendor = 'keysight';
                    
                case 'ics'
                    gpibVendor = 'ics';
                    
                case 'mcc'
                    gpibVendor = 'mcc';
                    
                case 'adlink'
                    gpibVendor = 'adlink';
            end
            
            gpibOptions = instrhwinfo('gpib',gpibVendor);
            
            if ~isempty(gpibOptions.InstalledBoardIds)
                conditionGPIB = true;
            else
                conditionGPIB = false;
            end
            app.DeviceAddressEditField.Enable = conditionGPIB;
            app.DeviceAddressEditFieldLabel.Enable = conditionGPIB;
            app.ConnectDeviceGPIBButton.Enable = conditionGPIB;
        end
    
        %--------------------------------
        % FUNCTION:
        function USBResoureName(app)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, lockinModel)
            
            % Store main app in property for CloseRequestFcn to use
            if nargin > 2
                app.CallingApp = mainapp;
                
                app.isLockinAppOpen = true;
                
                % Lock-in Amplifier Model Options
                switch lower(lockinModel)
                    case 'sr530'
                        % Lock-in Tab Options
                        app.SanfordResearchSystemModelSR530Button.Value = true;
                        app.SanfordResearchSystemModelSR530Button.Enable = true;
                        app.lockinModelSelected = lockinModel;
                        
                    case 'sr830'
                        % Lock-in Tab Options
                        app.SanfordResearchSystemModelSR830Button.Value = true;
                        app.SanfordResearchSystemModelSR830Button.Enable = true;
                        app.lockinModelSelected = lockinModel;
                    
                    case 'sr860'
                        % Lock-in Tab Options
                        app.SanfordResearchSystemModelSR860Button.Value = true;
                        app.SanfordResearchSystemModelSR860Button.Enable = true;
                        app.lockinModelSelected = lockinModel;
                        
                    case 'sr865'
                        % Lock-in Tab Options
                        app.SanfordResearchSystemModelSR865Button.Value = true;
                        app.SanfordResearchSystemModelSR865Button.Enable = true;
                        app.lockinModelSelected = lockinModel;
                end
                
                app.lockinSelection();
                
            else
                app.isLockinAppOpen = false;
                
                % Lock-in Tab Options
                app.SanfordResearchSystemModelSR530Button.Value = false;
                app.SanfordResearchSystemModelSR530Button.Enable = true;
                app.SanfordResearchSystemModelSR830Button.Value = true;
                app.SanfordResearchSystemModelSR830Button.Enable = true;
                app.SanfordResearchSystemModelSR860Button.Value = false;
                app.SanfordResearchSystemModelSR860Button.Enable = true;
                app.SanfordResearchSystemModelSR865Button.Value = false;
                app.SanfordResearchSystemModelSR865Button.Enable = true;
                
                app.lockinModelSelected = 'sr830';
                
                app.lockinSelection();

            end

        end

        % Button pushed function: ConnectDeviceSerialPortButton
        function ConnectDeviceSerialPortButtonPushed(app, event)
            
            port = app.PortDropDown.Value;
            baudrate = str2double(app.BaudrateDropDown.Value);
            parity = app.ParityDropDown.Value;
            stopBits = str2double(app.StopBitsDropDown.Value);
            terminator = app.TerminatorDropDown.Value;
            
            if app.isLockinAppOpen
                connect(app.CallingApp,'SerialPort','Port',port,'Baudrate',baudrate,'Parity',parity,'StopBits',stopBits,'Terminator',terminator);
                
            else
                
                if app.SanfordResearchSystemModelSR530Button.Value
                    lockin = lockinSR530();
                elseif app.SanfordResearchSystemModelSR830Button.Value
                    lockin = lockinSR830();
                elseif app.SanfordResearchSystemModelSR860Button.Value
                    lockin = lockinSR860();
                elseif app.SanfordResearchSystemModelSR865Button.Value
                    lockin = lockinSR865();
                end

                lockin.connect('SerialPort','Port',port,'Baudrate',baudrate,'Parity',parity,'StopBits',stopBits,'Terminator',terminator);
                
            end
            
            % Delete App
            delete(app);
            
        end

        % Close request function: ConnectDeviceUIFigure
        function ConnectDeviceUIFigureCloseRequest(app, event)
            
            delete(app);
            
        end

        % Selection changed function: LockinAmplifierModelButtonGroup
        function LockinAmplifierModelButtonGroupSelectionChanged(app, event)
            
            if app.SanfordResearchSystemModelSR530Button.Value
                app.lockinModelSelected = 'sr530';
            elseif app.SanfordResearchSystemModelSR830Button.Value
                app.lockinModelSelected = 'sr830';
            elseif app.SanfordResearchSystemModelSR860Button.Value
                app.lockinModelSelected = 'sr860';
            elseif app.SanfordResearchSystemModelSR865Button.Value
                app.lockinModelSelected = 'sr865';
            end
            
            app.lockinSelection();

        end

        % Button pushed function: ConnectDeviceGPIBButton
        function ConnectDeviceGPIBButtonPushed(app, event)
            
            vendor = app.VendorDropDown.Value;
            primaryAddress = app.DeviceAddressEditField.Value;
            
            if app.isLockinAppOpen
                connect(app.CallingApp,'GPIB','Vendor',vendor,'PrimaryAddress',primaryAddress);
            else
                
                if app.SanfordResearchSystemModelSR530Button.Value
                    lockin = lockinSR530();
                elseif app.SanfordResearchSystemModelSR830Button.Value
                    lockin = lockinSR830();
                elseif app.SanfordResearchSystemModelSR860Button.Value
                    lockin = lockinSR860();
                elseif app.SanfordResearchSystemModelSR865Button.Value
                    lockin = lockinSR865();
                end

                lockin.connect('GPIB','Vendor',vendor,'PrimaryAddress',primaryAddress);
            end
            
            % Delete App
            delete(app);
            
        end

        % Button pushed function: ConnectDeviceUSBButton
        function ConnectDeviceUSBButtonPushed(app, event)
            
            resourcename = app.ResourceNameDropDown.Value;
            
            if app.isLockinAppOpen
                connect(app.CallingApp,'USB','resourcename',resourcename);
            else
                
                if app.SanfordResearchSystemModelSR530Button.Value
                    lockin = lockinSR530();
                elseif app.SanfordResearchSystemModelSR830Button.Value
                    lockin = lockinSR830();
                elseif app.SanfordResearchSystemModelSR860Button.Value
                    lockin = lockinSR860();
                elseif app.SanfordResearchSystemModelSR865Button.Value
                    lockin = lockinSR865();
                end

                lockin.connect('USB','resourcename',resourcename);
                
            end
            
            % Delete App
            delete(app);
            
        end

        % Callback function: not associated with a component
        function VendorDropDownValueChanged(app, event)
            
            app.gpibVendorSelection();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConnectDeviceUIFigure = uifigure('Visible', 'off');
            app.ConnectDeviceUIFigure.Name = 'Connect Device';
            app.ConnectDeviceUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConnectDeviceUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ConnectDeviceUIFigure);
            app.DeviceTab = uitab(app.TabGroup);
            app.DeviceTab.Title = 'Device';
            app.LockinAmplifierModelButtonGroup = uibuttongroup(app.DeviceTab);
            app.LockinAmplifierModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LockinAmplifierModelButtonGroupSelectionChanged, true);
            app.LockinAmplifierModelButtonGroup.Title = 'Lock-in Amplifier Model';
            app.SanfordResearchSystemModelSR530Button = uiradiobutton(app.LockinAmplifierModelButtonGroup);
            app.SanfordResearchSystemModelSR530Button.Enable = 'off';
            app.SanfordResearchSystemModelSR530Button.Text = 'Sanford Research System Model SR530';
            app.SanfordResearchSystemModelSR530Button.Value = true;
            app.SanfordResearchSystemModelSR830Button = uiradiobutton(app.LockinAmplifierModelButtonGroup);
            app.SanfordResearchSystemModelSR830Button.Enable = 'off';
            app.SanfordResearchSystemModelSR830Button.Text = 'Sanford Research System Model SR830';
            app.SanfordResearchSystemModelSR860Button = uiradiobutton(app.LockinAmplifierModelButtonGroup);
            app.SanfordResearchSystemModelSR860Button.Enable = 'off';
            app.SanfordResearchSystemModelSR860Button.Text = 'Sanford Research System Model SR860';
            app.SanfordResearchSystemModelSR865Button = uiradiobutton(app.LockinAmplifierModelButtonGroup);
            app.SanfordResearchSystemModelSR865Button.Enable = 'off';
            app.SanfordResearchSystemModelSR865Button.Text = 'Sanford Research System Model SR865';
            app.SerialTab = uitab(app.TabGroup);
            app.SerialTab.Title = 'Serial';
            app.PortDropDownLabel = uilabel(app.SerialTab);
            app.PortDropDownLabel.Enable = 'off';
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.SerialTab);
            app.PortDropDown.Items = {'none'};
            app.PortDropDown.Enable = 'off';
            app.PortDropDown.Value = 'none';
            app.BaudrateDropDownLabel = uilabel(app.SerialTab);
            app.BaudrateDropDownLabel.Enable = 'off';
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.SerialTab);
            app.BaudrateDropDown.Items = {'none'};
            app.BaudrateDropDown.Enable = 'off';
            app.BaudrateDropDown.Value = 'none';
            app.ParityDropDownLabel = uilabel(app.SerialTab);
            app.ParityDropDownLabel.Enable = 'off';
            app.ParityDropDownLabel.Text = 'Parity';
            app.ParityDropDown = uidropdown(app.SerialTab);
            app.ParityDropDown.Items = {'none'};
            app.ParityDropDown.Enable = 'off';
            app.ParityDropDown.Value = 'none';
            app.TerminatorDropDownLabel = uilabel(app.SerialTab);
            app.TerminatorDropDownLabel.Enable = 'off';
            app.TerminatorDropDownLabel.Text = 'Terminator';
            app.TerminatorDropDown = uidropdown(app.SerialTab);
            app.TerminatorDropDown.Items = {'none'};
            app.TerminatorDropDown.Enable = 'off';
            app.TerminatorDropDown.Value = 'none';
            app.ConnectDeviceSerialPortButton = uibutton(app.SerialTab, 'push');
            app.ConnectDeviceSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDeviceSerialPortButtonPushed, true);
            app.ConnectDeviceSerialPortButton.Enable = 'off';
            app.ConnectDeviceSerialPortButton.Text = {'Connect'; 'Device'};
            app.StopBitsDropDownLabel = uilabel(app.SerialTab);
            app.StopBitsDropDownLabel.Enable = 'off';
            app.StopBitsDropDownLabel.Text = 'Stop Bits';
            app.StopBitsDropDown = uidropdown(app.SerialTab);
            app.StopBitsDropDown.Items = {'none'};
            app.StopBitsDropDown.Enable = 'off';
            app.StopBitsDropDown.Value = 'none';
            app.GPIBTab = uitab(app.TabGroup);
            app.GPIBTab.Title = 'GPIB';
            app.ConnectDeviceGPIBButton = uibutton(app.GPIBTab, 'push');
            app.ConnectDeviceGPIBButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDeviceGPIBButtonPushed, true);
            app.ConnectDeviceGPIBButton.Enable = 'off';
            app.ConnectDeviceGPIBButton.Text = {'Connect'; 'Device'};
            app.UITable = uitable(app.GPIBTab);
            app.UITable.ColumnName = {'Address'; 'Model'; 'Serial Number'; ''};
            app.UITable.RowName = {};
            app.USBTab = uitab(app.TabGroup);
            app.USBTab.Title = 'USB';
            app.ResourceNameDropDownLabel = uilabel(app.USBTab);
            app.ResourceNameDropDownLabel.Enable = 'off';
            app.ResourceNameDropDownLabel.Text = 'Resource Name';
            app.ResourceNameDropDown = uidropdown(app.USBTab);
            app.ResourceNameDropDown.Items = {'none'};
            app.ResourceNameDropDown.Enable = 'off';
            app.ResourceNameDropDown.Value = 'none';
            app.ConnectDeviceUSBButton = uibutton(app.USBTab, 'push');
            app.ConnectDeviceUSBButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDeviceUSBButtonPushed, true);
            app.ConnectDeviceUSBButton.Enable = 'off';
            app.ConnectDeviceUSBButton.Text = {'Connect'; 'Device'};
            app.DescriptionTextAreaLabel = uilabel(app.USBTab);
            app.DescriptionTextAreaLabel.Enable = 'off';
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.USBTab);
            app.DescriptionTextArea.Editable = 'off';
            app.DescriptionTextArea.Enable = 'off';
            % Show the figure after all components are created
            app.ConnectDeviceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lockinConnect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConnectDeviceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConnectDeviceUIFigure)
        end
    end
end