classdef lockinSR830 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StanfordResearchSystemsModelSR830UIFigure  matlab.ui.Figure
        TimeConstantPanel              matlab.ui.container.Panel
        TimeConstantDropDownLabel      matlab.ui.control.Label
        TimeConstantDropDown           matlab.ui.control.DropDown
        LowPassFilterDropDownLabel     matlab.ui.control.Label
        LowPassFilterDropDown          matlab.ui.control.DropDown
        Sync200HzCheckBox              matlab.ui.control.CheckBox
        OverloadLamp_2Label            matlab.ui.control.Label
        TimeConstantOverloadLamp       matlab.ui.control.Lamp
        SignalInputPanel               matlab.ui.container.Panel
        OverloadLamp_3Label            matlab.ui.control.Label
        SignalInputOverloadLamp        matlab.ui.control.Lamp
        InputButtonGroup               matlab.ui.container.ButtonGroup
        AButton                        matlab.ui.control.RadioButton
        ABButton                       matlab.ui.control.RadioButton
        I106Button                     matlab.ui.control.RadioButton
        I108Button                     matlab.ui.control.RadioButton
        CouplingButtonGroup            matlab.ui.container.ButtonGroup
        ACButton                       matlab.ui.control.RadioButton
        DCButton                       matlab.ui.control.RadioButton
        ShieldGroundingButtonGroup     matlab.ui.container.ButtonGroup
        FloatButton                    matlab.ui.control.RadioButton
        GroundButton                   matlab.ui.control.RadioButton
        SensitivityPanel               matlab.ui.container.Panel
        DropDown                       matlab.ui.control.DropDown
        OverloadLampLabel              matlab.ui.control.Label
        SensitivityOverloadLamp        matlab.ui.control.Lamp
        ReserveButtonGroup             matlab.ui.container.ButtonGroup
        HighReserveButton              matlab.ui.control.RadioButton
        NormalButton                   matlab.ui.control.RadioButton
        LowNoiseButton                 matlab.ui.control.RadioButton
        Channel1Panel                  matlab.ui.container.Panel
        DisplayCH1EditField            matlab.ui.control.NumericEditField
        DisplayButtonGroup             matlab.ui.container.ButtonGroup
        CH1XButton                     matlab.ui.control.RadioButton
        CH1RButton                     matlab.ui.control.RadioButton
        CH1XnoiseButton                matlab.ui.control.RadioButton
        CH1AuxIn1Button                matlab.ui.control.RadioButton
        CH1AuxIn2Button                matlab.ui.control.RadioButton
        RatioButtonGroup               matlab.ui.container.ButtonGroup
        Ch1RatioNoneButton             matlab.ui.control.RadioButton
        Ch1RatioAuxIn1Button           matlab.ui.control.RadioButton
        Ch1RatioAuxIn2Button           matlab.ui.control.RadioButton
        ExpandButtonGroup              matlab.ui.container.ButtonGroup
        CH1x1Button                    matlab.ui.control.RadioButton
        CH1x10Button                   matlab.ui.control.RadioButton
        CH1x100Button                  matlab.ui.control.RadioButton
        OutputCH1ButtonGroup           matlab.ui.container.ButtonGroup
        OutputCH1DisplayButton         matlab.ui.control.RadioButton
        OutputCH1XButton               matlab.ui.control.RadioButton
        OffsetButtonGroup              matlab.ui.container.ButtonGroup
        OffsetCH1OnButton              matlab.ui.control.RadioButton
        OffsetCH1OffButton             matlab.ui.control.RadioButton
        OffsetCH1AutoButton            matlab.ui.control.Button
        OffsetCH1ModifyEditFieldLabel  matlab.ui.control.Label
        OffsetCH1ModifyEditField       matlab.ui.control.NumericEditField
        FilterPanel                    matlab.ui.container.Panel
        LineCheckBox                   matlab.ui.control.CheckBox
        xLineCheckBox                  matlab.ui.control.CheckBox
        AutoPanel                      matlab.ui.container.Panel
        PhasButton                     matlab.ui.control.Button
        GainButton                     matlab.ui.control.Button
        ReserveButton                  matlab.ui.control.Button
        LampPhas                       matlab.ui.control.Lamp
        LampGain                       matlab.ui.control.Lamp
        LampReserve                    matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        AuxOutPanel                    matlab.ui.container.Panel
        Output2EditFieldLabel          matlab.ui.control.Label
        Output2EditField               matlab.ui.control.NumericEditField
        Output3EditFieldLabel          matlab.ui.control.Label
        Output3EditField               matlab.ui.control.NumericEditField
        Output4EditFieldLabel          matlab.ui.control.Label
        Output4EditField               matlab.ui.control.NumericEditField
        Output1EditFieldLabel          matlab.ui.control.Label
        Output1EditField               matlab.ui.control.NumericEditField
        ReferencePanel                 matlab.ui.container.Panel
        TriggerButtonGroup             matlab.ui.container.ButtonGroup
        SineButton                     matlab.ui.control.RadioButton
        PosEdgeButton                  matlab.ui.control.RadioButton
        NegEdgeButton                  matlab.ui.control.RadioButton
        SourceButtonGroup              matlab.ui.container.ButtonGroup
        InternalButton                 matlab.ui.control.RadioButton
        ExternalButton                 matlab.ui.control.RadioButton
        PhaseLabel                     matlab.ui.control.Label
        PhaseEditField                 matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel           matlab.ui.control.Label
        FreqHzEditField                matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel       matlab.ui.control.Label
        AmplitudeVEditField            matlab.ui.control.NumericEditField
        HarmonicEditFieldLabel         matlab.ui.control.Label
        HarmonicEditField              matlab.ui.control.NumericEditField
        Channel2Panel                  matlab.ui.container.Panel
        DisplayCH2EditField            matlab.ui.control.NumericEditField
        DisplayButtonGroup_2           matlab.ui.container.ButtonGroup
        CH2YButton                     matlab.ui.control.RadioButton
        CH2ThetaButton                 matlab.ui.control.RadioButton
        CH2YnoiseButton                matlab.ui.control.RadioButton
        CH2AuxIn3Button                matlab.ui.control.RadioButton
        CH2AuxIn4Button                matlab.ui.control.RadioButton
        CH2RatioButtonGroup            matlab.ui.container.ButtonGroup
        Ch2RatioNoneButton             matlab.ui.control.RadioButton
        Ch2RatioAuxIn3Button           matlab.ui.control.RadioButton
        Ch2RatioAuxIn4Button           matlab.ui.control.RadioButton
        ExpandCH1ButtonGroup           matlab.ui.container.ButtonGroup
        CH2x1Button                    matlab.ui.control.RadioButton
        CH2x10Button                   matlab.ui.control.RadioButton
        CH2x100Button                  matlab.ui.control.RadioButton
        OutputCH2ButtonGroup           matlab.ui.container.ButtonGroup
        OutputCH2DisplayButton         matlab.ui.control.RadioButton
        OutputCH2YButton               matlab.ui.control.RadioButton
        OffsetCH2ButtonGroup           matlab.ui.container.ButtonGroup
        OffsetCH2OnButton              matlab.ui.control.RadioButton
        OffsetCH2OffButton             matlab.ui.control.RadioButton
        OffsetCH2AutoButton            matlab.ui.control.Button
        OffsetCH2ModifyEditFieldLabel  matlab.ui.control.Label
        OffsetCH2ModifyEditField       matlab.ui.control.NumericEditField
        AquisitionPlotPanel            matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        CleanButton                    matlab.ui.control.Button
        PlotLeftDropDownLabel          matlab.ui.control.Label
        PlotLeftDropDown               matlab.ui.control.DropDown
        PlotRightDropDownLabel         matlab.ui.control.Label
        PlotRightDropDown              matlab.ui.control.DropDown
        IntervalsSpinnerLabel          matlab.ui.control.Label
        IntervalsSpinner               matlab.ui.control.Spinner
        TimesSpinnerLabel              matlab.ui.control.Label
        TimesSpinner                   matlab.ui.control.Spinner
        ForceUpdateButton              matlab.ui.control.Button
        DeviceIdendificationEditField  matlab.ui.control.EditField
        SaveConfigButton               matlab.ui.control.Button
        LoadConfigButton               matlab.ui.control.Button
        ConnectDeviceButton            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        
        % Time
        TIMEOUT = 1;                    % Communication time out.
        TIMEAQUISITIONINTERVAL = .3;    % Aquisition interval time (obtain data from device)
        TIMEAQUISITIONTOTAL = 60;       % Amount of time the data is displayed.
        
        %
        portInfo;                       % Device port information.
        isconnected = false;            % Flag if device connected.
        dataTimer;                      % Timer to retrive data from device.
        
        % Plot
        plotObject;
        plotDataAxisYLeft;
        plotDataAxisYRight;
        plotDataAxisX;
        frequencyUnlock;                % External Reference Frequency Unlock
        
        % External Apps
        lockinConnectApp;               % App to connect device.
    end
    
    properties (Access = public)
        portObject;                     % Device port object: serial port ID or gpib ID.
    end
    
    methods (Access = private)
        
        %--------------------------------
        %
        function lineFilters(app)
            
            valueLine = app.LineCheckBox.Value;
            value2xLine = app.xLineCheckBox.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.               
            if ~valueLine && ~value2xLine
                fprintf(app.portObject,'ILIN 0');
            elseif valueLine && ~value2xLine
                fprintf(app.portObject,'ILIN 1');
            elseif ~valueLine && value2xLine
                fprintf(app.portObject,'ILIN 2');
            elseif valueLine && value2xLine
                fprintf(app.portObject,'ILIN 3');
            end
            
        end
        
        %--------------------------------
        %
        function displayCH1(app)
            
            % Channel 1 Display Option
            if app.CH1XButton.Value
                option = '0';
            elseif app.CH1RButton.Value
                option = '1';
            elseif app.CH1XnoiseButton.Value
                option = '2';
            elseif app.CH1AuxIn1Button.Value
                option = '3';
            elseif app.CH1AuxIn2Button.Value
                option = '4';
            end
            
            % Channel 1 Ratio
            if app.Ch1RatioNoneButton.Value
                valueRatio = '0';
            elseif app.Ch1RatioAuxIn1Button.Value
                valueRatio = '1';
            elseif app.Ch1RatioAuxIn2Button.Value
                valueRatio = '2';
            end
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['DDEF 1,' option ',' valueRatio]);
            
        end
    
        %--------------------------------
        %
        function displayCH2(app)
            
            % Channel 2 Display Option
            if app.CH2YButton.Value
                option = '0';
            elseif app.CH2ThetaButton.Value
                option = '1';
            elseif app.CH2YnoiseButton.Value
                option = '2';
            elseif app.CH2AuxIn3Button.Value
                option = '3';
            elseif app.CH2AuxIn4Button.Value
                option = '4';
            end
            
            % Channel 2 Ratio
            if app.Ch2RatioNoneButton.Value
                valueRatio = '0';
            elseif app.Ch2RatioAuxIn3Button.Value
                valueRatio = '1';
            elseif app.Ch2RatioAuxIn4Button.Value
                valueRatio = '2';
            end
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['DDEF 2,' option ',' valueRatio]);
            
        end
        
        %--------------------------------
        % FUNCTION: CHANNEL 1 - OFFSET & EXPAND
        function offsetExpandCH1(app)
            
            % Check if Offset is ON or OFF
            if app.OffsetCH1OnButton.Value
                conditionOffset = true;
                valueOffset = app.OffsetCH1ModifyEditField.Value;
            else
                conditionOffset = false;
            end
            
            % Check if Displays X or R
            if app.CH1XButton.Value 
                conditionDisplay = true;
                valueDisplay = '1';
            elseif app.CH1RButton.Value
                conditionDisplay = true;
                valueDisplay = '3';
            else
                conditionDisplay = false;
                valueDisplay = '1';
            end
            
            % Check Channel 1 Expand
            if app.CH1x1Button.Value
                valueExpand = '0';
            elseif app.CH1x10Button.Value
                valueExpand = '1';
            elseif app.CH1x100Button.Value
                valueExpand = '2';   
            end
            
            if conditionOffset && conditionDisplay
                conditionOffsetEnable = true;
            else
                conditionOffsetEnable = false;
            end
            
            app.OffsetCH1AutoButton.Enable = conditionOffsetEnable;
            app.OffsetCH1ModifyEditField.Enable = conditionOffsetEnable;
            app.OffsetCH1ModifyEditFieldLabel.Enable = conditionOffsetEnable;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if conditionOffsetEnable && conditionDisplay
                fprintf(app.portObject,['OEXP ' valueDisplay ',' valueOffset ',' valueExpand]);
            elseif ~conditionOffsetEnable && conditionDisplay
                fprintf(app.portObject,['OEXP ' valueDisplay ',0,' valueExpand]);
            end
        end
    
        %--------------------------------
        % FUNCTION: CHANNEL 2 - OFFSET & EXPAND
        function offsetExpandCH2(app)
            
            % Check if Offset is ON or OFF
            if app.OffsetCH2OnButton.Value
                conditionOffset = true;
                valueOffset = app.OffsetCH2ModifyEditField.Value;
            else
                conditionOffset = false;
            end
            
            % Check if Displays Y
            if app.CH2YButton.Value 
                conditionDisplay = true;
                valueDisplay = '2';
            else
                conditionDisplay = false;
                valueDisplay = '2';
            end
            
            % Check Channel 2 Expand
            if app.CH2x1Button.Value
                valueExpand = '0';
            elseif app.CH2x10Button.Value
                valueExpand = '1';
            elseif app.CH2x100Button.Value
                valueExpand = '2';   
            end
            
            if conditionOffset && conditionDisplay
                conditionOffsetEnable = true;
            else
                conditionOffsetEnable = false;
            end
            
            app.OffsetCH2AutoButton.Enable = conditionOffsetEnable;
            app.OffsetCH2ModifyEditField.Enable = conditionOffsetEnable;
            app.OffsetCH2ModifyEditFieldLabel.Enable = conditionOffsetEnable;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if conditionOffsetEnable && conditionDisplay
                fprintf(app.portObject,['OEXP ' valueDisplay ',' valueOffset ',' valueExpand]);
            elseif ~conditionOffsetEnable && conditionDisplay
                fprintf(app.portObject,['OEXP ' valueDisplay ',0,' valueExpand]);
            end
        end
        
        %--------------------------------
        % FUNCTION: INITIALIZATION 
        function initialize(app)
            
            % Lock-in LIAS Status - Overloads
            app.lockinLIASStatus();
            
            % Reference and Phase
                % Phase Value
                fprintf(app.portObject,'PHAS?');
                answer = fscanf(app.portObject);
                app.PhaseEditField.Value = str2double(answer);
                app.PhaseEditField.Editable = true;
                app.PhaseLabel.Enable = true;
                
                % Reference
                fprintf(app.portObject,'FMOD?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.ExternalButton.Value = true;
                    app.FreqHzEditField.Editable = false;
                    app.AmplitudeVEditField.Editable = false;
                else
                    app.InternalButton.Value = true;
                    app.FreqHzEditField.Editable = true;
                    app.AmplitudeVEditField.Editable = true;
                end
                app.ExternalButton.Enable = true;
                app.InternalButton.Enable = true;
                
                % Frequency
                if ~app.frequencyUnlock
                    fprintf(app.portObject,'FREQ?');
                    answer = fscanf(app.portObject);
                    app.FreqHzEditField.Value = str2double(answer);
                    app.FreqHzEditField.BackgroundColor = [1 1 1];
                else
                    app.FreqHzEditField.Value = 1;
                    app.FreqHzEditField.BackgroundColor = [1 0 0];
                end
                app.FreqHzEditFieldLabel.Enable = true;
                
                % External Slope
                fprintf(app.portObject,'RSLP?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.SineButton.Value = true;
                elseif answer(1) == '1'
                    app.PosEdgeButton.Value = true;
                elseif answer(1) == '2'
                    app.NegEdgeButton.Value = true;
                end
                app.SineButton.Enable = true;
                app.PosEdgeButton.Enable = true;
                app.NegEdgeButton.Enable = true;
                
                % Harmonic
                fprintf(app.portObject,'HARM?');
                answer = fscanf(app.portObject);
                app.HarmonicEditField.Value = str2double(answer);
                app.HarmonicEditField.Editable = true;
                app.HarmonicEditFieldLabel.Enable = true;
                
                % Amplitude
                fprintf(app.portObject,'SLVL?');
                answer = fscanf(app.portObject);
                app.AmplitudeVEditField.Value = str2double(answer);
                app.AmplitudeVEditFieldLabel.Enable = true;
                
            % Input and Filter
                % Input Configuration
                fprintf(app.portObject,'ISRC?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.AButton.Value = true;
                elseif answer(1) == '1'
                    app.ABButton.Value = true;
                elseif answer(1) == '2'
                    app.I106Button.Value = true;
                elseif answer(1) == '3'
                    app.I108Button.Value = true;
                end
                app.AButton.Enable = true;
                app.ABButton.Enable = true;
                app.I106Button.Enable = true;
                app.I108Button.Enable = true;
                
                % Shielding
                fprintf(app.portObject,'IGND?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.FloatButton.Value = true;
                else
                    app.GroundButton.Value = true;
                end
                app.FloatButton.Enable = true;
                app.GroundButton.Enable = true;
                
                % Coupling
                fprintf(app.portObject,'ICPL?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.ACButton.Value = true;
                else
                    app.DCButton.Value = true;
                end
                app.ACButton.Enable = true;
                app.DCButton.Enable = true;
                
                % Line Filters
                fprintf(app.portObject,'ILIN?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.LineCheckBox.Value = false;
                    app.xLineCheckBox.Value = false;
                elseif answer(1) == '1'
                    app.LineCheckBox.Value = true;
                    app.xLineCheckBox.Value = false;
                elseif answer(1) == '2'
                    app.LineCheckBox.Value = false;
                    app.xLineCheckBox.Value = true;
                elseif answer(1) == '3'
                    app.LineCheckBox.Value = true;
                    app.xLineCheckBox.Value = true;
                end
                app.LineCheckBox.Enable = true;
                app.xLineCheckBox.Enable = true;
                
            % Gain and Time Constant
                % Sensitivity
                fprintf(app.portObject,'SENS?');
                answer = fscanf(app.portObject);
                app.DropDown.Value = answer(1:end-1);   % Remove terminator caracter (in SR830 can only be CR or LF)
                app.DropDown.Enable = true;
                
                % Dynamic Reserve
                fprintf(app.portObject,'RMOD?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.HighReserveButton.Value = true;
                elseif answer(1) == '1'
                    app.NormalButton.Value = true;
                elseif answer(1) == '2'
                    app.LowNoiseButton.Value = true;
                end
                app.HighReserveButton.Enable = true;
                app.NormalButton.Enable = true;
                app.LowNoiseButton.Enable = true;
                
                % Time Constant
                fprintf(app.portObject,'OFLT?');
                answer = fscanf(app.portObject);
                app.TimeConstantDropDown.Value = answer(1:end-1);
                app.TimeConstantDropDown.Enable = true;
                app.TimeConstantDropDownLabel.Enable = true;
                
                % Low Pass Filter
                fprintf(app.portObject,'OFSL?');
                answer = fscanf(app.portObject);
                app.LowPassFilterDropDown.Value = answer(1:end-1);
                app.LowPassFilterDropDown.Enable = true;
                app.LowPassFilterDropDownLabel.Enable = true;
                
                % Synchronous Filter
                fprintf(app.portObject,'SYNC?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.Sync200HzCheckBox.Value = false;
                else
                    app.Sync200HzCheckBox.Value = true;
                end
                app.Sync200HzCheckBox.Enable = true;

            % Display and Output
                % Display and Ratio, Channel 1
                app.CH1XButton.Enable = true;
                app.CH1RButton.Enable = true;
                app.CH1XnoiseButton.Enable = true;
                app.CH1AuxIn1Button.Enable = true;
                app.CH1AuxIn2Button.Enable = true;
                app.Ch1RatioNoneButton.Enable = true;
                app.Ch1RatioAuxIn1Button.Enable = true;
                app.Ch1RatioAuxIn2Button.Enable = true;
                
                fprintf(app.portObject,'DDEF? 1');
                answer = fscanf(app.portObject);
                displayOption = extractBefore(answer,',');
                ratioOption = extractAfter(answer,',');
                
                switch displayOption(1)
                    case '0'
                        app.CH1XButton.Value = true;
                    case '1'
                        app.CH1RButton.Value = true;
                    case '2'
                        app.CH1XnoiseButton.Value = true;
                    case '3'
                        app.CH1AuxIn1Button.Value = true;
                    case '4'
                        app.CH1AuxIn2Button.Value = true;
                end
                
                switch ratioOption(1)
                    case '0'
                        app.Ch1RatioNoneButton.Value = true;
                    case '1'
                        app.Ch1RatioAuxIn1Button.Value = true;
                    case '2'
                        app.Ch1RatioAuxIn2Button.Value = true;
                end
                
                % Display and Ratio, Channel 2
                app.CH2YButton.Enable = true;
                app.CH2ThetaButton.Enable = true;
                app.CH2YnoiseButton.Enable = true;
                app.CH2AuxIn3Button.Enable = true;
                app.CH2AuxIn4Button.Enable = true;
                app.Ch2RatioNoneButton.Enable = true;
                app.Ch2RatioAuxIn3Button.Enable = true;
                app.Ch2RatioAuxIn4Button.Enable = true;
                
                fprintf(app.portObject,'DDEF? 2');
                answer = fscanf(app.portObject);
                displayOption = extractBefore(answer,',');
                ratioOption = extractAfter(answer,',');
                
                switch displayOption(1)
                    case '0'
                        app.CH2YButton.Value = true;
                    case '1'
                        app.CH2ThetaButton.Value = true;
                    case '2'
                        app.CH2YnoiseButton.Value = true;
                    case '3'
                        app.CH2AuxIn3Button.Value = true;
                    case '4'
                        app.CH2AuxIn4Button.Value = true;
                end
                
                switch ratioOption(1)
                    case '0'
                        app.Ch2RatioNoneButton.Value = true;
                    case '1'
                        app.Ch2RatioAuxIn1Button.Value = true;
                    case '2'
                        app.Ch2RatioAuxIn2Button.Value = true;
                end
            
                % Output source, Channel 1
                app.OutputCH1DisplayButton.Enable = true;
                app.OutputCH1XButton.Enable = true;
                fprintf(app.portObject,'FPOP? 1');
                answer = fscanf(app.portObject);
                if answer(1) == "0"
                    app.OutputCH1DisplayButton.Value = true;
                else
                    app.OutputCH1XButton.Value = true;
                end
                
                % Output source, Channel 2
                app.OutputCH2DisplayButton.Enable = true;
                app.OutputCH2YButton.Enable = true;
                fprintf(app.portObject,'FPOP? 2');
                answer = fscanf(app.portObject);
                if answer(1) == "0"
                    app.OutputCH2DisplayButton.Value = true;
                else
                    app.OutputCH2YButton.Value = true;
                end
                
                % Offset and Expand
                app.OffsetCH1OffButton.Enable = true;
                app.OffsetCH1OnButton.Enable = true;
                app.OffsetCH1ModifyEditFieldLabel.Enable = true;
                app.OffsetCH1ModifyEditField.Enable = true;
                app.CH1x1Button.Enable = true;
                app.CH1x10Button.Enable = true;
                app.CH1x100Button.Enable = true;
                
                app.OffsetCH2OffButton.Enable = true;
                app.OffsetCH2OnButton.Enable = true;
                app.OffsetCH2ModifyEditFieldLabel.Enable = true;
                app.OffsetCH2ModifyEditField.Enable = true;
                app.CH2x1Button.Enable = true;
                app.CH2x10Button.Enable = true;
                app.CH2x100Button.Enable = true;

                optionExpandCH1 = char.empty();
                optionExpandCH2 = char.empty();
                if app.CH1XButton.Value
                    fprintf(app.portObject,'OEXP? 1');
                    answer = fscanf(app.portObject);
                    valueOffset = extractBefore(answer,',');
                    optionExpandCH1 = extractAfter(answer,',');
                    if str2double(valueOffset) ~= 0
                        app.OffsetCH1OnButton.Enable = true;
                        app.OffsetCH1ModifyEditField.Editable = true;
                        app.OffsetCH1ModifyEditField.Value = str2double(valueOffset);
                        app.OffsetCH1AutoButton.Enable = true;
                    else
                        app.OffsetCH1OffButton.Enable = true;
                        app.OffsetCH1ModifyEditField.Editable = false;
                        app.OffsetCH1ModifyEditField.Value = 0;
                    end
                end
                
                if app.CH1RButton.Value
                    fprintf(app.portObject,'OEXP? 3');
                    answer = fscanf(app.portObject);
                    valueOffset = extractBefore(answer,',');
                    optionExpandCH1 = extractAfter(answer,',');
                    if str2double(valueOffset) ~= 0
                        app.OffsetCH1OnButton.Value = true;
                        app.OffsetCH1ModifyEditField.Editable = true;
                        app.OffsetCH1ModifyEditField.Value = str2double(valueOffset);
                        app.OffsetCH1AutoButton.Enable = true;
                    else
                        app.OffsetCH1OffButton.Value= true;
                        app.OffsetCH1ModifyEditField.Editable = false;
                        app.OffsetCH1ModifyEditField.Value = 0;
                    end
                end
                
                if app.CH2YButton.Value
                    fprintf(app.portObject,'OEXP? 2');
                    answer = fscanf(app.portObject);
                    valueOffset = extractBefore(answer,',');
                    optionExpandCH2 = extractAfter(answer,',');
                    if str2double(valueOffset) ~= 0
                        app.OffsetCH2OnButton.Value = true;
                        app.OffsetCH2ModifyEditField.Editable = true;
                        app.OffsetCH2ModifyEditField.Value = str2double(valueOffset);
                        app.OffsetCH2AutoButton.Enable = true;
                    else
                        app.OffsetCH2OffButton.Value = true;
                        app.OffsetCH2ModifyEditField.Editable = false;
                        app.OffsetCH2ModifyEditField.Value = 0;
                    end
                end

                if isempty(optionExpandCH1)
                    fprintf(app.portObject,'OEXP? 1');
                    answer = fscanf(app.portObject);
                    optionExpandCH1 = extractAfter(answer,',');
                end
                
                if isempty(optionExpandCH2)
                    fprintf(app.portObject,'OEXP? 2');
                    answer = fscanf(app.portObject);
                    optionExpandCH2 = extractAfter(answer,',');
                end
                
                switch optionExpandCH1(1)
                    case '0'
                        app.CH1x1Button.Value = true;
                    case '1'
                        app.CH1x10Button.Value = true;
                    case '2'
                        app.CH1x100Button.Value = true;
                end
                
                switch optionExpandCH2(1)
                    case '0'
                        app.CH2x1Button.Value = true;
                    case '1'
                        app.CH2x10Button.Value = true;
                    case '2'
                        app.CH2x100Button.Value = true;
                end
            
            % Auxiliar Inputs and Auxiliar Outputs
                %
                
                % Auxiliar Outputs
                fprintf(app.portObject,'AUXV? 1');
                answer = fscanf(app.portObject);
                app.Output1EditField.Value = str2double(answer);
                app.Output1EditField.Enable = true;
                app.Output1EditFieldLabel.Enable = true;
                app.Output1EditField.Editable = true;
                
                fprintf(app.portObject,'AUXV? 2');
                answer = fscanf(app.portObject);
                app.Output2EditField.Value = str2double(answer);
                app.Output2EditField.Enable = true;
                app.Output2EditFieldLabel.Enable = true;
                app.Output2EditField.Editable = true;
                
                fprintf(app.portObject,'AUXV? 3');
                answer = fscanf(app.portObject);
                app.Output3EditField.Value = str2double(answer);
                app.Output3EditField.Enable = true;
                app.Output3EditFieldLabel.Enable = true;
                app.Output3EditField.Editable = true;
                
                fprintf(app.portObject,'AUXV? 4');
                answer = fscanf(app.portObject);
                app.Output4EditField.Value = str2double(answer);
                app.Output4EditField.Enable = true;
                app.Output4EditFieldLabel.Enable = true;
                app.Output4EditField.Editable = true;
                
            % Auto Functions
                % Auto Gain
                app.GainButton.Enable = true;
                
                % Auto Reserve
                app.ReserveButton.Enable = true;
                
                % Auto Phase
                app.PhasButton.Enable = true;
                
            % Data Transfer
                % Display
                fprintf(app.portObject,'SNAP? 10,11');
                answer = fscanf(app.portObject);
                app.DisplayCH1EditField.Value = str2double(extractBefore(answer,','));
                app.DisplayCH2EditField.Value = str2double(extractAfter(answer,','));
                
            % Interface
                % Device Identification
                fprintf(app.portObject,'*IDN?');
                answer = fscanf(app.portObject);
                app.DeviceIdendificationEditField.Value = answer;
                
            % Plot Buttons
            app.StartButton.Enable = true;
            app.StopButton.Enable = true;
            app.CleanButton.Enable = true;
            app.PlotLeftDropDown.Enable = true;
            app.PlotLeftDropDownLabel.Enable = true;
            app.PlotRightDropDown.Enable = true;
            app.PlotRightDropDownLabel.Enable = true;
            app.IntervalsSpinner.Enable = true;
            app.IntervalsSpinner.Editable = true;
            app.IntervalsSpinnerLabel.Enable = true;
            app.IntervalsSpinner.Value = app.TIMEAQUISITIONINTERVAL;
            app.TimesSpinner.Enable = true;
            app.TimesSpinner.Editable = true;
            app.TimesSpinnerLabel.Enable = true;
            app.TimesSpinner.Value = app.TIMEAQUISITIONTOTAL;
            
            % 
            app.ForceUpdateButton.Enable = true;
            app.SaveConfigButton.Enable = true;
            app.LoadConfigButton.Enable = true;
        end
    
        %--------------------------------
        % FUNCTION: REGULAR DATA AQUISITION
        function dataTimerFcn(app,~,~)
            
            % Lock-in Amplifier LIAS Status
            app.lockinLIASStatus();
            
            % Get Lock-in Data for Channel 1 & 2 Display & Frequency & Plot Left & Right Hand Sides
            flushinput(app.portObject);                   % Remove data from port input buffer.
            plotLeftOption = app.PlotLeftDropDown.Value;
            plotRightOption = app.PlotRightDropDown.Value;
            
            if plotLeftOption ~= "0" && plotRightOption ~= "0"
                fprintf(app.portObject,['SNAP? 10,11,9,' plotLeftOption ',' plotRightOption]);
                plotLeftOption = true;
                plotRightOption = true;
                
            elseif plotLeftOption ~= "0" && plotRightOption == "0"
                fprintf(app.portObject,['SNAP? 10,11,9,' plotLeftOption]);
                plotLeftOption = true;
                plotRightOption = false;
                
            elseif plotLeftOption == "0" && plotRightOption ~= "0"
                fprintf(app.portObject,['SNAP? 10,11,9,' plotRightOption]);
                plotLeftOption = false;
                plotRightOption = true;
                
            elseif plotLeftOption == "0" && plotRightOption == "0"
                fprintf(app.portObject,'SNAP? 10,11,9');
                plotLeftOption = false;
                plotRightOption = false;
            end
            
            % Extract Data from String
            answer = fscanf(app.portObject);
            commaPositions = strfind(answer,',');
            
                % Channel 1 & Channel 2 Display Values
                dataCH1 = str2double(answer(1:commaPositions(1)-1));
                dataCH2 = str2double(answer((commaPositions(1)+1):(commaPositions(2)-1)));
                
                % Frequency Value
                if ~app.frequencyUnlock
                    dataFreq = str2double(answer((commaPositions(2)+1):(commaPositions(3)-1)));
                else
                    dataFreq = 1;
                end
                
                % Plot Values
                if plotLeftOption && plotRightOption
                    plotLeftValue = str2double(answer((commaPositions(3)+1):(commaPositions(4)-1)));
                    plotRightValue = str2double(answer((commaPositions(4)+1):end));
                elseif plotLeftOption && ~plotRightOption
                    plotLeftValue = str2double(answer((commaPositions(4)+1):end));
                    plotRightValue = 0;
                elseif ~plotLeftOption && plotRightOption
                    plotLeftValue = 0;
                    plotRightValue = str2double(answer((commaPositions(4)+1):end));
                elseif ~plotLeftOption && ~plotRightOption
                    plotLeftValue = 0;
                    plotRightValue = 0;
                end
            
            % Actualize Channel 1 & 2 Displays
            app.DisplayCH1EditField.Value = dataCH1;
            app.DisplayCH2EditField.Value = dataCH2;
            
            % Actualize Frequency
            app.FreqHzEditField.Value = dataFreq;
            
            % Plot data Channel 1
            ydata = app.plotDataAxisYLeft;
            ydata = circshift(ydata,1);
            ydata(1) = plotLeftValue;
            yyaxis(app.UIAxes,"left");
            
            plot(app.UIAxes,app.plotDataAxisX,ydata);
            app.plotObject.YData = ydata;
            app.plotDataAxisYLeft = ydata;
            
            % Plot data Channel 2
            ydata = app.plotDataAxisYRight;
            ydata = circshift(ydata,1);
            ydata(1) = plotRightValue;
            yyaxis(app.UIAxes,"right");
            app.plotObject.YData = ydata;
            app.plotDataAxisYRight = ydata;

        end 

        %--------------------------------
        % FUNCTION: 
        function plotAxisYLabel(app,side)
            
            if contains(side,'left')
                value = app.PlotLeftDropDown.Value;
            else
                value = app.PlotRightDropDown.Value;
            end
            
            switch value
                case "0"
                    ylabel(app.UIAxes,"None");
                    
                case "1"
                    if app.AButton.Value || app.ABButton.Value
                        ylabel(app.UIAxes,"X (V)");
                    elseif app.I106Button.Value || app.I108Button.Value
                        ylabel(app.UIAxes,"X (A)");
                    end
                    
                case "2"
                    if app.AButton.Value || app.ABButton.Value
                        ylabel(app.UIAxes,"Y (V)");
                    elseif app.I106Button.Value || app.I108Button.Value
                        ylabel(app.UIAxes,"Y (A)");
                    end
                    
                case "3"
                    if app.AButton.Value || app.ABButton.Value
                        ylabel(app.UIAxes,"R (V)");
                    elseif app.I106Button.Value || app.I108Button.Value
                        ylabel(app.UIAxes,"R (A)");
                    end
                    
                case "4"
                    if app.AButton.Value || app.ABButton.Value
                        ylabel(app.UIAxes,"Theta (º)");
                    elseif app.I106Button.Value || app.I108Button.Value
                        ylabel(app.UIAxes,"Theta (º)");
                    end
                    
                case "5"
                    ylabel(app.UIAxes,"Aux In 1 (V)");
                    
                case "6"
                    ylabel(app.UIAxes,"Aux In 2 (V)");
                    
                case "7"
                    ylabel(app.UIAxes,"Aux In 3 (V)");
                    
                case "8"
                    ylabel(app.UIAxes,"Aux In 4 (V)");
                    
                case "9"
                    ylabel(app.UIAxes,"Frequency (Hz)");
                    
                case "10"
                    ylabel(app.UIAxes,"Channel 1");
                    
                case "11"
                    ylabel(app.UIAxes,"Channel 2");
            end
            
        end
        
        %--------------------------------
        % FUNCTION:
        function lockinLIASStatus(app)
            
            % Flush Por Object
            flushinput(app.portObject);                   % Remove data from port input buffer.
            
            % Get LIAS Status Byte
            fprintf(app.portObject,'*CLS');
            fprintf(app.portObject,'LIAS?');
            answer = dec2bin(fscanf(app.portObject),8);     % LIAS byte + terminator 'CR'
            
                % Input or Amplifier Overload
                if answer(1,8) == '1'
                    app.SignalInputOverloadLamp.Color = [1 0 0];
                else
                    app.SignalInputOverloadLamp.Color = [0 0 0];
                end
                
                % Time Constant Filter Overload
                if answer(1,7) == '1'
                    app.TimeConstantOverloadLamp.Color = [1 0 0];
                else
                    app.TimeConstantOverloadLamp.Color = [0 0 0];
                end
                
                % Output Overload
                if answer(1,6) == '1'
                    app.SensitivityOverloadLamp.Color = [1 0 0];
                else
                    app.SensitivityOverloadLamp.Color = [0 0 0];
                end
                
                % Frequency Unlock
                if answer(1,5) == '1'
                    app.FreqHzEditField.BackgroundColor = [1 0 0];
                    app.frequencyUnlock = true;
                else
                    app.FreqHzEditField.BackgroundColor = [1 1 1];
                    app.frequencyUnlock = false;
                end
            
                % Time Constant
                if answer(1,3) == '1'
                    if str2double(app.TimeConstantDropDown.Value) >= 13
                        fprintf(app.portObject,'OFLT?');
                        answer = fscanf(app.portObject);
                        app.TimeConstantDropDown.Value = answer(1:end-1);
                    end
                end
            
        end
    end
    
    methods (Access = public)
        
        %--------------------------------
        %
        function connect(app,varargin)
            
            % Organizing Input Information
            if nargin == 2

                app.portObject = varargin{1};
                
            else
                for i = 1:(nargin - 1)
                    
                    switch lower(varargin{i})
                        
                        case {'portobject' 'port object'}
                            i = i+1;
                            app.portObject = varargin{i};
                            
                        case {'serialport' 'serial port'}
                            app.portInfo.portChoice = 'serialport';

                        case 'gpib'
                            app.portInfo.portChoice = 'gpib';

                        case 'port'
                            i = i+1;
                            app.portInfo.serialPort.port = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'serialport';
                            end
                            
                        case 'baudrate'
                            i = i+1;
                            app.portInfo.serialPort.baudRate = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'serialport';
                            end
                            
                        case 'parity'
                            i = i+1;
                            app.portInfo.serialPort.parity = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'serialport';
                            end
                            
                        case 'vendor'
                            i = i+1;
                            app.portInfo.gpib.vendor = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'gpib';
                            end
                            
                        case {'boardindex' 'board index'}
                            i = i+1;
                            app.portInfo.gpib.boardindex = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'gpib';
                            end
                            
                        case {'primaryaddress' 'primary address'}
                            i = i+1;
                            app.portInfo.gpib.primaryAddress = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'gpib';
                            end
                        case 'terminator'
                            i = i+1;
                            app.portInfo.terminator = varargin{i};

                    end
                end
                
                % Connect
                switch lower(app.portInfo.portChoice)               % Choose connection through serial port or GPIB
                    case {'serialport' 'serial port'}
                        port       = app.portInfo.serialPort.port;        % Serial port COM.
                        baudRate   = app.portInfo.serialPort.baudRate;    % Serial port baudrate (monochromator recognizes baudRate during initialization).
                        dataBits   = 8;                                   % Serial port dataBits, fixed by the maker.
                        stopBits   = 1;                                   % Serial port stopBits, fixed by the maker.
                        parity     = app.portInfo.serialPort.parity;      % Serial port parity, fixed by the maker.
                        terminator = 'CR';                                % Communication terminator (SR830 can use CR or LF).
                        
                        try
                            app.portObject = serial(port,...                  % Create serial port object.
                                                    'Baudrate',baudRate,...
                                                    'DataBits',dataBits,...
                                                    'StopBits',stopBits,...
                                                    'Parity',parity,...
                                                    'Terminator',terminator,...
                                                    'TimeOut',app.TIMEOUT);
                                            
                            flushoutput(app.portObject);                      % Remove data from port output buffer.
                            flushinput(app.portObject);                       % Remove data from port input buffer.
                            fopen(app.portObject);                            % Open the serial port connection.
                            app.isconnected = true;
                        catch
                            app.isconnected = false;
                            msg = ["It was not possible to connect with device SR830."...
                                   "Check if serial port exist and has the correct COM number."...
                                   "Check if the correct device is turn on."];
                            title = 'Error';
                            icon = 'error';
                            msgbox(msg,title,icon);
                        end
                        
                    case 'gpib'
                        vendor  = app.portInfo.gpib.vendor;                 % gpib adapter vendor
                        boardindex = app.portInfo.gpib.boardindex;          % controller board index.
                        primaryAddress = app.portInfo.gpib.primaryAddress;  % device gpib address
                         
                        try
                            app.portObject = gpib(vendor,boardindex,primaryAddress);  % Create gpib objet.
                            
                            flushoutput(app.portObject);                      % Remove data from port output buffer.
                            flushinput(app.portObject);                       % Remove data from port input buffer.
                            fopen(app.portObject);                            % Open the serial port connection.
                            app.isconnected = true;
                        catch
                            app.isconnected = false;
                            msg = ["It was not possible to connect with device SR830."...
                                   "Check if serial port exist and has the correct COM number."...
                                   "Check if the correct device is turn on."];
                            title = 'Error';
                            icon = 'error';
                            msgbox(msg,title,icon);
                        end
                end
            end
            
            if app.isconnected
                app.initialize();
            end
            
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Create timer object
            app.dataTimer = timer('ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                                  'Period', app.TIMEAQUISITIONINTERVAL, ...                     % Period is 1 second
                                  'BusyMode', 'queue',...              % Queue timer callbacks when busy
                                  'TimerFcn', @app.dataTimerFcn);    % Specify callback function
            
            % Configure x-axis
            app.UIAxes.XLim = [0 app.TIMEAQUISITIONTOTAL];
            app.UIAxes.XDir = 'reverse';
            
            % Initial Plot Data (Zeros)
            app.plotDataAxisX = 0:app.TIMEAQUISITIONINTERVAL:app.TIMEAQUISITIONTOTAL;
            axisXSize = size(app.plotDataAxisX,2);
            app.plotDataAxisYLeft = zeros(1,axisXSize);
            app.plotDataAxisYRight = zeros(1,axisXSize);
            
            % Configure y-axis Left Hand Side
            yyaxis(app.UIAxes,"left");
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYLeft);
            
            % Configure y-axis Left Hand Side
            yyaxis(app.UIAxes,"right");
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYRight);
            
        end

        % Selection changed function: ShieldGroundingButtonGroup
        function ShieldGroundingButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.FloatButton.Value
                fprintf(app.portObject,'IGND 0');
            elseif app.GroundButton.Value
                fprintf(app.portObject,'IGND 1');
            end
        end

        % Selection changed function: CouplingButtonGroup
        function CouplingButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.ACButton.Value
                fprintf(app.portObject,'ICPL 0');
            elseif app.DCButton.Value
                fprintf(app.portObject,'ICPL 1');
            end
        end

        % Selection changed function: InputButtonGroup
        function InputButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.AButton.Value
                fprintf(app.portObject,'ISRC 0');
            elseif app.ABButton.Value
                fprintf(app.portObject,'ISRC 1');
            elseif app.I106Button.Value
                fprintf(app.portObject,'ISRC 2');
            elseif app.I108Button.Value
                fprintf(app.portObject,'ISRC 3');
            end
        end

        % Selection changed function: ReserveButtonGroup
        function ReserveButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.HighReserveButton.Value
                fprintf(app.portObject,'RMOD 0');
            elseif app.NormalButton.Value
                fprintf(app.portObject,'RMOD 1');
            elseif app.LowNoiseButton.Value
                fprintf(app.portObject,'RMOD 2');
            end
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            app.displayCH1();
            
        end

        % Button pushed function: GainButton
        function GainButtonPushed(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            
            fprintf(app.portObject,'AGAN');
            app.LampGain.Color = [1 0 0];
            pause(0.1);
            app.portObject.TimeOut = 180;
            fprintf(app.portObject,'*STB?1');
            answer = fscanf(app.portObject);
            app.portObject.TimeOut = 1;
            
            fprintf(app.portObject,'SENS?');
            answer = fscanf(app.portObject);
            app.DropDown.Value = answer(1:end-1);
            app.LampGain.Color = [0 0 0];
        end

        % Button pushed function: ReserveButton
        function ReserveButtonPushed(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,'ARSV');
            
            app.LampReserve.Color = [1 0 0];
            pause(0.1);
            app.portObject.TimeOut = 180;
            fprintf(app.portObject,'*STB?1');
            answer = fscanf(app.portObject);
            app.portObject.TimeOut = 1;
            
            fprintf(app.portObject,'RMOD?');
            answer = fscanf(app.portObject);
            fprintf(app.portObject,['RMOD' answer(1)]);
            
            
            app.LampReserve.Color = [0 0 0];
        end

        % Button pushed function: PhasButton
        function PhasButtonPushed(app, event)
            flushinput(app.portObject);                     % Remove data from port input buffer.
            fprintf(app.portObject,'APHS');                 % Auto phase function.
            
            app.LampPhas.Color = [1 0 0];                   % Change phase lamp color to red while operating.
            app.portObject.TimeOut = 30;                    % Increase time out value to give enough time to execute function.
            fprintf(app.portObject,'*STB?1');
            answer = fscanf(app.portObject);                % When answer is received, mean end of operation.
            app.portObject.TimeOut = app.TIMEOUT;           % Return time out to original value.
            
            fprintf(app.portObject,'PHAS?');                % Ask device for new phase value.
            answer = fscanf(app.portObject);
            app.PhaseEditField.Value = str2double(answer);  % Update app phase value.
            pause(0.1);                                     % Provides some time to observe colour transition of the lamp.
            app.LampPhas.Color = [0 0 0];                   % Change lamp color back to black.
        end

        % Value changed function: Output1EditField
        function Output1EditFieldValueChanged(app, event)
            value = app.Output1EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 1,' num2str(value)]);
        end

        % Value changed function: Output2EditField
        function Output2EditFieldValueChanged(app, event)
            value = app.Output2EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 2,' num2str(value)]);
        end

        % Value changed function: Output3EditField
        function Output3EditFieldValueChanged(app, event)
            value = app.Output3EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 3,' num2str(value)]);
        end

        % Value changed function: Output4EditField
        function Output4EditFieldValueChanged(app, event)
            value = app.Output4EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 4,' num2str(value)]);
        end

        % Button pushed function: OffsetCH1AutoButton
        function OffsetCH1AutoButtonPushed(app, event)
            app.offsetExpandCH1();
            
            if app.CH1XButton.Value
                fprintf(app.portObject,'AOFF 1');
            elseif app.CH1RButton.Value
                fprintf(app.portObject,'AOFF 3');
            end
            
            fprintf(app.portObject,'OEXP? 1');
            answer = fscanf(app.portObject);
            
            app.OffsetCH1ModifyEditField.Value = str2double(extractBefore(answer,','));
            
            switch str2double(extractAfter(answer,','))
                case 0
                    app.CH1x1Button.Value = true;
                case 1
                    app.CH1x10Button.Value = true;
                case 2
                    app.CH1x100Button.Value = true;
            end
        end

        % Value changed function: Sync200HzCheckBox
        function Sync200HzCheckBoxValueChanged(app, event)
            value = app.Sync200HzCheckBox.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if value
                fprintf(app.portObject,'SYNC 1');
                pause(.2);
                fprintf(app.portObject,'SYNC?');
                answer = fscanf(app.portObject);
                if answer == 0
                    app.Sync200HzCheckBox.Value = 0;
                end
            else
                fprintf(app.portObject,'SYNC 0');
            end
        end

        % Value changed function: LowPassFilterDropDown
        function LowPassFilterDropDownValueChanged(app, event)
            value = app.LowPassFilterDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            switch value
                case '0'
                    fprintf(app.portObject,'OFSL 0');
                case '1'
                    fprintf(app.portObject,'OFSL 1');
                case '2'
                    fprintf(app.portObject,'OFSL 2');
                case '3'
                    fprintf(app.portObject,'OFSL 3');
            end
        end

        % Value changed function: TimeConstantDropDown
        function TimeConstantDropDownValueChanged(app, event)
            value = app.TimeConstantDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            switch value
                case '0'
                    fprintf(app.portObject,'OFLT 0');
                case '1'
                    fprintf(app.portObject,'OFLT 1');
                case '2'
                    fprintf(app.portObject,'OFLT 2');
                case '3'
                    fprintf(app.portObject,'OFLT 3');
                case '4'
                    fprintf(app.portObject,'OFLT 4');
                case '5'
                    fprintf(app.portObject,'OFLT 5');
                case '6'
                    fprintf(app.portObject,'OFLT 6');
                case '7'
                    fprintf(app.portObject,'OFLT 7');
                case '8'
                    fprintf(app.portObject,'OFLT 8');
                case '9'
                    fprintf(app.portObject,'OFLT 9');
                case '10'
                    fprintf(app.portObject,'OFLT 10');
                case '11'
                    fprintf(app.portObject,'OFLT 11');
                case '12'
                    fprintf(app.portObject,'OFLT 12');
                case '13'
                    fprintf(app.portObject,'OFLT 13');
                case '14'
                    fprintf(app.portObject,'OFLT 14');
                case '15'
                    fprintf(app.portObject,'OFLT 15');
                case '16'
                    fprintf(app.portObject,'OFLT 16');
                case '17'
                    fprintf(app.portObject,'OFLT 17');
                case '18'
                    fprintf(app.portObject,'OFLT 18');
                case '19'
                    fprintf(app.portObject,'OFLT 19');
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            switch value
                case '0'
                    fprintf(app.portObject,'SENS 0');
                case '1'
                    fprintf(app.portObject,'SENS 1');
                case '2'
                    fprintf(app.portObject,'SENS 2');
                case '3'
                    fprintf(app.portObject,'SENS 3');
                case '4'
                    fprintf(app.portObject,'SENS 4');
                case '5'
                    fprintf(app.portObject,'SENS 5');
                case '6'
                    fprintf(app.portObject,'SENS 6');
                case '7'
                    fprintf(app.portObject,'SENS 7');
                case '8'
                    fprintf(app.portObject,'SENS 8');
                case '9'
                    fprintf(app.portObject,'SENS 9');
                case '10'
                    fprintf(app.portObject,'SENS 10');
                case '11'
                    fprintf(app.portObject,'SENS 11');
                case '12'
                    fprintf(app.portObject,'SENS 12');
                case '13'
                    fprintf(app.portObject,'SENS 13');
                case '14'
                    fprintf(app.portObject,'SENS 14');
                case '15'
                    fprintf(app.portObject,'SENS 15');
                case '16'
                    fprintf(app.portObject,'SENS 16');
                case '17'
                    fprintf(app.portObject,'SENS 17');
                case '18'
                    fprintf(app.portObject,'SENS 18');
                case '19'
                    fprintf(app.portObject,'SENS 19');
                case '20'
                    fprintf(app.portObject,'SENS 20');
                case '21'
                    fprintf(app.portObject,'SENS 21');
                case '22'
                    fprintf(app.portObject,'SENS 22');
                case '23'
                    fprintf(app.portObject,'SENS 23');
                case '24'
                    fprintf(app.portObject,'SENS 24');
                case '25'
                    fprintf(app.portObject,'SENS 25');
                case '26'
                    fprintf(app.portObject,'SENS 26');
            end
        end

        % Selection changed function: SourceButtonGroup
        function SourceButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.ExternalButton.Value
                fprintf(app.portObject,'FMOD 0');
                condition = false;
                pause(.25);
            elseif app.InternalButton.Value
                fprintf(app.portObject,'FMOD 1');
                condition = true;
            end
            app.FreqHzEditField.Editable = condition;
            app.AmplitudeVEditField.Editable = condition;
            fprintf(app.portObject,'FREQ?');
            answer = fscanf(app.portObject);
            app.FreqHzEditField.Value = str2double(answer);
        end

        % Selection changed function: TriggerButtonGroup
        function TriggerButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.SineButton.Value
                fprintf(app.portObject,'RSLP 0');
            elseif app.PosEdgeButton.Value
                fprintf(app.portObject,'RSLP 1');
            elseif app.NegEdgeButton.Value
                fprintf(app.portObject,'RSLP 2');
            end
        end

        % Value changed function: HarmonicEditField
        function HarmonicEditFieldValueChanged(app, event)
            value = app.HarmonicEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['HARM ' num2str(fix(value))]);
            pause(.2);
            fprintf(app.portObject,'HARM?');
            answer = fscanf(app.portObject);
            app.HarmonicEditField.Value = str2double(answer);
        end

        % Value changed function: LineCheckBox
        function LineCheckBoxValueChanged(app, event)
            app.lineFilters();
        end

        % Value changed function: xLineCheckBox
        function xLineCheckBoxValueChanged(app, event)
            app.lineFilters();
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
            value = app.PhaseEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['PHAS ' num2str(value)]);
        end

        % Value changed function: FreqHzEditField
        function FreqHzEditFieldValueChanged(app, event)
            value = app.FreqHzEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['FREQ ' num2str(value)]);
        end

        % Value changed function: AmplitudeVEditField
        function AmplitudeVEditFieldValueChanged(app, event)
            value = app.AmplitudeVEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['SLVL ' num2str(value)]);
        end

        % Selection changed function: DisplayButtonGroup_2
        function DisplayButtonGroup_2SelectionChanged(app, event)
            app.displayCH2();
        end

        % Selection changed function: CH2RatioButtonGroup
        function CH2RatioButtonGroupSelectionChanged(app, event)
            app.displayCH2();
        end

        % Selection changed function: RatioButtonGroup
        function RatioButtonGroupSelectionChanged(app, event)
            app.displayCH1();
        end

        % Selection changed function: OutputCH1ButtonGroup
        function OutputCH1ButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.OutputCH1DisplayButton.Value
                fprintf(app.portObject,'FPOP 1,0');
            elseif app.OutputCH1XButton.Value
                fprintf(app.portObject,'FPOP 1,1');
            end
        end

        % Selection changed function: OutputCH2ButtonGroup
        function OutputCH2ButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.OutputCH2DisplayButton.Value
                fprintf(app.portObject,'FPOP 2,0');
            elseif app.OutputCH2YButton.Value
                fprintf(app.portObject,'FPOP 2,1');
            end
        end

        % Selection changed function: OffsetButtonGroup
        function OffsetButtonGroupSelectionChanged(app, event)
            app.offsetExpandCH1();
        end

        % Selection changed function: ExpandButtonGroup
        function ExpandButtonGroupSelectionChanged(app, event)
            app.offsetExpandCH1();
        end

        % Selection changed function: ExpandCH1ButtonGroup
        function ExpandCH1ButtonGroupSelectionChanged(app, event)
            app.offsetExpandCH2();
        end

        % Selection changed function: OffsetCH2ButtonGroup
        function OffsetCH2ButtonGroupSelectionChanged(app, event)
            app.offsetExpandCH2();
        end

        % Value changed function: OffsetCH1ModifyEditField
        function OffsetCH1ModifyEditFieldValueChanged(app, event)
            app.offsetExpandCH1();
        end

        % Value changed function: OffsetCH2ModifyEditField
        function OffsetCH2ModifyEditFieldValueChanged(app, event)
            app.offsetExpandCH2();
        end

        % Button pushed function: OffsetCH2AutoButton
        function OffsetCH2AutoButtonPushed(app, event)
            app.offsetExpandCH2();
            
            if app.CH1XButton.Value
                fprintf(app.portObject,'AOFF 2');
            end
            fprintf(app.portObject,'OEXP? 2');
            answer = fscanf(app.portObject);
            
            app.OffsetCH2ModifyEditField.Value = str2double(extractBefore(answer,','));
            
            switch str2double(extractAfter(answer,','))
                case 0
                    app.CH2x1Button.Value = true;
                case 1
                    app.CH2x10Button.Value = true;
                case 2
                    app.CH2x100Button.Value = true;
            end
        end

        % Close request function: 
        % StanfordResearchSystemsModelSR830UIFigure
        function StanfordResearchSystemsModelSR830UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.dataTimer);
            delete(app.dataTimer);
            
            % Close Device Connection
            if app.isconnected
                fclose(app.portObject);
            end
            
            delete(app)
        end

        % Button pushed function: ForceUpdateButton
        function ForceUpdateButtonPushed(app, event)
            
            app.initialize();
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % If timer is not running, start it
            if strcmp(app.dataTimer.Running, 'off')
               start(app.dataTimer);
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop the timer
            stop(app.dataTimer);

        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)

            % Stop Data Collection
            if strcmp(app.dataTimer.Running, 'on')
                stop(app.dataTimer);
                dataTimerWasRunning = true;
            else
                dataTimerWasRunning = false;
            end
            
            % Zero Left Plot
            ydata = app.plotObject.YData;
            ydata = zeros(size(ydata,2),1);
            app.plotDataAxisYLeft = ydata;
            yyaxis(app.UIAxes,"left");
            ylabel(app.UIAxes,'Left');
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYLeft);
            
            % Zero Right Plot
            app.plotDataAxisYRight = ydata;
            yyaxis(app.UIAxes,"right");
            ylabel(app.UIAxes,'Right');
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYRight);
            
            % Re-start Data Collection
            if dataTimerWasRunning
                start(app.dataTimer);
            end
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
            % Device
            config.deviceModel = 'sr830';
            config.deviceIdentification = app.DeviceIdendificationEditField.Value;
            
            % Time Constant
            config.timeConstant = app.TimeConstantDropDown.Value;
            config.lowPassFilter = app.LowPassFilterDropDown.Value;
            config.syncFilter = app.Sync200HzCheckBox.Value;
            
            % Input
            config.inputA = app.AButton.Value;
            config.inputAB = app.ABButton.Value;
            config.inputI1E6 = app.I106Button.Value;
            config.inputI1E8 = app.I108Button.Value;
            config.couplingAC = app.ACButton.Value;
            config.couplingDC = app.DCButton.Value;
            config.shieldGroundingFloat = app.FloatButton.Value;
            config.shieldGroundingGround = app.GroundButton.Value;
            
            % Sensitivity
            config.sensitivity = app.DropDown.Value;
            
            % Reserve
            config.reserveHighReserve = app.HighReserveButton.Value;
            config.reserveNormal = app.NormalButton.Value;
            config.reserveLowNoise = app.LowNoiseButton.Value;
            
            % Line Filters
            config.filterLine = app.LineCheckBox.Value;
            config.filter2xLine = app.xLineCheckBox.Value;
            
            % Auxiliar Outputs
            config.auxiliarOutput1 = app.Output1EditField.Value;
            config.auxiliarOutput2 = app.Output2EditField.Value;
            config.auxiliarOutput3 = app.Output3EditField.Value;
            config.auxiliarOutput4 = app.Output4EditField.Value;
            
            % Channel 1 Display
            config.channel1DisplayX = app.CH1XButton.Value;
            config.channel1DisplayR = app.CH1RButton.Value;
            config.channel1DisplayXnoise = app.CH1XnoiseButton.Value;
            config.channel1DisplayAuxIn1 = app.CH1AuxIn1Button.Value;
            config.channel1DisplayAuxIn2 = app.CH1AuxIn2Button.Value;
            config.channel1RatioNone = app.Ch1RatioNoneButton.Value;
            config.channel1RatioAuxIn1 = app.Ch1RatioAuxIn1Button.Value;
            config.channel1RatioAuxIn2 = app.Ch1RatioAuxIn2Button.Value;
            config.channel1Expandx1 = app.CH1x1Button.Value;
            config.channel1Expandx10 = app.CH1x10Button.Value;
            config.channel1Expandx100 = app.CH1x100Button.Value;
            config.channel1OutputDisplay = app.OutputCH1DisplayButton.Value;
            config.channel1OutputX = app.OutputCH1XButton.Value;
            config.channel1OffsetOff = app.OffsetCH1OffButton.Value;
            config.channel1OffsetOn = app.OffsetCH1OnButton.Value;
            config.channel1Offset = app.OffsetCH1ModifyEditField.Value;
            
            % Channel 2 Display
            config.channel2DisplayY = app.CH2YButton.Value;
            config.channel2DisplayTheta = app.CH2ThetaButton.Value;
            config.channel2DisplayYnoise = app.CH2YnoiseButton.Value;
            config.channel2DisplayAuxIn3 = app.CH2AuxIn3Button.Value;
            config.channel2DisplayAuxIn4 = app.CH2AuxIn4Button.Value;
            config.channel2RatioNone = app.Ch2RatioNoneButton.Value;
            config.channel2RatioAuxIn1 = app.Ch2RatioAuxIn3Button.Value;
            config.channel2RatioAuxIn2 = app.Ch2RatioAuxIn4Button.Value;
            config.channel2Expandx1 = app.CH2x1Button.Value;
            config.channel2Expandx10 = app.CH2x10Button.Value;
            config.channel2Expandx100 = app.CH2x100Button.Value;
            config.channel2OutputDisplay = app.OutputCH2DisplayButton.Value;
            config.channel2OutputY = app.OutputCH2YButton.Value;
            config.channel2OffsetOff = app.OffsetCH2OffButton.Value;
            config.channel2OffsetOn = app.OffsetCH2OnButton.Value;
            config.channel2Offset = app.OffsetCH2ModifyEditField.Value;
            
            % Reference
            config.referencePhase = app.PhaseEditField.Value;
            config.referenceFrequency = app.FreqHzEditField.Value;
            config.referenceAmplitude = app.AmplitudeVEditField.Value;
            config.referenceHarmonic = app.HarmonicEditField.Value;
            config.referenceSourceExternal = app.ExternalButton.Value;
            config.referenceSourceImternal = app.InternalButton.Value;
            config.referenceTriggerSine = app.SineButton.Value;
            config.referenceTriggerPositiveEdge = app.PosEdgeButton.Value;
            config.referenceTriggerNegativeEdge = app.NegEdgeButton.Value;
            
            % Plot
            config.PlotLeftDropDown = app.PlotLeftDropDown.Value;
            config.PlotRightDropDown = app.PlotRightDropDown.Value;
            config.IntervalsSpinner = app.IntervalsSpinner.Value;
            config.TimesSpinner = app.TimesSpinner.Value;
            
            % Create File Name
            uisave('config','Configuration_SR830');
            
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            
            % Get File
            [file,path] = uigetfile('*.mat');
            filepath = fullfile(path,file);
            if isequal(file,0)
               return;
            end
            
            load(filepath);
            
            % Device
            if contains(lower(config.deviceModel),'sr830')
                
                if contains(config.deviceIdentification,app.DeviceIdendificationEditField.Value)
                    condition = true;
                else
                    msg = 'This configuration was obtained from a different SR830. Would you like to proceed?';
                    title = 'Confirm';
                    answer = questdlg(msg, ...
                    	              title, ...
                    	              'Yes','No','No');
                    % Handle response
                    switch answer
                        case 'Yes'
                            condition = true;
                        case 'No'
                            condition = false;
                    end
                end
                
                if condition
                    % Time Constant
                    app.TimeConstantDropDown.Value = config.timeConstant;
                    app.LowPassFilterDropDown.Value = config.lowPassFilter;
                    app.Sync200HzCheckBox.Value = config.syncFilter;
                    
                    % Input
                    app.AButton.Value = config.inputA;
                    app.ABButton.Value = config.inputAB;
                    app.I106Button.Value = config.inputI1E6;
                    app.I108Button.Value = config.inputI1E8;
                    app.ACButton.Value = config.couplingAC;
                    app.DCButton.Value = config.couplingDC;
                    app.FloatButton.Value = config.shieldGroundingFloat;
                    app.GroundButton.Value = config.shieldGroundingGround;
                    
                    % Sensitivity
                    app.DropDown.Value = config.sensitivity;
                    
                    % Reserve
                    app.HighReserveButton.Value = config.reserveHighReserve;
                    app.NormalButton.Value = config.reserveNormal;
                    app.LowNoiseButton.Value = config.reserveLowNoise;
                    
                    % Line Filters
                    app.LineCheckBox.Value = config.filterLine;
                    app.xLineCheckBox.Value = config.filter2xLine;
                    
                    % Auxiliar Outputs
                    app.Output1EditField.Value = config.auxiliarOutput1;
                    app.Output2EditField.Value = config.auxiliarOutput2;
                    app.Output3EditField.Value = config.auxiliarOutput3;
                    app.Output4EditField.Value = config.auxiliarOutput4;
                    
                    % Channel 1 Display
                    app.CH1XButton.Value = config.channel1DisplayX;
                    app.CH1RButton.Value = config.channel1DisplayR;
                    app.CH1XnoiseButton.Value = config.channel1DisplayXnoise;
                    app.CH1AuxIn1Button.Value = config.channel1DisplayAuxIn1;
                    app.CH1AuxIn2Button.Value = config.channel1DisplayAuxIn2;
                    app.Ch1RatioNoneButton.Value = config.channel1RatioNone;
                    app.Ch1RatioAuxIn1Button.Value = config.channel1RatioAuxIn1;
                    app.Ch1RatioAuxIn2Button.Value = config.channel1RatioAuxIn2;
                    app.CH1x1Button.Value = config.channel1Expandx1;
                    app.CH1x10Button.Value = config.channel1Expandx10;
                    app.CH1x100Button.Value = config.channel1Expandx100;
                    app.OutputCH1DisplayButton.Value = config.channel1OutputDisplay;
                    app.OutputCH1XButton.Value = config.channel1OutputX;
                    app.OffsetCH1OffButton.Value = config.channel1OffsetOff;
                    app.OffsetCH1OnButton.Value = config.channel1OffsetOn;
                    app.OffsetCH1ModifyEditField.Value = config.channel1Offset;
                    
                    % Channel 2 Display
                    app.CH2YButton.Value = config.channel2DisplayY;
                    app.CH2ThetaButton.Value = config.channel2DisplayTheta;
                    app.CH2YnoiseButton.Value = config.channel2DisplayYnoise;
                    app.CH2AuxIn3Button.Value = config.channel2DisplayAuxIn3;
                    app.CH2AuxIn4Button.Value = config.channel2DisplayAuxIn4;
                    app.Ch2RatioNoneButton.Value = config.channel2RatioNone;
                    app.Ch2RatioAuxIn3Button.Value = config.channel2RatioAuxIn1;
                    app.Ch2RatioAuxIn4Button.Value = config.channel2RatioAuxIn2;
                    app.CH2x1Button.Value = config.channel2Expandx1;
                    app.CH2x10Button.Value = config.channel2Expandx10;
                    app.CH2x100Button.Value = config.channel2Expandx100;
                    app.OutputCH2DisplayButton.Value = config.channel2OutputDisplay;
                    app.OutputCH2YButton.Value = config.channel2OutputY;
                    app.OffsetCH2OffButton.Value = config.channel2OffsetOff;
                    app.OffsetCH2OnButton.Value = config.channel2OffsetOn;
                    app.OffsetCH2ModifyEditField.Value = config.channel2Offset;
                    
                    % Reference
                    app.PhaseEditField.Value = config.referencePhase;
                    app.FreqHzEditField.Value = config.referenceFrequency;
                    app.AmplitudeVEditField.Value = config.referenceAmplitude;
                    app.HarmonicEditField.Value = config.referenceHarmonic;
                    app.ExternalButton.Value = config.referenceSourceExternal;
                    app.InternalButton.Value = config.referenceSourceImternal;
                    app.SineButton.Value = config.referenceTriggerSine;
                    app.PosEdgeButton.Value = config.referenceTriggerPositiveEdge;
                    app.NegEdgeButton.Value = config.referenceTriggerNegativeEdge;
                    
                     % Plot
                    app.PlotLeftDropDown.Value = config.PlotLeftDropDown;
                    app.PlotRightDropDown.Value = config.PlotRightDropDown;
                    app.IntervalsSpinner.Value = config.IntervalsSpinner;
                    app.TimesSpinner.Value = config.TimesSpinner;
                end
                
            else
                msg = 'Configuration not compatible with SR830.';
                title = 'Error';
                icon = 'Error';
                msgbox(msg,title,icon);
            end
        end

        % Value changed function: IntervalsSpinner
        function IntervalsSpinnerValueChanged(app, event)
            
            % Stop Data Collection
            if strcmp(app.dataTimer.Running, 'on')
                stop(app.dataTimer);
                dataTimerWasRunning = true;
            else
                dataTimerWasRunning = false;
            end
            
            %
            value = app.IntervalsSpinner.Value;
            app.TIMEAQUISITIONINTERVAL = value;
            app.dataTimer.Period = value;
            
            % Initial plot is all zeros
            app.plotDataAxisX = 0:app.TIMEAQUISITIONINTERVAL:app.TIMEAQUISITIONTOTAL;
            axisXSize = size(app.plotDataAxisX,2);
            app.plotDataAxisYLeft = zeros(1,axisXSize);
            app.plotDataAxisYRight = zeros(1,axisXSize);
            yyaxis(app.UIAxes,"left");
            ylabel(app.UIAxes,'Channel 1');
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYLeft);
            yyaxis(app.UIAxes,"right");
            ylabel(app.UIAxes,'Channel 2');
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYRight);
            
            % Re-start Data Collection
            if dataTimerWasRunning
                start(app.dataTimer);
            end
        end

        % Value changed function: TimesSpinner
        function TimesSpinnerValueChanged(app, event)
            
            % Stop Data Collection
            if strcmp(app.dataTimer.Running, 'on')
                stop(app.dataTimer);
                dataTimerWasRunning = true;
            else
                dataTimerWasRunning = false;
            end
            
            %
            value = app.TimesSpinner.Value;
            app.TIMEAQUISITIONTOTAL = value;
            
            % Initial plot is all zeros
            app.plotDataAxisX = 0:app.TIMEAQUISITIONINTERVAL:app.TIMEAQUISITIONTOTAL;
            axisXSize = size(app.plotDataAxisX,2);
            app.plotDataAxisYLeft = zeros(1,axisXSize);
            app.plotDataAxisYRight = zeros(1,axisXSize);
            yyaxis(app.UIAxes,"left");
            ylabel(app.UIAxes,'Channel 1');
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYLeft);
            yyaxis(app.UIAxes,"right");
            ylabel(app.UIAxes,'Channel 2');
            app.plotObject = plot(app.UIAxes,app.plotDataAxisX,app.plotDataAxisYRight);
            
            % Re-start Data Collection
            if dataTimerWasRunning
                start(app.dataTimer);
            end
        end

        % Value changed function: PlotLeftDropDown
        function PlotLeftDropDownValueChanged(app, event)

            yyaxis(app.UIAxes,"left");
            app.plotAxisYLabel('left');

        end

        % Value changed function: PlotRightDropDown
        function PlotRightDropDownValueChanged(app, event)
            
            yyaxis(app.UIAxes,"right");
            app.plotAxisYLabel('right');
            
        end

        % Value changed function: ConnectDeviceButton
        function ConnectDeviceButtonValueChanged(app, event)
            value = app.ConnectDeviceButton.Value;
            
            if value
                app.ConnectDeviceButton.Text = {'Disconnect' 'Device'};
                app.ConnectDeviceButton.Enable = false;
                % Open the options dialog and pass inputs
                app.lockinConnectApp = lockinConnect(app,'SR830');
                
                app.ConnectDeviceButton.Enable = true;

                if isempty(app.lockinConnectApp)
                    app.ConnectDeviceButton.Value = false;
                    app.ConnectDeviceButton.Text = {'Connect' 'Device'};
                end
            else
                app.ConnectDeviceButton.Text = {'Connect' 'Device'};
                
                % If timer is running, stop it
                if strcmp(app.dataTimer.Running, 'on')
                    stop(app.dataTimer);
                end
                
                % If Device Connected
                if app.isconnected
                    fclose(app.portObject);
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StanfordResearchSystemsModelSR830UIFigure = uifigure('Visible', 'off');
            app.StanfordResearchSystemsModelSR830UIFigure.Name = 'Stanford Research Systems Model SR830';
            app.StanfordResearchSystemsModelSR830UIFigure.CloseRequestFcn = createCallbackFcn(app, @StanfordResearchSystemsModelSR830UIFigureCloseRequest, true);
            app.TimeConstantPanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.TimeConstantPanel.Title = 'Time Constant';
            app.TimeConstantDropDownLabel = uilabel(app.TimeConstantPanel);
            app.TimeConstantDropDownLabel.Enable = 'off';
            app.TimeConstantDropDownLabel.Text = 'Time Constant';
            app.TimeConstantDropDown = uidropdown(app.TimeConstantPanel);
            app.TimeConstantDropDown.Items = {'10 us', '30 us', '100 us', '300 us', '1 ms', '3 ms', '10 ms', '30 ms', '100 ms', '300 ms', '1 s', '3 s', '10 s', '30 s', '100 s', '300 s', '1 ks', '3 ks', '10 ks', '30 ks'};
            app.TimeConstantDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19'};
            app.TimeConstantDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeConstantDropDownValueChanged, true);
            app.TimeConstantDropDown.Enable = 'off';
            app.TimeConstantDropDown.Value = '1';
            app.LowPassFilterDropDownLabel = uilabel(app.TimeConstantPanel);
            app.LowPassFilterDropDownLabel.Enable = 'off';
            app.LowPassFilterDropDownLabel.Text = 'Low Pass Filter';
            app.LowPassFilterDropDown = uidropdown(app.TimeConstantPanel);
            app.LowPassFilterDropDown.Items = {'6 dB / oct', '12 dB / oct', '18 dB / oct', '24 dB / oct'};
            app.LowPassFilterDropDown.ItemsData = {'0', '1', '2', '3'};
            app.LowPassFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterDropDownValueChanged, true);
            app.LowPassFilterDropDown.Enable = 'off';
            app.LowPassFilterDropDown.Value = '1';
            app.Sync200HzCheckBox = uicheckbox(app.TimeConstantPanel);
            app.Sync200HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @Sync200HzCheckBoxValueChanged, true);
            app.Sync200HzCheckBox.Enable = 'off';
            app.Sync200HzCheckBox.Text = {'Sync'; '(<200 Hz)'};
            app.OverloadLamp_2Label = uilabel(app.TimeConstantPanel);
            app.OverloadLamp_2Label.Text = 'Overload';
            app.TimeConstantOverloadLamp = uilamp(app.TimeConstantPanel);
            app.SignalInputPanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.SignalInputPanel.Title = 'Signal Input';
            app.OverloadLamp_3Label = uilabel(app.SignalInputPanel);
            app.OverloadLamp_3Label.Text = 'Overload';
            app.SignalInputOverloadLamp = uilamp(app.SignalInputPanel);
            app.InputButtonGroup = uibuttongroup(app.SignalInputPanel);
            app.InputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputButtonGroupSelectionChanged, true);
            app.InputButtonGroup.Title = 'Input';
            app.AButton = uiradiobutton(app.InputButtonGroup);
            app.AButton.Enable = 'off';
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.ABButton = uiradiobutton(app.InputButtonGroup);
            app.ABButton.Enable = 'off';
            app.ABButton.Text = 'A-B';
            app.I106Button = uiradiobutton(app.InputButtonGroup);
            app.I106Button.Enable = 'off';
            app.I106Button.Text = 'I (10^6)';
            app.I108Button = uiradiobutton(app.InputButtonGroup);
            app.I108Button.Enable = 'off';
            app.I108Button.Text = 'I (10^8)';
            app.CouplingButtonGroup = uibuttongroup(app.SignalInputPanel);
            app.CouplingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CouplingButtonGroupSelectionChanged, true);
            app.CouplingButtonGroup.Title = 'Coupling';
            app.ACButton = uiradiobutton(app.CouplingButtonGroup);
            app.ACButton.Enable = 'off';
            app.ACButton.Text = 'AC';
            app.ACButton.Value = true;
            app.DCButton = uiradiobutton(app.CouplingButtonGroup);
            app.DCButton.Enable = 'off';
            app.DCButton.Text = 'DC';
            app.ShieldGroundingButtonGroup = uibuttongroup(app.SignalInputPanel);
            app.ShieldGroundingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShieldGroundingButtonGroupSelectionChanged, true);
            app.ShieldGroundingButtonGroup.Title = 'Shield Grounding';
            app.FloatButton = uiradiobutton(app.ShieldGroundingButtonGroup);
            app.FloatButton.Enable = 'off';
            app.FloatButton.Text = 'Float';
            app.FloatButton.Value = true;
            app.GroundButton = uiradiobutton(app.ShieldGroundingButtonGroup);
            app.GroundButton.Enable = 'off';
            app.GroundButton.Text = 'Ground';
            app.SensitivityPanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.SensitivityPanel.Title = 'Sensitivity';
            app.DropDown = uidropdown(app.SensitivityPanel);
            app.DropDown.Items = {'2 nV / fA', '5 nV / fA', '10 nV / fA', '20 nV / fA', '50 nV / fA', '100 nV / fA', '200 nV / fA', '500 nV / fA', '1 uV / pA', '2 uV / pA', '5 uV / pA', '10 uV / pA', '20 uV / pA', '50 uV / pA', '100 uV / pA', '200 uV / pA', '500 uV / pA', '1 mV / nA', '2 mV / nA', '5 mV / nA', '10 mV / nA', '20 mV / nA', '50 mV / nA', '100 mV / nA', '200 mV / nA', '500 mV / nA', '1 V/ uA'};
            app.DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = '26';
            app.OverloadLampLabel = uilabel(app.SensitivityPanel);
            app.OverloadLampLabel.Text = 'Overload';
            app.SensitivityOverloadLamp = uilamp(app.SensitivityPanel);
            app.ReserveButtonGroup = uibuttongroup(app.StanfordResearchSystemsModelSR830UIFigure);
            app.ReserveButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReserveButtonGroupSelectionChanged, true);
            app.ReserveButtonGroup.Title = 'Reserve';
            app.HighReserveButton = uiradiobutton(app.ReserveButtonGroup);
            app.HighReserveButton.Enable = 'off';
            app.HighReserveButton.Text = 'High Reserve';
            app.NormalButton = uiradiobutton(app.ReserveButtonGroup);
            app.NormalButton.Enable = 'off';
            app.NormalButton.Text = 'Normal';
            app.LowNoiseButton = uiradiobutton(app.ReserveButtonGroup);
            app.LowNoiseButton.Enable = 'off';
            app.LowNoiseButton.Text = 'Low Noise';
            app.LowNoiseButton.Value = true;
            app.Channel1Panel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.Channel1Panel.Title = 'Channel 1';
            app.DisplayCH1EditField = uieditfield(app.Channel1Panel, 'numeric');
            app.DisplayCH1EditField.Editable = 'off';
            app.DisplayButtonGroup = uibuttongroup(app.Channel1Panel);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.CH1XButton = uiradiobutton(app.DisplayButtonGroup);
            app.CH1XButton.Enable = 'off';
            app.CH1XButton.Text = 'X';
            app.CH1XButton.Value = true;
            app.CH1RButton = uiradiobutton(app.DisplayButtonGroup);
            app.CH1RButton.Enable = 'off';
            app.CH1RButton.Text = 'R';
            app.CH1XnoiseButton = uiradiobutton(app.DisplayButtonGroup);
            app.CH1XnoiseButton.Enable = 'off';
            app.CH1XnoiseButton.Text = 'X noise';
            app.CH1AuxIn1Button = uiradiobutton(app.DisplayButtonGroup);
            app.CH1AuxIn1Button.Enable = 'off';
            app.CH1AuxIn1Button.Text = 'Aux In 1';
            app.CH1AuxIn2Button = uiradiobutton(app.DisplayButtonGroup);
            app.CH1AuxIn2Button.Enable = 'off';
            app.CH1AuxIn2Button.Text = 'Aux In 2';
            app.RatioButtonGroup = uibuttongroup(app.Channel1Panel);
            app.RatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RatioButtonGroupSelectionChanged, true);
            app.RatioButtonGroup.Title = 'Ratio';
            app.Ch1RatioNoneButton = uiradiobutton(app.RatioButtonGroup);
            app.Ch1RatioNoneButton.Enable = 'off';
            app.Ch1RatioNoneButton.Text = 'None';
            app.Ch1RatioNoneButton.Value = true;
            app.Ch1RatioAuxIn1Button = uiradiobutton(app.RatioButtonGroup);
            app.Ch1RatioAuxIn1Button.Enable = 'off';
            app.Ch1RatioAuxIn1Button.Text = 'Aux In 1';
            app.Ch1RatioAuxIn2Button = uiradiobutton(app.RatioButtonGroup);
            app.Ch1RatioAuxIn2Button.Enable = 'off';
            app.Ch1RatioAuxIn2Button.Text = 'Aux In 2';
            app.ExpandButtonGroup = uibuttongroup(app.Channel1Panel);
            app.ExpandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExpandButtonGroupSelectionChanged, true);
            app.ExpandButtonGroup.Title = 'Expand';
            app.CH1x1Button = uiradiobutton(app.ExpandButtonGroup);
            app.CH1x1Button.Enable = 'off';
            app.CH1x1Button.Text = 'x 1';
            app.CH1x1Button.Value = true;
            app.CH1x10Button = uiradiobutton(app.ExpandButtonGroup);
            app.CH1x10Button.Enable = 'off';
            app.CH1x10Button.Text = 'x 10';
            app.CH1x100Button = uiradiobutton(app.ExpandButtonGroup);
            app.CH1x100Button.Enable = 'off';
            app.CH1x100Button.Text = 'x 100';
            app.OutputCH1ButtonGroup = uibuttongroup(app.Channel1Panel);
            app.OutputCH1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputCH1ButtonGroupSelectionChanged, true);
            app.OutputCH1ButtonGroup.Title = 'Output';
            app.OutputCH1DisplayButton = uiradiobutton(app.OutputCH1ButtonGroup);
            app.OutputCH1DisplayButton.Enable = 'off';
            app.OutputCH1DisplayButton.Text = 'Display';
            app.OutputCH1DisplayButton.Value = true;
            app.OutputCH1XButton = uiradiobutton(app.OutputCH1ButtonGroup);
            app.OutputCH1XButton.Enable = 'off';
            app.OutputCH1XButton.Text = 'X';
            app.OffsetButtonGroup = uibuttongroup(app.Channel1Panel);
            app.OffsetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OffsetButtonGroupSelectionChanged, true);
            app.OffsetButtonGroup.Title = 'Offset';
            app.OffsetCH1OnButton = uiradiobutton(app.OffsetButtonGroup);
            app.OffsetCH1OnButton.Enable = 'off';
            app.OffsetCH1OnButton.Text = 'On';
            app.OffsetCH1OffButton = uiradiobutton(app.OffsetButtonGroup);
            app.OffsetCH1OffButton.Enable = 'off';
            app.OffsetCH1OffButton.Text = 'Off';
            app.OffsetCH1OffButton.Value = true;
            app.OffsetCH1AutoButton = uibutton(app.OffsetButtonGroup, 'push');
            app.OffsetCH1AutoButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetCH1AutoButtonPushed, true);
            app.OffsetCH1AutoButton.Enable = 'off';
            app.OffsetCH1AutoButton.Text = 'Auto';
            app.OffsetCH1ModifyEditFieldLabel = uilabel(app.OffsetButtonGroup);
            app.OffsetCH1ModifyEditFieldLabel.Enable = 'off';
            app.OffsetCH1ModifyEditFieldLabel.Text = 'Offset (%)';
            app.OffsetCH1ModifyEditField = uieditfield(app.OffsetButtonGroup, 'numeric');
            app.OffsetCH1ModifyEditField.Limits = [-105 105];
            app.OffsetCH1ModifyEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetCH1ModifyEditFieldValueChanged, true);
            app.OffsetCH1ModifyEditField.Enable = 'off';
            app.FilterPanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.FilterPanel.Title = 'Filter';
            app.LineCheckBox = uicheckbox(app.FilterPanel);
            app.LineCheckBox.ValueChangedFcn = createCallbackFcn(app, @LineCheckBoxValueChanged, true);
            app.LineCheckBox.Enable = 'off';
            app.LineCheckBox.Text = 'Line';
            app.xLineCheckBox = uicheckbox(app.FilterPanel);
            app.xLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @xLineCheckBoxValueChanged, true);
            app.xLineCheckBox.Enable = 'off';
            app.xLineCheckBox.Text = '2x Line';
            app.AutoPanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.AutoPanel.Title = 'Auto';
            app.PhasButton = uibutton(app.AutoPanel, 'push');
            app.PhasButton.ButtonPushedFcn = createCallbackFcn(app, @PhasButtonPushed, true);
            app.PhasButton.Enable = 'off';
            app.PhasButton.Text = 'Phas';
            app.GainButton = uibutton(app.AutoPanel, 'push');
            app.GainButton.ButtonPushedFcn = createCallbackFcn(app, @GainButtonPushed, true);
            app.GainButton.Enable = 'off';
            app.GainButton.Text = 'Gain';
            app.ReserveButton = uibutton(app.AutoPanel, 'push');
            app.ReserveButton.ButtonPushedFcn = createCallbackFcn(app, @ReserveButtonPushed, true);
            app.ReserveButton.Enable = 'off';
            app.ReserveButton.Text = 'Reserve';
            app.LampPhas = uilamp(app.AutoPanel);
            app.LampGain = uilamp(app.AutoPanel);
            app.LampReserve = uilamp(app.AutoPanel);
            app.UIAxes = uiaxes(app.StanfordResearchSystemsModelSR830UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Voltage / Current')
            app.AuxOutPanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.AuxOutPanel.Title = 'Aux Out';
            app.Output2EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output2EditFieldLabel.Text = 'Output 2';
            app.Output2EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output2EditField.Limits = [-10.5 10.5];
            app.Output2EditField.ValueDisplayFormat = '%11.5g';
            app.Output2EditField.ValueChangedFcn = createCallbackFcn(app, @Output2EditFieldValueChanged, true);
            app.Output2EditField.Editable = 'off';
            app.Output3EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output3EditFieldLabel.Text = 'Output 3';
            app.Output3EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output3EditField.Limits = [-10.5 10.5];
            app.Output3EditField.ValueDisplayFormat = '%11.5g';
            app.Output3EditField.ValueChangedFcn = createCallbackFcn(app, @Output3EditFieldValueChanged, true);
            app.Output3EditField.Editable = 'off';
            app.Output4EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output4EditFieldLabel.Text = 'Output 4';
            app.Output4EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output4EditField.Limits = [-10.5 10.5];
            app.Output4EditField.ValueDisplayFormat = '%11.5g';
            app.Output4EditField.ValueChangedFcn = createCallbackFcn(app, @Output4EditFieldValueChanged, true);
            app.Output4EditField.Editable = 'off';
            app.Output1EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output1EditFieldLabel.Text = 'Output 1';
            app.Output1EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output1EditField.Limits = [-10.5 10.5];
            app.Output1EditField.ValueDisplayFormat = '%11.5g';
            app.Output1EditField.ValueChangedFcn = createCallbackFcn(app, @Output1EditFieldValueChanged, true);
            app.Output1EditField.Editable = 'off';
            app.ReferencePanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.ReferencePanel.Title = 'Reference';
            app.TriggerButtonGroup = uibuttongroup(app.ReferencePanel);
            app.TriggerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TriggerButtonGroupSelectionChanged, true);
            app.TriggerButtonGroup.Title = 'Trigger';
            app.SineButton = uiradiobutton(app.TriggerButtonGroup);
            app.SineButton.Enable = 'off';
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.PosEdgeButton = uiradiobutton(app.TriggerButtonGroup);
            app.PosEdgeButton.Enable = 'off';
            app.PosEdgeButton.Text = 'Pos. Edge';
            app.NegEdgeButton = uiradiobutton(app.TriggerButtonGroup);
            app.NegEdgeButton.Enable = 'off';
            app.NegEdgeButton.Text = 'Neg. Edge';
            app.SourceButtonGroup = uibuttongroup(app.ReferencePanel);
            app.SourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceButtonGroupSelectionChanged, true);
            app.SourceButtonGroup.Title = 'Source';
            app.InternalButton = uiradiobutton(app.SourceButtonGroup);
            app.InternalButton.Enable = 'off';
            app.InternalButton.Text = 'Internal';
            app.InternalButton.Value = true;
            app.ExternalButton = uiradiobutton(app.SourceButtonGroup);
            app.ExternalButton.Enable = 'off';
            app.ExternalButton.Text = 'External';
            app.PhaseLabel = uilabel(app.ReferencePanel);
            app.PhaseLabel.Text = 'Phase(º)';
            app.PhaseEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.PhaseEditField.Limits = [-360 729.99];
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.PhaseEditField.Editable = 'off';
            app.FreqHzEditFieldLabel = uilabel(app.ReferencePanel);
            app.FreqHzEditFieldLabel.Text = 'Freq. (Hz)';
            app.FreqHzEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.FreqHzEditField.Limits = [0.001 102000];
            app.FreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanged, true);
            app.FreqHzEditField.Editable = 'off';
            app.FreqHzEditField.Value = 1000;
            app.AmplitudeVEditFieldLabel = uilabel(app.ReferencePanel);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude(V)';
            app.AmplitudeVEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.AmplitudeVEditField.Limits = [0.004 5];
            app.AmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditFieldValueChanged, true);
            app.AmplitudeVEditField.Editable = 'off';
            app.AmplitudeVEditField.Value = 1;
            app.HarmonicEditFieldLabel = uilabel(app.ReferencePanel);
            app.HarmonicEditFieldLabel.Text = 'Harmonic';
            app.HarmonicEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.HarmonicEditField.Limits = [1 19999];
            app.HarmonicEditField.RoundFractionalValues = 'on';
            app.HarmonicEditField.ValueDisplayFormat = '%.0f';
            app.HarmonicEditField.ValueChangedFcn = createCallbackFcn(app, @HarmonicEditFieldValueChanged, true);
            app.HarmonicEditField.Editable = 'off';
            app.HarmonicEditField.Value = 1;
            app.Channel2Panel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.Channel2Panel.Title = 'Channel 2';
            app.DisplayCH2EditField = uieditfield(app.Channel2Panel, 'numeric');
            app.DisplayCH2EditField.Editable = 'off';
            app.DisplayButtonGroup_2 = uibuttongroup(app.Channel2Panel);
            app.DisplayButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroup_2SelectionChanged, true);
            app.DisplayButtonGroup_2.Title = 'Display';
            app.CH2YButton = uiradiobutton(app.DisplayButtonGroup_2);
            app.CH2YButton.Enable = 'off';
            app.CH2YButton.Text = 'Y';
            app.CH2YButton.Value = true;
            app.CH2ThetaButton = uiradiobutton(app.DisplayButtonGroup_2);
            app.CH2ThetaButton.Enable = 'off';
            app.CH2ThetaButton.Text = 'Theta';
            app.CH2YnoiseButton = uiradiobutton(app.DisplayButtonGroup_2);
            app.CH2YnoiseButton.Enable = 'off';
            app.CH2YnoiseButton.Text = 'Y noise';
            app.CH2AuxIn3Button = uiradiobutton(app.DisplayButtonGroup_2);
            app.CH2AuxIn3Button.Enable = 'off';
            app.CH2AuxIn3Button.Text = 'Aux In 3';
            app.CH2AuxIn4Button = uiradiobutton(app.DisplayButtonGroup_2);
            app.CH2AuxIn4Button.Enable = 'off';
            app.CH2AuxIn4Button.Text = 'Aux In 4';
            app.CH2RatioButtonGroup = uibuttongroup(app.Channel2Panel);
            app.CH2RatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH2RatioButtonGroupSelectionChanged, true);
            app.CH2RatioButtonGroup.Title = 'Ratio';
            app.Ch2RatioNoneButton = uiradiobutton(app.CH2RatioButtonGroup);
            app.Ch2RatioNoneButton.Enable = 'off';
            app.Ch2RatioNoneButton.Text = 'None';
            app.Ch2RatioNoneButton.Value = true;
            app.Ch2RatioAuxIn3Button = uiradiobutton(app.CH2RatioButtonGroup);
            app.Ch2RatioAuxIn3Button.Enable = 'off';
            app.Ch2RatioAuxIn3Button.Text = 'Aux In 3';
            app.Ch2RatioAuxIn4Button = uiradiobutton(app.CH2RatioButtonGroup);
            app.Ch2RatioAuxIn4Button.Enable = 'off';
            app.Ch2RatioAuxIn4Button.Text = 'Aux In 4';
            app.ExpandCH1ButtonGroup = uibuttongroup(app.Channel2Panel);
            app.ExpandCH1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExpandCH1ButtonGroupSelectionChanged, true);
            app.ExpandCH1ButtonGroup.Title = 'Expand';
            app.CH2x1Button = uiradiobutton(app.ExpandCH1ButtonGroup);
            app.CH2x1Button.Enable = 'off';
            app.CH2x1Button.Text = 'x 1';
            app.CH2x1Button.Value = true;
            app.CH2x10Button = uiradiobutton(app.ExpandCH1ButtonGroup);
            app.CH2x10Button.Enable = 'off';
            app.CH2x10Button.Text = 'x 10';
            app.CH2x100Button = uiradiobutton(app.ExpandCH1ButtonGroup);
            app.CH2x100Button.Enable = 'off';
            app.CH2x100Button.Text = 'x 100';
            app.OutputCH2ButtonGroup = uibuttongroup(app.Channel2Panel);
            app.OutputCH2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputCH2ButtonGroupSelectionChanged, true);
            app.OutputCH2ButtonGroup.Title = 'Output';
            app.OutputCH2DisplayButton = uiradiobutton(app.OutputCH2ButtonGroup);
            app.OutputCH2DisplayButton.Enable = 'off';
            app.OutputCH2DisplayButton.Text = 'Display';
            app.OutputCH2DisplayButton.Value = true;
            app.OutputCH2YButton = uiradiobutton(app.OutputCH2ButtonGroup);
            app.OutputCH2YButton.Enable = 'off';
            app.OutputCH2YButton.Text = 'Y';
            app.OffsetCH2ButtonGroup = uibuttongroup(app.Channel2Panel);
            app.OffsetCH2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OffsetCH2ButtonGroupSelectionChanged, true);
            app.OffsetCH2ButtonGroup.Title = 'Offset';
            app.OffsetCH2OnButton = uiradiobutton(app.OffsetCH2ButtonGroup);
            app.OffsetCH2OnButton.Enable = 'off';
            app.OffsetCH2OnButton.Text = 'On';
            app.OffsetCH2OnButton.Value = true;
            app.OffsetCH2OffButton = uiradiobutton(app.OffsetCH2ButtonGroup);
            app.OffsetCH2OffButton.Enable = 'off';
            app.OffsetCH2OffButton.Text = 'Off';
            app.OffsetCH2AutoButton = uibutton(app.OffsetCH2ButtonGroup, 'push');
            app.OffsetCH2AutoButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetCH2AutoButtonPushed, true);
            app.OffsetCH2AutoButton.Enable = 'off';
            app.OffsetCH2AutoButton.Text = 'Auto';
            app.OffsetCH2ModifyEditFieldLabel = uilabel(app.OffsetCH2ButtonGroup);
            app.OffsetCH2ModifyEditFieldLabel.Enable = 'off';
            app.OffsetCH2ModifyEditFieldLabel.Text = 'Offset (%)';
            app.OffsetCH2ModifyEditField = uieditfield(app.OffsetCH2ButtonGroup, 'numeric');
            app.OffsetCH2ModifyEditField.Limits = [-105 105];
            app.OffsetCH2ModifyEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetCH2ModifyEditFieldValueChanged, true);
            app.OffsetCH2ModifyEditField.Enable = 'off';
            app.AquisitionPlotPanel = uipanel(app.StanfordResearchSystemsModelSR830UIFigure);
            app.AquisitionPlotPanel.Title = 'Aquisition / Plot';
            app.StartButton = uibutton(app.AquisitionPlotPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AquisitionPlotPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.CleanButton = uibutton(app.AquisitionPlotPanel, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Enable = 'off';
            app.CleanButton.Text = 'Clean';
            app.PlotLeftDropDownLabel = uilabel(app.AquisitionPlotPanel);
            app.PlotLeftDropDownLabel.Enable = 'off';
            app.PlotLeftDropDownLabel.Text = 'Left';
            app.PlotLeftDropDown = uidropdown(app.AquisitionPlotPanel);
            app.PlotLeftDropDown.Items = {'None', 'X', 'Y', 'R', 'Theta', 'Aux In 1', 'Aux In 2', 'Aux In 3', 'Aux In 4', 'Frequency', 'CH1 Display', 'CH2 Display'};
            app.PlotLeftDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.PlotLeftDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotLeftDropDownValueChanged, true);
            app.PlotLeftDropDown.Enable = 'off';
            app.PlotLeftDropDown.Value = '10';
            app.PlotRightDropDownLabel = uilabel(app.AquisitionPlotPanel);
            app.PlotRightDropDownLabel.Enable = 'off';
            app.PlotRightDropDownLabel.Text = 'Right';
            app.PlotRightDropDown = uidropdown(app.AquisitionPlotPanel);
            app.PlotRightDropDown.Items = {'None', 'X', 'Y', 'R', 'Theta', 'Aux In 1', 'Aux In 2', 'Aux In 3', 'Aux In 4', 'Frequency', 'CH1 Display', 'CH2 Display'};
            app.PlotRightDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.PlotRightDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotRightDropDownValueChanged, true);
            app.PlotRightDropDown.Enable = 'off';
            app.PlotRightDropDown.Value = '11';
            app.IntervalsSpinnerLabel = uilabel(app.AquisitionPlotPanel);
            app.IntervalsSpinnerLabel.Enable = 'off';
            app.IntervalsSpinnerLabel.Text = 'Interval (s)';
            app.IntervalsSpinner = uispinner(app.AquisitionPlotPanel);
            app.IntervalsSpinner.Step = 0.1;
            app.IntervalsSpinner.Limits = [0.1 60];
            app.IntervalsSpinner.ValueChangedFcn = createCallbackFcn(app, @IntervalsSpinnerValueChanged, true);
            app.IntervalsSpinner.Editable = 'off';
            app.IntervalsSpinner.Enable = 'off';
            app.IntervalsSpinner.Tooltip = {'Time internal  between data points.'};
            app.IntervalsSpinner.Value = 0.3;
            app.TimesSpinnerLabel = uilabel(app.AquisitionPlotPanel);
            app.TimesSpinnerLabel.Enable = 'off';
            app.TimesSpinnerLabel.Text = 'Time(s)';
            app.TimesSpinner = uispinner(app.AquisitionPlotPanel);
            app.TimesSpinner.Limits = [10 3200];
            app.TimesSpinner.RoundFractionalValues = 'on';
            app.TimesSpinner.ValueDisplayFormat = '%.0f';
            app.TimesSpinner.ValueChangedFcn = createCallbackFcn(app, @TimesSpinnerValueChanged, true);
            app.TimesSpinner.Editable = 'off';
            app.TimesSpinner.Enable = 'off';
            app.TimesSpinner.Tooltip = {'Time internal between data points.'};
            app.TimesSpinner.Value = 60;
            app.ForceUpdateButton = uibutton(app.StanfordResearchSystemsModelSR830UIFigure, 'push');
            app.ForceUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ForceUpdateButtonPushed, true);
            app.ForceUpdateButton.Enable = 'off';
            app.ForceUpdateButton.Text = {'Force'; 'Update'};
            app.DeviceIdendificationEditField = uieditfield(app.StanfordResearchSystemsModelSR830UIFigure, 'text');
            app.DeviceIdendificationEditField.Editable = 'off';
            app.SaveConfigButton = uibutton(app.StanfordResearchSystemsModelSR830UIFigure, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Enable = 'off';
            app.SaveConfigButton.Text = {'Save'; 'Config.'};
            app.LoadConfigButton = uibutton(app.StanfordResearchSystemsModelSR830UIFigure, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Enable = 'off';
            app.LoadConfigButton.Text = {'Load'; 'Config.'};
            app.ConnectDeviceButton = uibutton(app.StanfordResearchSystemsModelSR830UIFigure, 'state');
            app.ConnectDeviceButton.ValueChangedFcn = createCallbackFcn(app, @ConnectDeviceButtonValueChanged, true);
            app.ConnectDeviceButton.Text = {'Connect'; 'Device'};
            % Show the figure after all components are created
            app.StanfordResearchSystemsModelSR830UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lockinSR830

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StanfordResearchSystemsModelSR830UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StanfordResearchSystemsModelSR830UIFigure)
        end
    end
end