classdef lockinSR865 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StanfordResearchSystemsModelSR865AUIFigure  matlab.ui.Figure
        SensitivityPanel               matlab.ui.container.Panel
        DropDown                       matlab.ui.control.DropDown
        TimeConstantPanel              matlab.ui.container.Panel
        TimeConstantDropDown           matlab.ui.control.DropDown
        InputRangePanel                matlab.ui.container.Panel
        InputRangeDropDown             matlab.ui.control.DropDown
        InputRangeSignalStrength       matlab.ui.control.LinearGauge
        OverloadLamp_3Label_3          matlab.ui.control.Label
        InputRangeOverloadLamp         matlab.ui.control.Lamp
        VoltagePanel                   matlab.ui.container.Panel
        InputButtonGroup               matlab.ui.container.ButtonGroup
        AButton                        matlab.ui.control.RadioButton
        ABButton                       matlab.ui.control.RadioButton
        CouplingButtonGroup            matlab.ui.container.ButtonGroup
        ACButton                       matlab.ui.control.RadioButton
        DCButton                       matlab.ui.control.RadioButton
        ShieldGroundingButtonGroup     matlab.ui.container.ButtonGroup
        FloatButton                    matlab.ui.control.RadioButton
        GroundButton                   matlab.ui.control.RadioButton
        InputButtonGroup_3             matlab.ui.container.ButtonGroup
        VoltageButton                  matlab.ui.control.RadioButton
        CurrentButton                  matlab.ui.control.RadioButton
        CurrentButtonGroup             matlab.ui.container.ButtonGroup
        I1E6Button                     matlab.ui.control.RadioButton
        I1E8Button                     matlab.ui.control.RadioButton
        FiltersPanel                   matlab.ui.container.Panel
        LowPassFilterDropDown          matlab.ui.control.DropDown
        SyncCheckBox                   matlab.ui.control.CheckBox
        AdvancedCheckBox               matlab.ui.control.CheckBox
        OvldSyncLabel                  matlab.ui.control.Label
        SyncOverloadLamp               matlab.ui.control.Lamp
        DisplayPanel                   matlab.ui.container.Panel
        DisplayGreenEditField          matlab.ui.control.NumericEditField
        DisplayBlueEditField           matlab.ui.control.NumericEditField
        DisplayYellowEditField         matlab.ui.control.NumericEditField
        DisplayOrangeEditField         matlab.ui.control.NumericEditField
        DisplayGreenDropDown           matlab.ui.control.DropDown
        DisplayBlueDropDown            matlab.ui.control.DropDown
        DisplayYellowDropDown          matlab.ui.control.DropDown
        DisplayOrangeDropDown          matlab.ui.control.DropDown
        BlankPanelBlankingCheckBox     matlab.ui.control.CheckBox
        OverloadGreenLamp              matlab.ui.control.Lamp
        OverloadYellowLamp             matlab.ui.control.Lamp
        OverloadBlueLamp               matlab.ui.control.Lamp
        OverloadOrangeLamp             matlab.ui.control.Lamp
        AutoPanel                      matlab.ui.container.Panel
        PhaseButton                    matlab.ui.control.Button
        ScaleButton                    matlab.ui.control.Button
        RangeButton                    matlab.ui.control.Button
        LampPhase                      matlab.ui.control.Lamp
        LampScale                      matlab.ui.control.Lamp
        LampRange                      matlab.ui.control.Lamp
        AuxOutPanel                    matlab.ui.container.Panel
        Output2EditFieldLabel          matlab.ui.control.Label
        Output2EditField               matlab.ui.control.NumericEditField
        Output3EditFieldLabel          matlab.ui.control.Label
        Output3EditField               matlab.ui.control.NumericEditField
        Output4EditFieldLabel          matlab.ui.control.Label
        Output4EditField               matlab.ui.control.NumericEditField
        Output1EditFieldLabel          matlab.ui.control.Label
        Output1EditField               matlab.ui.control.NumericEditField
        DeviceIdendificationEditField  matlab.ui.control.EditField
        ReferencePanel                 matlab.ui.container.Panel
        TriggerButtonGroup             matlab.ui.container.ButtonGroup
        SineButton                     matlab.ui.control.RadioButton
        PosEdgeButton                  matlab.ui.control.RadioButton
        NegEdgeButton                  matlab.ui.control.RadioButton
        SourceButtonGroup              matlab.ui.container.ButtonGroup
        InternalButton                 matlab.ui.control.RadioButton
        ExternalButton                 matlab.ui.control.RadioButton
        DualButton                     matlab.ui.control.RadioButton
        ChopButton                     matlab.ui.control.RadioButton
        PhaseLabel                     matlab.ui.control.Label
        PhaseEditField                 matlab.ui.control.NumericEditField
        FreqExternalHzEditFieldLabel   matlab.ui.control.Label
        FreqExternalHzEditField        matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel       matlab.ui.control.Label
        AmplitudeVEditField            matlab.ui.control.NumericEditField
        HarmonicEditFieldLabel         matlab.ui.control.Label
        HarmonicEditField              matlab.ui.control.NumericEditField
        InputResistorButtonGroup       matlab.ui.container.ButtonGroup
        OhmsButton                     matlab.ui.control.RadioButton
        MOhmsButton                    matlab.ui.control.RadioButton
        DCLevelVEditFieldLabel         matlab.ui.control.Label
        DCLevelVEditField              matlab.ui.control.NumericEditField
        DCLevelButtonGroup             matlab.ui.container.ButtonGroup
        DifferenceButton               matlab.ui.control.RadioButton
        CommonButton                   matlab.ui.control.RadioButton
        ChopperBladeButtonGroup        matlab.ui.container.ButtonGroup
        ChopperWheel6SlotButton        matlab.ui.control.RadioButton
        ChopperWheel30SlotButton       matlab.ui.control.RadioButton
        ChopperWheelPhaseLabel         matlab.ui.control.Label
        ChopperWheelPhaseEditField     matlab.ui.control.NumericEditField
        TimeBaseButtonGroup            matlab.ui.container.ButtonGroup
        TimeBaseAutoButton             matlab.ui.control.RadioButton
        TimeBaseInternalButton         matlab.ui.control.RadioButton
        FreqInternalHzEditFieldLabel   matlab.ui.control.Label
        FreqInternalHzEditField        matlab.ui.control.NumericEditField
        UIAxesLeft                     matlab.ui.control.UIAxes
        ChannelOutput1Panel            matlab.ui.container.Panel
        CH1OutputOutputButtonGroup     matlab.ui.container.ButtonGroup
        CH1OutputXButton               matlab.ui.control.RadioButton
        CH1OutputRButton               matlab.ui.control.RadioButton
        CH1OutputRatioButtonGroup      matlab.ui.container.ButtonGroup
        CH1OutputRatioOffButton        matlab.ui.control.RadioButton
        CH1OutputRatioOnButton         matlab.ui.control.RadioButton
        CH1OutputExpandButtonGroup     matlab.ui.container.ButtonGroup
        CH1Outputx1Button              matlab.ui.control.RadioButton
        CH1Outputx10Button             matlab.ui.control.RadioButton
        CH1Outputx100Button            matlab.ui.control.RadioButton
        CH1OutputOffsetButtonGroup     matlab.ui.container.ButtonGroup
        CH1OutputOffsetOnButton        matlab.ui.control.RadioButton
        CH1OutputOffsetOffButton       matlab.ui.control.RadioButton
        OffsetCH1ModifyEditFieldLabel  matlab.ui.control.Label
        CH1OutputOffsetValueEditField  matlab.ui.control.NumericEditField
        OverloadLamp_3Label            matlab.ui.control.Label
        CH1OutputOverloadLamp          matlab.ui.control.Lamp
        CH1OutputOffsetAutoButton      matlab.ui.control.Button
        ChannelOutput2Panel            matlab.ui.container.Panel
        CH2OutputOutputButtonGroup     matlab.ui.container.ButtonGroup
        CH2OutputYButton               matlab.ui.control.RadioButton
        CH2OutputThetaButton           matlab.ui.control.RadioButton
        CH2OutputRatioButtonGroup      matlab.ui.container.ButtonGroup
        CH2OutputRatioOffButton        matlab.ui.control.RadioButton
        CH2OutputRatioOnButton         matlab.ui.control.RadioButton
        CH2OutputExpandButtonGroup     matlab.ui.container.ButtonGroup
        CH2Outputx1Button              matlab.ui.control.RadioButton
        CH2Outputx10Button             matlab.ui.control.RadioButton
        CH2Outputx100Button            matlab.ui.control.RadioButton
        CH2OutputOffsetButtonGroup     matlab.ui.container.ButtonGroup
        CH2OutputOffsetOnButton        matlab.ui.control.RadioButton
        CH2OutputOffsetOffButton       matlab.ui.control.RadioButton
        OffsetCH1ModifyEditFieldLabel_2  matlab.ui.control.Label
        CH2OutputOffsetValueEditField  matlab.ui.control.NumericEditField
        OverloadLamp_3Label_2          matlab.ui.control.Label
        CH2OutputOverloadLamp          matlab.ui.control.Lamp
        CH2OutputOffsetAutoButton      matlab.ui.control.Button
        UIAxesRight                    matlab.ui.control.UIAxes
        ForceUpdateButton              matlab.ui.control.Button
        SaveConfigButton               matlab.ui.control.Button
        LoadConfigButton               matlab.ui.control.Button
        AquisitionPlotPanel            matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        CleanButton                    matlab.ui.control.Button
        TimesSpinnerLabel              matlab.ui.control.Label
        TimesSpinner                   matlab.ui.control.Spinner
        IntervalsSpinnerLabel          matlab.ui.control.Label
        IntervalsSpinner               matlab.ui.control.Spinner
        ConnectDeviceButton            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        % Time
        TIMEOUT = 1;                    % Communication time out.
        TIMEAQUISITIONINTERVAL = .3;    % Aquisition interval time (obtain data from device)
        TIMEAQUISITIONTOTAL = 60;       % Amount of time the data is displayed.
        
        % 
        portObject;
        portInfo;                       % Device port information.
        isconnected = false;            % Flag if device connected.
        dataTimer;                      % Timer to retrive data from device.
        
        % Plot
        plotObjectLeft;                 % Plot object Left right hand (line green and yellow).
        plotObjectRight;                % Plot object Right right hand (line blue and orange).
        plotDataGreen;                  % Plot data for green line.
        plotDataYellow;                 % Plot data for yellow line.
        plotDataBlue;                   % Plot data for blue line.
        plotDataOrange;                 % Plot data for orange line.
        plotDataAxisX;                  % Plot date for axis x.
        
        %
        frequencyUnlock;                % External Reference Frequency Unlock.
        
        % External Apps
        lockinConnectApp;               % App to connect device.
    end
    
    methods (Access = public)
        
        %--------------------------------
        % FUNCTION: CONNECT DEVICE
        % inputs: port information
        function connect(app,varargin)
            % Organizing Input Information
            if nargin == 2

                app.portObject = varargin{1};
                
            else
                for i = 1:(nargin - 1)
                    
                    switch lower(varargin{i})               % Key words are always char/string.
                        
                        case {'portobject' 'port object'}
                            i = i+1;
                            app.portObject = varargin{i};
                            
                        case {'serialport' 'serial port'}
                            app.portInfo.portChoice = 'serialport';

                        case 'gpib'
                            app.portInfo.portChoice = 'gpib';
                            
                        case 'usb'
                            app.portInfo.portChoice = 'usb';

                        case 'port'
                            i = i+1;
                            app.portInfo.serialPort.port = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'serialport';
                            end
                            
                        case 'baudrate'
                            i = i+1;
                            app.portInfo.serialPort.baudRate = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'serialport';
                            end
                            
                        case 'parity'
                            i = i+1;
                            app.portInfo.serialPort.parity = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'serialport';
                            end
                            
                        case 'vendor'
                            i = i+1;
                            app.portInfo.gpib.vendor = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'gpib';
                            end
                            
                        case {'boardindex' 'board index'}
                            i = i+1;
                            app.portInfo.gpib.boardindex = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'gpib';
                            end
                            
                        case {'primaryaddress' 'primary address'}
                            i = i+1;
                            app.portInfo.gpib.primaryAddress = varargin{i};

                            % If port choise is empty, adds port choise
                            if isempty(app.portInfo.portChoice)
                                app.portInfo.portChoice = 'gpib';
                            end
                            
                        case {'resourcename' 'resource name'}
                            i = i+1;
                            app.portInfo.resourceName = varargin{i};
                            
                        case 'terminator'
                            i = i+1;
                            app.portInfo.terminator = varargin{i};

                    end
                end
                
                % Connect
                switch lower(app.portInfo.portChoice)               % Choose connection through serial port or GPIB
                    case {'serialport' 'serial port'}
                        port       = app.portInfo.serialPort.port;        % Serial port COM.
                        baudRate   = app.portInfo.serialPort.baudRate;    % Serial port baudrate.
                        dataBits   = 8;                                   % Serial port dataBits, fixed by the maker.
                        stopBits   = 1;                                   % Serial port stopBits, fixed by the maker.
                        parity     = app.portInfo.serialPort.parity;      % Serial port parity, fixed by the maker.
                        terminator = app.portInfo.terminator;
                        
                        try
                            app.portObject = serial(port,...                  % Create serial port object.
                                                    'Baudrate',baudRate,...
                                                    'DataBits',dataBits,...
                                                    'StopBits',stopBits,...
                                                    'Parity',parity,...
                                                    'Terminator',terminator,...
                                                    'TimeOut',app.TIMEOUT);

                            flushoutput(app.portObject);                      % Remove data from port output buffer.
                            flushinput(app.portObject);                       % Remove data from port input buffer.
                            fopen(app.portObject);                            % Open the serial port connection.
                            app.isconnected = true;
                        catch
                            app.isconnected = false;
                            msg = ["It was not possible to connect with device SR865."...
                                   "Check if serial port exist and has the correct COM number."...
                                   "Check if the correct device is turn on."];
                            title = 'Error';
                            icon = 'error';
                            msgbox(msg,title,icon);
                        end
                        
                    case 'gpib'
                        vendor  = app.portInfo.gpib.vendor;                 % gpib adapter vendor
                        boardindex = app.portInfo.gpib.boardindex;          % controller board index.
                        primaryAddress = app.portInfo.gpib.primaryAddress;  % device gpib address
                        
                        try
                            app.portObject = gpib(vendor,boardindex,primaryAddress);  % Create gpib objet.
                            
                            flushoutput(app.portObject);                      % Remove data from port output buffer.
                            flushinput(app.portObject);                       % Remove data from port input buffer.
                            fopen(app.portObject);                            % Open the serial port connection.
                            app.isconnected = true;
                        catch
                            app.isconnected = false;
                            msg = ["It was not possible to connect with device SR830."...
                                   "Check if serial port exist and has the correct COM number."...
                                   "Check if the correct device is turn on."];
                            title = 'Error';
                            icon = 'error';
                            msgbox(msg,title,icon);
                        end
                        
                    case 'usb'
                        try
                            app.portObject = visa('KEYSIGHT',app.portInfo.resourceName);
                            fopen(app.portObject);
                            app.isconnected = true;
                        catch
                            msg = {'Not possible to connect device.'...
                                   'Check if device is already connected to other program.'};
                            msgbox(msg,'error','error');
                            app.isconnected = false;
                        end
                        %  visadevlist();
                        %  app.portObject = visadev(app.portInfo.resourceName);
                end
            end
            
            if app.isconnected
                app.initialize();
            end
            
        end
    end
    
    methods (Access = private)
        
        %--------------------------------
        % FUNCTION: INITIALIZATION 
        function initialize(app)
            
            % Lock-in LIAS Status - Overloads
            app.lockinLIASStatus();
            
            % Reference and Phase
                % Time Base 10 MHz
                fprintf(app.portObject,'TBMODE?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.TimeBaseAutoButton.Value = true;
                elseif answer(1) == '1'
                    app.TimeBaseInternalButton.Value = true;
                end
                app.TimeBaseAutoButton.Enable = true;
                app.TimeBaseInternalButton.Enable = true;
                
                % Phase Value
                fprintf(app.portObject,'PHAS?');
                answer = fscanf(app.portObject);
                app.PhaseEditField.Value = str2double(answer);
                app.PhaseEditField.Editable = true;
                app.PhaseLabel.Enable = true;
                
                % Frequency Internal
                fprintf(app.portObject,'FREQINT?');
                answer = fscanf(app.portObject);
                app.FreqInternalHzEditField.Value = str2double(answer);
                app.FreqInternalHzEditField.Enable = true;
                app.FreqInternalHzEditField.Editable = true;
                app.FreqInternalHzEditFieldLabel.Enable = true;
                
                % Frequency Exernal
                if ~app.frequencyUnlock
                    fprintf(app.portObject,'FREQEXT?');
                    answer = fscanf(app.portObject);
                    app.FreqExternalHzEditField.Value = str2double(answer);
                    app.FreqExternalHzEditField.BackgroundColor = [1 1 1];
                else
                    app.FreqExternalHzEditField.Value = 1;
                    app.FreqExternalHzEditField.BackgroundColor = [1 0 0];
                end
                app.FreqExternalHzEditField.Enable = true;
                app.FreqExternalHzEditField.Editable = true;
                app.FreqExternalHzEditFieldLabel.Enable = true;
                
                % Harmonic
                fprintf(app.portObject,'HARM?');
                answer = fscanf(app.portObject);
                app.HarmonicEditField.Value = str2double(answer);
                app.HarmonicEditField.Editable = true;
                app.HarmonicEditFieldLabel.Enable = true;
                
                % Chopper Wheel Number of Blades
                fprintf(app.portObject,'BLADESLOTS?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.ChopperWheel6SlotButton.Value = true;
                else
                    app.ChopperWheel30SlotButton.Value = true;
                end
                app.ChopperWheel6SlotButton.Enable = true;
                app.ChopperWheel30SlotButton.Enable = true;
                
                % Chopper Wheel Phase
                fprintf(app.portObject,'BLADEPHASE?');
                answer = fscanf(app.portObject);
                app.ChopperWheelPhaseEditField.Value = str2double(answer);
                app.ChopperWheelPhaseEditField.Editable = true;
                app.ChopperWheelPhaseLabel.Enable = true;
                
                % Internal Reference Amplitude
                fprintf(app.portObject,'SLVL?');
                answer = fscanf(app.portObject);
                app.AmplitudeVEditField.Value = str2double(answer);
                app.AmplitudeVEditField.Editable = true;
                app.AmplitudeVEditFieldLabel.Enable = true;
                
                % Internal Reference DC Level
                fprintf(app.portObject,'SOFF?');
                answer = fscanf(app.portObject);
                app.DCLevelVEditField.Value = str2double(answer);
                app.DCLevelVEditField.Editable = true;
                app.DCLevelVEditFieldLabel.Enable = true;
                
                % Internal Reference DC Mode
                fprintf(app.portObject,'REFM?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.CommonButton.Value = true;
                else
                    app.DifferenceButton.Value = true;
                end
                app.CommonButton.Enable = true;
                app.DifferenceButton.Enable = true;
                
                % Reference
                fprintf(app.portObject,'RSRC?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.InternalButton.Value = true;
                elseif answer(1) == '1'
                    app.ExternalButton.Value = true;
                elseif answer(1) == '2'
                    app.DualButton.Value = true;
                elseif answer(1) == '3'
                    app.ChopButton.Value = true;
                end
                app.ExternalButton.Enable = true;
                app.InternalButton.Enable = true;
                app.DualButton.Enable = true;
                app.ChopButton.Enable = true;
                
                % External Reference Trigger Slope
                fprintf(app.portObject,'RTRG?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.SineButton.Value = true;
                elseif answer(1) == '1'
                    app.PosEdgeButton.Value = true;
                elseif answer(1) == '2'
                    app.NegEdgeButton.Value = true;
                end
                app.SineButton.Enable = true;
                app.PosEdgeButton.Enable = true;
                app.NegEdgeButton.Enable = true;
                
                % External Reference Trigger Input Impedance
                fprintf(app.portObject,'REFZ?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.OhmsButton.Value = true;
                else
                    app.MOhmsButton.Value = true;
                end
                app.OhmsButton.Enable = true;
                app.MOhmsButton.Enable = true;
                
            % Signal Commands
                % Voltage or Current Mode
                fprintf(app.portObject,'IVMD?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.VoltageButton.Value = true;
                else
                    app.CurrentButton.Value = true;
                end
                app.VoltageButton.Enable = true;
                app.CurrentButton.Enable = true;
                
                % Voltage Input Mode
                fprintf(app.portObject,'ISRC?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.AButton.Value = true;
                else
                    app.ABButton.Value = true;
                end
                app.AButton.Enable = true;
                app.ABButton.Enable = true;
                
                % Voltage Input Coupling
                fprintf(app.portObject,'ICPL?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.ACButton.Value = true;
                else
                    app.DCButton.Value = true;
                end
                app.ACButton.Enable = true;
                app.DCButton.Enable = true;
                
                % Voltage Input Shielding
                fprintf(app.portObject,'IGND?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.FloatButton.Value = true;
                else
                    app.GroundButton.Value = true;
                end
                app.FloatButton.Enable = true;
                app.GroundButton.Enable = true;
                
                % Range
                fprintf(app.portObject,'IRNG?');
                answer = fscanf(app.portObject);
                app.InputRangeDropDown.Value = answer(1:end-1);   % Remove terminator caracter.
                app.InputRangeDropDown.Enable = true;
                
                % Current Input Mode
                fprintf(app.portObject,'ICUR?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.I1E6Button.Value = true;
                else
                    app.I1E8Button.Value = true;
                end
                app.I1E6Button.Enable = true;
                app.I1E8Button.Enable = true;
                
                % Signal Strengh Indicator
                fprintf(app.portObject,'ILVL?');
                answer = fscanf(app.portObject);
                app.InputRangeSignalStrength.Value = str2double(answer(1:end-1));   % Remove terminator caracter.
                
            % Gain and Time Constant
                % Sensitivity
                fprintf(app.portObject,'SCAL?');
                answer = fscanf(app.portObject);
                app.DropDown.Value = answer(1:end-1);   % Remove terminator caracter.
                app.DropDown.Enable = true;
                
                % Time Constant
                fprintf(app.portObject,'OFLT?');
                answer = fscanf(app.portObject);
                app.TimeConstantDropDown.Value = answer(1:end-1);
                app.TimeConstantDropDown.Enable = true;
                
                % Low Pass Filter
                fprintf(app.portObject,'OFSL?');
                answer = fscanf(app.portObject);
                app.LowPassFilterDropDown.Value = answer(1:end-1);
                app.LowPassFilterDropDown.Enable = true;
                
                % Synchronous Filter
                fprintf(app.portObject,'SYNC?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.SyncCheckBox.Value = false;
                else
                    app.SyncCheckBox.Value = true;
                end
                app.SyncCheckBox.Enable = true;
                
                % Advance Filter
                fprintf(app.portObject,'ADVFILT?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.AdvancedCheckBox.Value = false;
                else
                    app.AdvancedCheckBox.Value = true;
                end
                app.AdvancedCheckBox.Enable = true;

            % Channel 1 and Channel 2 Outputs
                % Channel 1 Output
                fprintf(app.portObject,'COUT? 0');
                answer = fscanf(app.portObject);
                if answer(1) == "0"
                    app.CH1OutputXButton.Value = true;
                else
                    app.CH1OutputRButton.Value = true;
                end
                app.CH1OutputXButton.Enable = true;
                app.CH1OutputRButton.Enable = true;
                
                % Channel 2 Output
                fprintf(app.portObject,'COUT? 1');
                answer = fscanf(app.portObject);
                if answer(1) == "0"
                    app.CH2OutputYButton.Value = true;
                else
                    app.CH2OutputThetaButton.Value = true;
                end
                app.CH2OutputYButton.Enable = true;
                app.CH2OutputThetaButton.Enable = true;
                
                % Channel 1 Expand
                if app.CH1OutputXButton.Value
                    fprintf(app.portObject,'CEXP? 0');
                elseif app.CH1OutputRButton.Value
                    fprintf(app.portObject,'CEXP? 2');
                end
                answer = fscanf(app.portObject);
                if answer(1) == "0"
                    app.CH1Outputx1Button.Value = true;
                elseif answer(1) == "1"
                    app.CH1Outputx10Button.Value = true;
                elseif answer(1) == "2"
                    app.CH1Outputx100Button.Value = true;
                end
                app.CH1Outputx1Button.Enable = true;
                app.CH1Outputx10Button.Enable = true;
                app.CH1Outputx100Button.Enable = true;
                
                % Channel 2 Expand
                if app.CH2OutputYButton.Value
                    fprintf(app.portObject,'CEXP? 1');
                    answer = fscanf(app.portObject);
                    if answer(1) == "0"
                        app.CH2Outputx1Button.Value = true;
                    elseif answer(1) == "1"
                        app.CH2Outputx10Button.Value = true;
                    elseif answer(1) == "2"
                        app.CH2Outputx100Button.Value = true;
                    end
                    app.CH2Outputx1Button.Enable = true;
                    app.CH2Outputx10Button.Enable = true;
                    app.CH2Outputx100Button.Enable = true;
                elseif app.CH2OutputThetaButton.Value
                    app.CH2Outputx1Button.Value = true;
                end

                % Channel 1 Offset
                if app.CH1OutputXButton.Value
                    fprintf(app.portObject,'COFA? 0');
                elseif app.CH1OutputRButton.Value
                    fprintf(app.portObject,'COFA? 2');
                end
                answer = fscanf(app.portObject);
                if answer(1) == "0"
                    app.CH1OutputOffsetOffButton.Value = true;
                elseif answer(1) == "1"
                    app.CH1OutputOffsetOnButton.Value = true;
                end
                app.CH1OutputOffsetOffButton.Enable = true;
                app.CH1OutputOffsetOnButton.Enable = true;
                
                % Channel 2 Offset
                if app.CH2OutputYButton.Value
                    fprintf(app.portObject,'COFA? 1');
                    answer = fscanf(app.portObject);
                    if answer(1) == "0"
                        app.CH2OutputOffsetOffButton.Value = true;
                    elseif answer(1) == "1"
                        app.CH2OutputOffsetOnButton.Value = true;
                    end
                    app.CH1OutputOffsetOffButton.Enable = true;
                    app.CH1OutputOffsetOnButton.Enable = true;
                elseif app.CH2OutputThetaButton.Value
                    app.CH2OutputOffsetOffButton.Value = true;
                end
                
                % Channel 1 Offset Value
                if app.CH1OutputXButton.Value
                    fprintf(app.portObject,'COFP? 0');
                elseif app.CH1OutputRButton.Value
                    fprintf(app.portObject,'COFP? 2');
                end
                answer = fscanf(app.portObject);
                app.CH1OutputOffsetValueEditField.Value = str2double(answer);
                app.CH1OutputOffsetValueEditField.Enable = true;
                if app.CH1OutputOffsetOffButton.Enable
                    app.CH1OutputOffsetValueEditField.Editable = false;
                else
                    app.CH1OutputOffsetValueEditField.Editable = true;
                end
                
                % Channel 2 Offset Auto Button
                if app.CH1OutputOffsetOffButton.Enable
                    app.CH1OutputOffsetAutoButton.Enable = false;
                else
                    app.CH1OutputOffsetAutoButton.Enable  = true;
                end
                
                % Channel 2 Offset Value
                if app.CH1OutputXButton.Value
                    fprintf(app.portObject,'COFP? 1');
                    answer = fscanf(app.portObject);
                    app.CH2OutputOffsetValueEditField.Value = str2double(answer);
                elseif app.CH1OutputRButton.Value
                    app.CH2OutputOffsetValueEditField.Value = 0;
                end
                app.CH2OutputOffsetValueEditField.Enable = true;
                if app.CH2OutputOffsetOffButton.Enable
                    app.CH2OutputOffsetValueEditField.Editable = false;
                else
                    app.CH2OutputOffsetValueEditField.Editable = true;
                end
                
                % Channel 2 Offset Auto Button
                if app.CH2OutputOffsetOffButton.Enable
                    app.CH2OutputOffsetAutoButton.Enable = false;
                else
                    app.CH2OutputOffsetAutoButton.Enable  = true;
                end
                
                % Channel 1 Ratio
                if app.CH1OutputXButton.Value
                    fprintf(app.portObject,'CRAT? 0');
                elseif app.CH1OutputRButton.Value
                    fprintf(app.portObject,'CRAT? 2');
                end
                answer = fscanf(app.portObject);
                if answer(1) == "0"
                    app.CH1OutputRatioOffButton.Value = true;
                elseif answer(1) == "1"
                    app.CH1OutputRatioOnButton.Value = true;
                end
                app.CH1OutputRatioOffButton.Enable = true;
                app.CH1OutputRatioOnButton.Enable = true;
                
                % Channel 2 Ratio
                if app.CH2OutputYButton.Value
                    fprintf(app.portObject,'CRAT? 1');
                    answer = fscanf(app.portObject);
                    if answer(1) == "0"
                        app.CH2OutputRatioOffButton.Value = true;
                    elseif answer(1) == "1"
                        app.CH2OutputRatioOnButton.Value = true;
                    end
                    app.CH2OutputOffsetOffButton.Enable = true;
                    app.CH2OutputOffsetOnButton.Enable = true;
                elseif app.CH2OutputThetaButton.Value
                    app.CH2OutputRatioOffButton.Value = true;
                end
            
            % Auxiliar Outputs
                % Auxiliar Output 1
                fprintf(app.portObject,'AUXV? 0');
                answer = fscanf(app.portObject);
                app.Output1EditField.Value = str2double(answer);
                app.Output1EditField.Enable = true;
                app.Output1EditFieldLabel.Enable = true;
                app.Output1EditField.Editable = true;
                
                % Auxiliar Output 2
                fprintf(app.portObject,'AUXV? 1');
                answer = fscanf(app.portObject);
                app.Output2EditField.Value = str2double(answer);
                app.Output2EditField.Enable = true;
                app.Output2EditFieldLabel.Enable = true;
                app.Output2EditField.Editable = true;
                
                % Auxiliar Output 3
                fprintf(app.portObject,'AUXV? 2');
                answer = fscanf(app.portObject);
                app.Output3EditField.Value = str2double(answer);
                app.Output3EditField.Enable = true;
                app.Output3EditFieldLabel.Enable = true;
                app.Output3EditField.Editable = true;
                
                % Auxiliar Output 4
                fprintf(app.portObject,'AUXV? 3');
                answer = fscanf(app.portObject);
                app.Output4EditField.Value = str2double(answer);
                app.Output4EditField.Enable = true;
                app.Output4EditFieldLabel.Enable = true;
                app.Output4EditField.Editable = true;
                
            % Auto Functions
                % Auto Phase
                app.PhaseButton.Enable = true;
                
                % Auto range
                app.RangeButton.Enable = true;
                
                % Auto Scale
                app.ScaleButton.Enable = true;
                
            % Display Commands
                % Panel Banking
                fprintf(app.portObject,'DBLK?');
                answer = fscanf(app.portObject);
                if answer(1) == '0'
                    app.BlankPanelBlankingCheckBox.Value = false;
                else
                    app.BlankPanelBlankingCheckBox.Value = true;
                end
                app.BlankPanelBlankingCheckBox.Enable = true;
                
                % Data Channel 1 (Green)
                fprintf(app.portObject,'CDSP? 0');
                answer = fscanf(app.portObject);
                app.DisplayGreenDropDown.Value = answer(1:end-1);   % Remove terminator caracter.
                app.DisplayGreenDropDown.Enable = true;
                
                % Data Channel 2 (Blue)
                fprintf(app.portObject,'CDSP? 1');
                answer = fscanf(app.portObject);
                app.DisplayBlueDropDown.Value = answer(1:end-1);   % Remove terminator caracter.
                app.DisplayBlueDropDown.Enable = true;
                
                % Data Channel 3 (Yellow)
                fprintf(app.portObject,'CDSP? 2');
                answer = fscanf(app.portObject);
                app.DisplayYellowDropDown.Value = answer(1:end-1);   % Remove terminator caracter.
                app.DisplayYellowDropDown.Enable = true;
                
                % Data Channel 4 (Orange)
                fprintf(app.portObject,'CDSP? 3');
                answer = fscanf(app.portObject);
                app.DisplayOrangeDropDown.Value = answer(1:end-1);   % Remove terminator caracter.
                app.DisplayOrangeDropDown.Enable = true;
                
            % Data Transfer Commands
                % Data Channel 1 (Green)
                fprintf(app.portObject,'OUTR? 0');
                answer = fscanf(app.portObject);
                app.DisplayGreenEditField.Value = str2double(answer);
                
                % Data Channel 1 (Blue)
                fprintf(app.portObject,'OUTR? 1');
                answer = fscanf(app.portObject);
                app.DisplayBlueEditField.Value = str2double(answer);
                
                % Data Channel 1 (Yellow)
                fprintf(app.portObject,'OUTR? 2');
                answer = fscanf(app.portObject);
                app.DisplayYellowEditField.Value = str2double(answer);
                
                % Data Channel 1 (Orange)
                fprintf(app.portObject,'OUTR? 3');
                answer = fscanf(app.portObject);
                app.DisplayOrangeEditField.Value = str2double(answer);
                
            % Interface
                % Device Identification
                fprintf(app.portObject,'*IDN?');
                answer = fscanf(app.portObject);
                app.DeviceIdendificationEditField.Value = answer;
                
            % Plot Buttons
            app.StartButton.Enable = true;
            app.StopButton.Enable = true;
            app.CleanButton.Enable = true;
            app.IntervalsSpinner.Enable = true;
            app.IntervalsSpinner.Editable = true;
            app.IntervalsSpinnerLabel.Enable = true;
            app.IntervalsSpinner.Value = app.TIMEAQUISITIONINTERVAL;
            app.TimesSpinner.Enable = true;
            app.TimesSpinner.Editable = true;
            app.TimesSpinnerLabel.Enable = true;
            app.TimesSpinner.Value = app.TIMEAQUISITIONTOTAL;
            
            % Others
            app.ForceUpdateButton.Enable = true;
            app.SaveConfigButton.Enable = true;
            app.LoadConfigButton.Enable = true;
        end
        
        %--------------------------------
        % FUNCTION:
        function lockinLIASStatus(app)
            
            % Flush Por Object
            flushinput(app.portObject);                   % Remove data from port input buffer.
            
            % Get LIAS Status Byte
            fprintf(app.portObject,'*CLS');
            fprintf(app.portObject,'LIAS?');
            answer = dec2bin(fscanf(app.portObject),16);     % LIAS byte + terminator 'CR'
            
                % Channel 1 Output Overload
                if answer(1,16) == '1'%16) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.CH1OutputOverloadLamp.Color = value;
                
                % Channel 2 Output Overload
                if answer(1,15) == '1'%15) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.CH2OutputOverloadLamp.Color =  value;
                
                % Frequency Unlock
                if answer(1,13) == '1'%13) == '1'
                    value = [1 0 0];
                    condition = true;
                else
                    value = [1 1 1];
                    condition = false;
                end
                app.FreqExternalHzEditField.BackgroundColor = value;
                app.frequencyUnlock = condition;
                
                % Range Overload
                if answer(1,12) == '1'%12) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.InputRangeOverloadLamp.Color = value;
                
                % Sync Filter Overload
                if answer(1,10) == '1'%10) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.SyncOverloadLamp.Color = value;
                
                % Data Channel 1 (Green) Overload
                if answer(1,8) == '1'%8) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.OverloadGreenLamp.Color = value;
                
                % Data Channel 2 (Blue) Overload
                if answer(1,7) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.OverloadBlueLamp.Color = value;
                
                % Data Channel 3 (Yellow) Overload
                if answer(1,6) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.OverloadYellowLamp.Color = value;
                
                % Data Channel 4 (Orange) Overload
                if answer(1,5) == '1'
                    value = [1 0 0];
                else
                    value = [0 0 0];
                end
                app.OverloadOrangeLamp.Color = value;
            
        end
        
        %--------------------------------
        % FUNCTION: REGULAR DATA AQUISITION
        function dataTimerFcn(app,~,~)
            
            % Lock-in Amplifier LIAS Status
            app.lockinLIASStatus();                         % Checks overloads and external reference unlock.
            
            % Signal Strengh Indicator
            fprintf(app.portObject,'ILVL?');                % Get signal input strengh.
            answer = fscanf(app.portObject);                % Reads device answer.
            app.InputRangeSignalStrength.Value = str2double(answer(1:end-1));   % Remove terminator caracter.
            
            % Get Lock-in Data Display Channel 1, 2, 3 & 4
            flushinput(app.portObject);                     % Remove data from port input buffer.
            
            fprintf(app.portObject,'SNAPD?');               % Get data from all display channels.
            answer = fscanf(app.portObject);                % Reads device answer.
            commaPositions = strfind(answer,',');           % Commas separate all four values.
            
            % Extract Data from String
            dataChannelGreen = str2double(answer(1:commaPositions(1)-1));
            dataChannelBlue = str2double(answer((commaPositions(1)+1):(commaPositions(2)-1)));
            dataChannelYellow = str2double(answer((commaPositions(2)+1):(commaPositions(3)-1)));
            dataChannelOrange = str2double(answer((commaPositions(3)+1):(end-1)));
            
            % Actualize Channel 1 & 2 Displays
            app.DisplayGreenEditField.Value = dataChannelGreen;
            app.DisplayBlueEditField.Value = dataChannelBlue;
            app.DisplayYellowEditField.Value = dataChannelYellow;
            app.DisplayOrangeEditField.Value = dataChannelOrange;
            
            % Plot Left Hand Side: Data Channel Green & Channel Yellow
                % Left Hand Side Axis Y (Data Channel Green)
                ydata = app.plotDataGreen;
                ydata = circshift(ydata,1);
                ydata(1) = dataChannelGreen;
                yyaxis(app.UIAxesLeft,"left");
            
                plot(app.UIAxesLeft,app.plotDataAxisX,ydata);
%                 app.plotObjectLeft.YData = ydata;
                app.plotDataGreen = ydata;
                
                % Right Hand Side Axis Y (Data Channel Yellow)
                ydata = app.plotDataYellow;
                ydata = circshift(ydata,1);
                ydata(1) = dataChannelYellow;
                yyaxis(app.UIAxesLeft,"right");
            
                plot(app.UIAxesLeft,app.plotDataAxisX,ydata);
%                 app.plotObjectLeft.YData = ydata;
                app.plotDataYellow = ydata;
            
            % Plot Right Hand Side: Data Channel Blue & Channel Orange
                % Left Hand Side Axis Y (Data Channel Blue)
                ydata = app.plotDataBlue;
                ydata = circshift(ydata,1);
                ydata(1) = dataChannelBlue;
                yyaxis(app.UIAxesRight,"left");
            
                plot(app.UIAxesRight,app.plotDataAxisX,ydata);
%                 app.plotObjectRight.YData = ydata;
                app.plotDataBlue = ydata;
                
                % Right Hand Side Axis Y (Data Channel Orange)
                ydata = app.plotDataOrange;
                ydata = circshift(ydata,1);
                ydata(1) = dataChannelOrange;
                yyaxis(app.UIAxesRight,"right");
            
                plot(app.UIAxesRight,app.plotDataAxisX,ydata);
%                 app.plotObjectRight.YData = ydata;
                app.plotDataOrange = ydata;
                
            % External Reference Frequency Value
            if ~app.frequencyUnlock
                fprintf(app.portObject,'FREQEXT?');
                answer = fscanf(app.portObject);
                app.FreqInternalHzEditField.Value = str2double(answer);
            end

        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create timer object
            app.dataTimer = timer('ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                                  'Period', app.TIMEAQUISITIONINTERVAL, ...                     % Period is 1 second
                                  'BusyMode', 'queue',...              % Queue timer callbacks when busy
                                  'TimerFcn', @app.dataTimerFcn);    % Specify callback function
            
            % Plot Data Axis-X (time)
            app.plotDataAxisX = 0:app.TIMEAQUISITIONINTERVAL:app.TIMEAQUISITIONTOTAL;
            axisXSize = size(app.plotDataAxisX,2);
            
            % Configura Axes from Left Hand Side
                % Configure x-axis
                app.UIAxesLeft.XDir = 'reverse';
            
                % Initial Plot Data (Zeros)
                app.plotDataGreen= zeros(1,axisXSize);
                app.plotDataYellow = zeros(1,axisXSize);
            
                % Configure y-axis Left Hand Side
                yyaxis(app.UIAxesLeft,"left");
                app.plotObjectLeft = plot(app.UIAxesLeft,app.plotDataAxisX,app.plotDataGreen);
            
                % Configure y-axis Left Hand Side
                yyaxis(app.UIAxesLeft,"right");
%                 app.plotObjectLeft = plot(app.UIAxesLeft,app.plotDataAxisX,app.plotDataYellow);
                plot(app.UIAxesLeft,app.plotDataAxisX,app.plotDataYellow);
                
            % Configura Axes from Right Hand Side
                % Configure x-axis
                app.UIAxesRight.XDir = 'reverse';
            
                % Initial Plot Data (Zeros)
                app.plotDataBlue = zeros(1,axisXSize);
                app.plotDataOrange = zeros(1,axisXSize);
            
                % Configure y-axis Left Hand Side
                yyaxis(app.UIAxesRight,"left");
                app.plotObjectRight = plot(app.UIAxesRight,app.plotDataAxisX,app.plotDataBlue);
            
                % Configure y-axis Left Hand Side
                yyaxis(app.UIAxesRight,"right");
                app.plotObjectRight = plot(app.UIAxesLeft,app.plotDataAxisX,app.plotDataOrange);
        end

        % Value changed function: Output1EditField
        function Output1EditFieldValueChanged(app, event)
            value = app.Output1EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 0,' num2str(value)]);
        end

        % Value changed function: Output2EditField
        function Output2EditFieldValueChanged(app, event)
            value = app.Output2EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 1,' num2str(value)]);
        end

        % Value changed function: Output3EditField
        function Output3EditFieldValueChanged(app, event)
            value = app.Output3EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 2,' num2str(value)]);
        end

        % Value changed function: Output4EditField
        function Output4EditFieldValueChanged(app, event)
            value = app.Output4EditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['AUXV 3,' num2str(value)]);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['SCAL ' value]);
        end

        % Value changed function: TimeConstantDropDown
        function TimeConstantDropDownValueChanged(app, event)
            value = app.TimeConstantDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['OFLT ' value]);
        end

        % Value changed function: LowPassFilterDropDown
        function LowPassFilterDropDownValueChanged(app, event)
            value = app.LowPassFilterDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['OFSL ' value]);
        end

        % Value changed function: SyncCheckBox
        function SyncCheckBoxValueChanged(app, event)
            value = app.SyncCheckBox.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if value
                fprintf(app.portObject,'SYNC 1');
                pause(.2);
                fprintf(app.portObject,'SYNC?');
                answer = fscanf(app.portObject);
                if answer == 0
                    app.SyncCheckBox.Value = 0;
                end
            else
                fprintf(app.portObject,'SYNC 0');
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Value changed function: AdvancedCheckBox
        function AdvancedCheckBoxValueChanged(app, event)
            value = app.AdvancedCheckBox.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['ADVFILT ' num2str(value)]);
        end

        % Selection changed function: CouplingButtonGroup
        function CouplingButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.ACButton.Value
                fprintf(app.portObject,'ICPL 0');
            elseif app.DCButton.Value
                fprintf(app.portObject,'ICPL 1');
            end
        end

        % Selection changed function: ShieldGroundingButtonGroup
        function ShieldGroundingButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.FloatButton.Value
                fprintf(app.portObject,'IGND 0');
            elseif app.GroundButton.Value
                fprintf(app.portObject,'IGND 1');
            end
        end

        % Selection changed function: InputButtonGroup
        function InputButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.AButton.Value
                fprintf(app.portObject,'ISRC 0');
            elseif app.ABButton.Value
                fprintf(app.portObject,'ISRC 1');
            end
        end

        % Selection changed function: CurrentButtonGroup
        function CurrentButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.I1E6Button.Value
                fprintf(app.portObject,'ICUR 0');
            elseif app.I1E8Button.Value
                fprintf(app.portObject,'ICUR 1');
            end
        end

        % Selection changed function: InputButtonGroup_3
        function InputButtonGroup_3SelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.VoltageButton.Value
                fprintf(app.portObject,'IVMD 0');
            elseif app.CurrentButton.Value
                fprintf(app.portObject,'IVMD 1');
            end
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
            value = app.PhaseEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['PHAS ' num2str(value)]);
        end

        % Value changed function: AmplitudeVEditField
        function AmplitudeVEditFieldValueChanged(app, event)
            value = app.AmplitudeVEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['SLVL ' num2str(value)]);
        end

        % Value changed function: HarmonicEditField
        function HarmonicEditFieldValueChanged(app, event)
            value = app.HarmonicEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['HARM ' num2str(fix(value))]);
            pause(.2);
            fprintf(app.portObject,'HARM?');
            answer = fscanf(app.portObject);
            app.HarmonicEditField.Value = str2double(answer);
        end

        % Selection changed function: SourceButtonGroup
        function SourceButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.InternalButton.Value
                fprintf(app.portObject,'RSRC 0');
                condition = true;
            elseif app.ExternalButton.Value
                fprintf(app.portObject,'RSRC 1');
                condition = false;
                pause(.25);
            elseif app.DualButton.Value
                fprintf(app.portObject,'RSRC 2');
                condition = true;
                elseif app.ChopButton.Value
                fprintf(app.portObject,'RSRC 3');
                condition = true;
            end
            app.FreqExternalHzEditField.Editable = condition;
            app.AmplitudeVEditField.Editable = condition;
            fprintf(app.portObject,'FREQ?');
            answer = fscanf(app.portObject);
            app.FreqExternalHzEditField.Value = str2double(answer);
        end

        % Selection changed function: TriggerButtonGroup
        function TriggerButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.SineButton.Value
                fprintf(app.portObject,'RTRG 0');
            elseif app.PosEdgeButton.Value
                fprintf(app.portObject,'RTRG 1');
            elseif app.NegEdgeButton.Value
                fprintf(app.portObject,'RTRG 2');
            end
        end

        % Selection changed function: InputResistorButtonGroup
        function InputResistorButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.OhmsButton.Value
                fprintf(app.portObject,'REFZ 0');
            elseif app.MOhmsButton.Value
                fprintf(app.portObject,'REFZ 1');
            end
        end

        % Selection changed function: DCLevelButtonGroup
        function DCLevelButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.CommonButton.Value
                fprintf(app.portObject,'REFM 0');
            elseif app.DifferenceButton.Value
                fprintf(app.portObject,'REFM 1');
            end
        end

        % Value changed function: DCLevelVEditField
        function DCLevelVEditFieldValueChanged(app, event)
            value = app.DCLevelVEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['SOFF ' num2str(value)]);
        end

        % Selection changed function: ChopperBladeButtonGroup
        function ChopperBladeButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.ChopperWheel6SlotButton.Value
                fprintf(app.portObject,'BLADESLOTS 0');
            elseif app.ChopperWheel30SlotButton.Value
                fprintf(app.portObject,'BLADESLOTS 1');
            end
        end

        % Value changed function: ChopperWheelPhaseEditField
        function ChopperWheelPhaseEditFieldValueChanged(app, event)
            value = app.ChopperWheelPhaseEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['BLADEPHASE ' num2str(value)]);
        end

        % Selection changed function: TimeBaseButtonGroup
        function TimeBaseButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.TimeBaseAutoButton.Value
                fprintf(app.portObject,'TBMODE 0');
            elseif app.TimeBaseInternalButton.Value
                fprintf(app.portObject,'TBMODE 1');
            end
        end

        % Close request function: 
        % StanfordResearchSystemsModelSR865AUIFigure
        function StanfordResearchSystemsModelSR865AUIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.dataTimer);
            delete(app.dataTimer);
            
            % Close Device Connection
            if app.isconnected
                fclose(app.portObject);
            end
            
            delete(app)
            
        end

        % Button pushed function: ForceUpdateButton
        function ForceUpdateButtonPushed(app, event)
            
            app.initialize();
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % If timer is not running, start it
            if strcmp(app.dataTimer.Running, 'off')
               start(app.dataTimer);
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % If timer is running, stop it
            if strcmp(app.dataTimer.Running, 'on')
                stop(app.dataTimer);
            end
            
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            
            % Stop Data Collection
            if strcmp(app.dataTimer.Running, 'on')
                stop(app.dataTimer);
                dataTimerWasRunning = true;
            else
                dataTimerWasRunning = false;
            end
            
            % Zero all Plot Data
            axisXSize = size(app.plotDataAxisX,2);
            ydata = zeros(axisXSize,1);
            
            % Plot Left Hand Side: Data Channel Green & Channel Yellow
                % Left Hand Side Axis Y (Data Channel Green)
                yyaxis(app.UIAxesLeft,"left");
                plot(app.UIAxesLeft,app.plotDataAxisX,ydata);
                app.plotDataGreen = ydata;
            
                % Right Hand Side Axis Y (Data Channel Yellow)
                yyaxis(app.UIAxesLeft,"right");
                plot(app.UIAxesLeft,app.plotDataAxisX,ydata);
                app.plotDataYellow = ydata;
            
            % Plot Right Hand Side: Data Channel Blue & Channel Orange
                % Left Hand Side Axis Y (Data Channel Blue)
                yyaxis(app.UIAxesRight,"left");
                plot(app.UIAxesRight,app.plotDataAxisX,ydata);
                app.plotDataBlue = ydata;
                
                % Right Hand Side Axis Y (Data Channel Orange)
                yyaxis(app.UIAxesRight,"right");
                plot(app.UIAxesRight,app.plotDataAxisX,ydata);
                app.plotDataOrange = ydata;
            
            % Re-start Data Collection
            if dataTimerWasRunning
                start(app.dataTimer);
            end
            
        end

        % Value changed function: TimesSpinner
        function TimesSpinnerValueChanged(app, event)
            
            value = app.TimesSpinner.Value;
            app.TIMEAQUISITIONTOTAL = value;
            
            app.plotDataAxisX = 0:app.TIMEAQUISITIONINTERVAL:app.TIMEAQUISITIONTOTAL;
            
            app.CleanButtonPushed;
            
        end

        % Value changed function: IntervalsSpinner
        function IntervalsSpinnerValueChanged(app, event)
            
            value = app.IntervalsSpinner.Value;
            app.TIMEAQUISITIONINTERVAL = value;
            
            app.plotDataAxisX = 0:app.TIMEAQUISITIONINTERVAL:app.TIMEAQUISITIONTOTAL;
            
            app.CleanButtonPushed;
            
        end

        % Value changed function: BlankPanelBlankingCheckBox
        function BlankPanelBlankingCheckBoxValueChanged(app, event)
            value = app.BlankPanelBlankingCheckBox.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if value
                fprintf(app.portObject,'DBLK 1');
            else
                fprintf(app.portObject,'DBLK 0');
            end
            
        end

        % Button pushed function: PhaseButton
        function PhaseButtonPushed(app, event)
            
            flushinput(app.portObject);                     % Remove data from port input buffer.
            fprintf(app.portObject,'APHS');                 % Auto phase function.
            
            app.LampPhase.Color = [1 0 0];                   % Change phase lamp color to red while operating.
            app.portObject.TimeOut = 30;                    % Increase time out value to give enough time to execute function.
            fprintf(app.portObject,'*OPC?');                % Query operation complete (answer is always 1).
            answer = fscanf(app.portObject);                % When answer is received, mean end of operation.
            app.portObject.TimeOut = app.TIMEOUT;           % eturn time out to original value
            
            fprintf(app.portObject,'PHAS?');                % Ask device for new phase value.
            answer = fscanf(app.portObject);
            app.PhaseEditField.Value = str2double(answer);  % Update app phase value.
            pause(0.1);                                     % Provides some time to observe colour transition of the lamp.
            app.LampPhase.Color = [0 0 0];                   % Change lamp color back to black.
            
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            
            flushinput(app.portObject);                     % Remove data from port input buffer.
            fprintf(app.portObject,'ASCL');                 % Auto phase function.
            
            app.LampScale.Color = [1 0 0];                   % Change phase lamp color to red while operating.
            app.portObject.TimeOut = 30;                    % Increase time out value to give enough time to execute function.
            fprintf(app.portObject,'*OPC?');                % Query operation complete (answer is always 1).
            answer = fscanf(app.portObject);                % When answer is received, mean end of operation.
            app.portObject.TimeOut = app.TIMEOUT;           % Return time out to original value.
            
            fprintf(app.portObject,'SCAL?');                % Ask for new scale / sensitivity value.
            answer = fscanf(app.portObject);
            app.DropDown.Value = answer(1:end-1);
            pause(0.1);                                     % Provides some time to observe colour transition of the lamp.
            app.LampScale.Color = [0 0 0];                  % Change lamp color back to black.
            
        end

        % Button pushed function: RangeButton
        function RangeButtonPushed(app, event)
            
            flushinput(app.portObject);                     % Remove data from port input buffer.
            fprintf(app.portObject,'ARNG');                 % Auto phase function.
            
            app.LampRange.Color = [1 0 0];                   % Change phase lamp color to red while operating.
            app.portObject.TimeOut = 30;                    % Increase time out value to give enough time to execute function.
            fprintf(app.portObject,'*OPC?');                % Query operation complete (answer is always 1).
            answer = fscanf(app.portObject);                % When answer is received, mean end of operation.
            app.portObject.TimeOut = app.TIMEOUT;           % Return time out to original value.
            
            fprintf(app.portObject,'IRNG?');                % Ask for new range scale value.
            answer = fscanf(app.portObject);
            app.InputRangeDropDown.Value = answer(1:end-1); % Remove terminator caracter.

            % Signal Strengh Indicator  - frequenctly, the auto range results in overload results.
            fprintf(app.portObject,'ILVL?');                % Ask for signal strengh value.
            answer = fscanf(app.portObject);
            app.InputRangeSignalStrength.Value = str2double(answer(1:end-1));   % Remove terminator caracter.
            
            % Check Overload
            app.lockinLIASStatus();
                
            pause(0.1);                                     % Provides some time to observe colour transition of the lamp.
            app.LampRange.Color = [0 0 0];                  % Change lamp color back to black.
            
        end

        % Selection changed function: CH1OutputOutputButtonGroup
        function CH1OutputOutputButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                     % Remove data from port input buffer.
            if app.CH1OutputXButton.Value
                fprintf(app.portObject,'COUT 0,0');         % Change channel 1 output X.
            elseif app.CH1OutputRButton.Value
                fprintf(app.portObject,'COUT 0,1');         % Change channel 1 output R.
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Selection changed function: CH2OutputOutputButtonGroup
        function CH2OutputOutputButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                     % Remove data from port input buffer.
            if app.CH2OutputYButton.Value
                fprintf(app.portObject,'COUT 1,0');         % Change channel 1 output Y.
                app.CH2OutputRatioOffButton.Enable = true;  % Ratio only available with Y.
                app.CH2OutputRatioOnButton.Enable = true;
                app.CH2Outputx1Button.Enable = false;       % Output expand only available with Y.
                app.CH2Outputx10Button.Enable = false;
                app.CH2Outputx100Button.Enable = false;
            elseif app.CH2OutputThetaButton.Value
                fprintf(app.portObject,'COUT 1,1');         % Change channel 1 output Theta.
                app.CH2OutputRatioOffButton.Enable = false; % Ratio not available with Theta.
                app.CH2OutputRatioOnButton.Enable = false;
                app.CH2Outputx1Button.Enable = false;       % Output expand not available with Theta.
                app.CH2Outputx10Button.Enable = false;
                app.CH2Outputx100Button.Enable = false;
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Selection changed function: CH1OutputRatioButtonGroup
        function CH1OutputRatioButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                     % Remove data from port input buffer.
            
            if app.CH1OutputXButton.Value
                if app.CH1OutputRatioOffButton.Value
                    fprintf(app.portObject,'CRAT 0,0');         % Change channel 1 ratio to off.
                elseif app.CH1OutputRatioOnButton.Value
                    fprintf(app.portObject,'CRAT 0,1');         % Change channel 1 ratio to on.
                end
            elseif app.CH1OutputRButton.Value
                if app.CH1OutputRatioOffButton.Value
                    fprintf(app.portObject,'CRAT 2,0');         % Change channel 1 ratio to off.
                elseif app.CH1OutputRatioOnButton.Value
                    fprintf(app.portObject,'CRAT 2,1');         % Change channel 1 ratio to on.
                end
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Selection changed function: CH2OutputRatioButtonGroup
        function CH2OutputRatioButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                     % Remove data from port input buffer.
            
            if app.CH2OutputYButton.Value
                if app.CH1OutputRatioOffButton.Value
                    fprintf(app.portObject,'CRAT 1,0');         % Change channel 1 ratio to off.
                elseif app.CH1OutputRatioOnButton.Value
                    fprintf(app.portObject,'CRAT 1,1');         % Change channel 1 ratio to on.
                end
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Selection changed function: CH1OutputExpandButtonGroup
        function CH1OutputExpandButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                         % Remove data from port input buffer.
            if app.CH1OutputXButton.Value                       % If X selected.
                if app.CH1Outputx1Button.Value
                    fprintf(app.portObject,'CEXP 0,0');         % Change channel 1 expantion off.
                elseif app.CH1Outputx10Button.Value
                    fprintf(app.portObject,'CEXP 0,1');         % Change channel 1 expantion x10.
                elseif app.CH1Outputx100Button.Value
                    fprintf(app.portObject,'CEXP 0,2');         % Change channel 1 expantion x100.
                end
            elseif app.CH1OutputRButton.Value                   % If R selected.
                if app.CH1Outputx1Button.Value 
                    fprintf(app.portObject,'CEXP 2,0');         % Change channel 1 expantion off.
                elseif app.CH1Outputx10Button.Value
                    fprintf(app.portObject,'CEXP 2,1');         % Change channel 1 expantion x10.
                elseif app.CH1Outputx100Button.Value
                    fprintf(app.portObject,'CEXP 2,2');         % Change channel 1 expantion x100.
                end
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Selection changed function: CH2OutputExpandButtonGroup
        function CH2OutputExpandButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                         % Remove data from port input buffer.
            if app.CH2OutputYButton.Value                       % If X selected.
                if app.CH2Outputx1Button.Value
                    fprintf(app.portObject,'CEXP 1,0');         % Change channel 2 expantion off.
                elseif app.CH2Outputx10Button.Value
                    fprintf(app.portObject,'CEXP 1,1');         % Change channel 2 expantion x10.
                elseif app.CH2Outputx100Button.Value
                    fprintf(app.portObject,'CEXP 1,2');         % Change channel 2 expantion x100.
                end
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Selection changed function: CH1OutputOffsetButtonGroup
        function CH1OutputOffsetButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                     % Remove data from port input buffer.
            if app.CH1OutputXButton.Value
                if app.CH1OutputOffsetOffButton.Value
                    fprintf(app.portObject,'COFA 0,0');     % Turn off channel 1 offset.
                    app.CH1OutputOffsetValueEditField.Editable = false;
                    app.CH1OutputOffsetAutoButton.Enable = false;
                elseif app.CH1OutputOffsetOnButton.Value
                    fprintf(app.portObject,'COFA 0,1');     % Turn on channel 1 offset.
                    app.CH1OutputOffsetValueEditField.Editable = true;
                    app.CH1OutputOffsetAutoButton.Enable = true;
                end
                
            elseif app.CH1OutputRButton.Value
                if app.CH1OutputOffsetOffButton.Value
                    fprintf(app.portObject,'COFA 2,0');     % Turn off channel 1 offset.
                    app.CH1OutputOffsetValueEditField.Editable = false;
                    app.CH1OutputOffsetAutoButton.Enable = false;
                elseif app.CH1OutputOffsetOnButton.Value
                    fprintf(app.portObject,'COFA 2,1');     % Turn on channel 1 offset.
                    app.CH1OutputOffsetValueEditField.Editable = true;
                    app.CH1OutputOffsetAutoButton.Enable = true;
                end
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Selection changed function: CH2OutputOffsetButtonGroup
        function CH2OutputOffsetButtonGroupSelectionChanged(app, event)
            flushinput(app.portObject);                     % Remove data from port input buffer.
            if app.CH2OutputYButton.Value
                if app.CH2OutputOffsetOffButton.Value
                    fprintf(app.portObject,'COFA 1, 0');     % Turn off channel 2 offset.
                    app.CH2OutputOffsetValueEditField.Editable = false;
                    app.CH2OutputOffsetAutoButton.Enable = false;
                elseif app.CH2OutputOffsetOnButton.Value
                    fprintf(app.portObject,'COFA 1, 1');     % Turn on channel 2 offset.
                    app.CH2OutputOffsetValueEditField.Editable = true;
                    app.CH2OutputOffsetAutoButton.Enable = true;
                end
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Value changed function: CH1OutputOffsetValueEditField
        function CH1OutputOffsetValueEditFieldValueChanged(app, event)
            value = app.CH1OutputOffsetValueEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.CH1OutputXButton.Value
                fprintf(app.portObject,['COFP 0, ' num2str(value)]);
            elseif app.CH1OutputRButton.Value
                fprintf(app.portObject,['COFP 2, ' num2str(value)]);
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Value changed function: CH2OutputOffsetValueEditField
        function CH2OutputOffsetValueEditFieldValueChanged(app, event)
            value = app.CH2OutputOffsetValueEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.CH2OutputYButton.Value
                fprintf(app.portObject,['COFP 1, ' num2str(value)]);
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Button pushed function: CH1OutputOffsetAutoButton
        function CH1OutputOffsetAutoButtonPushed(app, event)
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.CH1OutputXButton.Value
                fprintf(app.portObject,'OAUT 0');
            elseif app.CH1OutputRButton.Value
                fprintf(app.portObject,'OAUT 2');
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Button pushed function: CH2OutputOffsetAutoButton
        function CH2OutputOffsetAutoButtonPushed(app, event)
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            if app.CH1OutputXButton.Value
                fprintf(app.portObject,'OAUT 0');
            elseif app.CH1OutputRButton.Value
                fprintf(app.portObject,'OAUT 2');
            end
            
            % Check for Overloads
            app.lockinLIASStatus();
        end

        % Value changed function: InputRangeDropDown
        function InputRangeDropDownValueChanged(app, event)
            value = app.InputRangeDropDown.Value;
            
            flushinput(app.portObject);                 % Remove data from port input buffer.
            fprintf(app.portObject,['IRNG ' value]);    % Change Input Range.
            pause(.25);

            % Signal Strengh Indicator  - frequenctly, the auto range results in overload results.
            fprintf(app.portObject,'ILVL?');            % Ask for signal strengh value.
            answer = fscanf(app.portObject);
            app.InputRangeSignalStrength.Value = str2double(answer(1:end-1));   % Remove terminator caracter.
            
            % Check Overload
            app.lockinLIASStatus();
        end

        % Value changed function: FreqInternalHzEditField
        function FreqInternalHzEditFieldValueChanged(app, event)
            value = app.FreqInternalHzEditField.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['FREQINT ' num2str(value)]);
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
            % Device
            config.deviceModel = 'sr865';
            config.deviceIdentification = app.DeviceIdendificationEditField.Value;
            
            % Reference and Phase
                % Time Base 10 MHz
                config.TimeBaseAutoButton = app.TimeBaseAutoButton.Value;
                config.TimeBaseInternalButton = app.TimeBaseInternalButton.Value;
                                
                % Phase Value
                config.PhaseEditField = app.PhaseEditField.Value;

                % Frequency Internal
                config.FreqInternalHzEditField = app.FreqInternalHzEditField.Value;
                
                % Frequency Exernal
                config.FreqExternalHzEditFieldValue = app.FreqExternalHzEditField.Value;
                config.FreqExternalHzEditFieldBackgroundColor = app.FreqExternalHzEditField.BackgroundColor;
                config.FreqExternalHzEditFieldEditable = app.FreqExternalHzEditField.Editable;
                
                % Harmonic
                config.HarmonicEditField = app.HarmonicEditField.Value;
                
                % Chopper Wheel Number of Blades
                config.ChopperWheel6SlotButton = app.ChopperWheel6SlotButton.Value;
                config.ChopperWheel30SlotButton = app.ChopperWheel30SlotButton.Value;
                
                % Chopper Wheel Phase
                config.ChopperWheelPhaseEditField = app.ChopperWheelPhaseEditField.Value;
                
                % Internal Reference Amplitude
                config.AmplitudeVEditField = app.AmplitudeVEditField.Value;
                
                % Internal Reference DC Level
                config.DCLevelVEditField = app.DCLevelVEditField.Value;
                
                % Internal Reference DC Mode
                config.CommonButton = app.CommonButton.Value;
                config.DifferenceButton = app.DifferenceButton.Value;
                
                % Reference
                config.InternalButton = app.InternalButton.Value;
                config.ExternalButton = app.ExternalButton.Value;
                config.DualButton = app.DualButton.Value;
                config.ChopButton = app.ChopButton.Value;
                
                % External Reference Trigger Slope
                config.SineButton = app.SineButton.Value;
                config.PosEdgeButton = app.PosEdgeButton.Value;
                config.NegEdgeButton = app.NegEdgeButton.Value;
                
                % External Reference Trigger Input Impedance
                config.OhmsButton = app.OhmsButton.Value;
                config.MOhmsButton = app.MOhmsButton.Value;

            % Signal Commands
                % Voltage or Current Mode
                config.VoltageButton = app.VoltageButton.Value;
                config.CurrentButton = app.CurrentButton.Value;
                
                % Voltage Input Mode
                config.AButton = app.AButton.Value;
                config.ABButton = app.ABButton.Value;
                
                % Voltage Input Coupling
                config.ACButton = app.ACButton.Value;
                config.DCButton = app.DCButton.Value;
                
                % Voltage Input Shielding
                config.FloatButton = app.FloatButton.Value;
                config.GroundButton = app.GroundButton.Value;
                
                % Range
                config.InputRangeDropDown = app.InputRangeDropDown.Value;
                
                % Current Input Mode
                config.I1E6Button = app.I1E6Button.Value;
                config.I1E8Button = app.I1E8Button.Value;
                
            % Gain and Time Constant
                % Sensitivity
                config.DropDown = app.DropDown.Value;
                
                % Time Constant
                config.TimeConstantDropDown = app.TimeConstantDropDown.Value;
                
                % Low Pass Filter
                config.LowPassFilterDropDown = app.LowPassFilterDropDown.Value;
                
                % Synchronous Filter
                config.SyncCheckBox = app.SyncCheckBox.Value;
                
                % Advance Filter
                config.AdvancedCheckBox = app.AdvancedCheckBox.Value;

            % Channel 1 and Channel 2 Outputs
                % Channel 1 Output
                config.CH1OutputXButton = app.CH1OutputXButton.Value;
                config.CH1OutputRButton = app.CH1OutputRButton.Value;
                
                % Channel 2 Output
                config.CH2OutputYButton = app.CH2OutputYButton.Value;
                config.CH2OutputThetaButton = app.CH2OutputThetaButton.Value;
                
                % Channel 1 Expand
                config.CH1Outputx1Button = app.CH1Outputx1Button.Value;
                config.CH1Outputx10Button = app.CH1Outputx10Button.Value;
                config.CH1Outputx100Button = app.CH1Outputx100Button.Value;
                
                % Channel 2 Expand
                config.CH2Outputx1ButtonValue = app.CH2Outputx1Button.Value;
                config.CH2Outputx10ButtonValue = app.CH2Outputx10Button.Value;
                config.CH2Outputx100Button.Value = app.CH2Outputx100Button.Value;
                config.CH2Outputx1ButtonEnable = app.CH2Outputx1Button.Enable;
                config.CH2Outputx10ButtonEnable = app.CH2Outputx10Button.Enable;
                config.CH2Outputx100ButtonEnable = app.CH2Outputx100Button.Enable;

                % Channel 1 Offset
                config.CH1OutputOffsetOffButton = app.CH1OutputOffsetOffButton.Value;
                config.CH1OutputOffsetOnButton = app.CH1OutputOffsetOnButton.Value;
                
                % Channel 2 Offset
                config.CH2OutputOffsetOffButton = app.CH2OutputOffsetOffButton.Value;
                config.CH2OutputOffsetOnButton = app.CH2OutputOffsetOnButton.Value;
                
                % Channel 1 Offset Value
                config.CH1OutputOffsetValueEditFieldValue = app.CH1OutputOffsetValueEditField.Value;
                config.CH1OutputOffsetValueEditFieldEditable = app.CH1OutputOffsetValueEditField.Editable;

                % Channel 2 Offset Value
                config.CH2OutputOffsetValueEditFieldValue = app.CH2OutputOffsetValueEditField.Value;
                config.CH2OutputOffsetValueEditFieldEditable = app.CH2OutputOffsetValueEditField.Editable;
                
                % Channel 2 Offset Auto Button
                config.CH1OutputOffsetAutoButton = app.CH1OutputOffsetAutoButton.Enable;
                
                % Channel 2 Offset Auto Button
                config.CH2OutputOffsetAutoButton = app.CH2OutputOffsetAutoButton.Enable;
                
                % Channel 1 Ratio
                config.CH1OutputRatioOffButtonValue = app.CH1OutputRatioOffButton.Value;
                config.CH1OutputRatioOnButtonValue = app.CH1OutputRatioOnButton.Value;
                config.CH1OutputRatioOffButtonEnable = app.CH1OutputRatioOffButton.Enable;
                config.CH1OutputRatioOnButtonEnable = app.CH1OutputRatioOnButton.Enable;
                
                % Channel 2 Ratio
                config.CH2OutputRatioOffButtonValue = app.CH2OutputRatioOffButton.Value;
                config.CH2OutputRatioOnButtonValue = app.CH2OutputRatioOnButton.Value;
                config.CH2OutputRatioOffButtonEnable = app.CH2OutputRatioOffButton.Enable;
                config.CH2OutputRatioOnButtonEnable = app.CH2OutputRatioOnButton.Enable;
            
            % Auxiliar Outputs
                % Auxiliar Output 1
                config.Output1EditField = app.Output1EditField.Value;
                
                % Auxiliar Output 2
                config.Output2EditField = app.Output2EditField.Value;
                
                % Auxiliar Output 3
                config.Output3EditField = app.Output3EditField.Value;
                
                % Auxiliar Output 4
                config.Output4EditField = app.Output4EditField.Value;
                
            % Display Commands
                % Panel Banking
                config.BlankPanelBlankingCheckBox = app.BlankPanelBlankingCheckBox.Value;
                
                % Data Channel 1 (Green)
                config.DisplayGreenDropDown = app.DisplayGreenDropDown.Value;
                
                % Data Channel 2 (Blue)
                config.DisplayBlueDropDown = app.DisplayYellowDropDown.Value;
                
                % Data Channel 3 (Yellow)
                config.DisplayYellowDropDown = app.DisplayBlueDropDown.Value;
                
                % Data Channel 4 (Orange)
                config.DisplayOrangeDropDown = app.DisplayOrangeDropDown.Value;

            % Plot Buttons
            config.TIMEAQUISITIONINTERVAL = app.TIMEAQUISITIONINTERVAL;
            config.IntervalsSpinner = app.IntervalsSpinner.Value;
            config.TIMEAQUISITIONTOTAL = app.TIMEAQUISITIONTOTAL;
            config.TimesSpinner = app.TimesSpinner.Value;
            
            % Create File Name
            uisave('config','Configuration_SR865');
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            
             % Get File
            [file,path] = uigetfile('*.mat');
            filepath = fullfile(path,file);
            if isequal(file,0)
               return;
            end
            
            load(filepath);
            
            % Device
            if contains(lower(config.deviceModel),'sr865')
                
                if contains(config.deviceIdentification,app.DeviceIdendificationEditField.Value)
                    condition = true;
                else
                    msg = 'This configuration was obtained from a different SR865. Would you like to proceed?';
                    title = 'Confirm';
                    answer = questdlg(msg, ...
                    	              title, ...
                    	              'Yes','No','No');
                    % Handle response
                    switch answer
                        case 'Yes'
                            condition = true;
                        case 'No'
                            condition = false;
                    end
                end
                
                if condition
                    
                    % Reference and Phase
                        % Time Base 10 MHz
                        app.TimeBaseAutoButton.Value = config.TimeBaseAutoButton;
                        app.TimeBaseInternalButton.Value = config.TimeBaseInternalButton;
                                        
                        % Phase Value
                        app.PhaseEditField.Value = config.PhaseEditField;
        
                        % Frequency Internal
                        app.FreqInternalHzEditField.Value = config.FreqInternalHzEditField;
                        
                        % Frequency Exernal
                        app.FreqExternalHzEditField.Value = config.FreqExternalHzEditFieldValue;
                        app.FreqExternalHzEditField.BackgroundColor = config.FreqExternalHzEditFieldBackgroundColor;
                        app.FreqExternalHzEditField.Editable = config.FreqExternalHzEditFieldEditable;
                        
                        % Harmonic
                        app.HarmonicEditField.Value = config.HarmonicEditField;
                        
                        % Chopper Wheel Number of Blades
                        app.ChopperWheel6SlotButton.Value = config.ChopperWheel6SlotButton;
                        app.ChopperWheel30SlotButton.Value = config.ChopperWheel30SlotButton;
                        
                        % Chopper Wheel Phase
                        app.ChopperWheelPhaseEditField.Value = config.ChopperWheelPhaseEditField;
                        
                        % Internal Reference Amplitude
                        app.AmplitudeVEditField.Value = config.AmplitudeVEditField;
                        
                        % Internal Reference DC Level
                        app.DCLevelVEditField.Value = config.DCLevelVEditField;
                        
                        % Internal Reference DC Mode
                        app.CommonButton.Value = config.CommonButton;
                        app.DifferenceButton.Value = config.DifferenceButton;
                        
                        % Reference
                        app.InternalButton.Value = config.InternalButton;
                        app.ExternalButton.Value = config.ExternalButton;
                        app.DualButton.Value = config.DualButton;
                        app.ChopButton.Value = config.ChopButton;
                        
                        % External Reference Trigger Slope
                        app.SineButton.Value = config.SineButton;
                        app.PosEdgeButton.Value = config.PosEdgeButton;
                        app.NegEdgeButton.Value = config.NegEdgeButton;
                        
                        % External Reference Trigger Input Impedance
                        app.OhmsButton.Value = config.OhmsButton;
                        app.MOhmsButton.Value = config.MOhmsButton;
        
                    % Signal Commands
                        % Voltage or Current Mode
                        app.VoltageButton.Value = config.VoltageButton;
                        app.CurrentButton.Value = config.CurrentButton;
                        
                        % Voltage Input Mode
                        app.AButton.Value = config.AButton;
                        app.ABButton.Value = config.ABButton;
                        
                        % Voltage Input Coupling
                        app.ACButton.Value = config.ACButton;
                        app.DCButton.Value = config.DCButton;
                        
                        % Voltage Input Shielding
                        app.FloatButton.Value = config.FloatButton;
                        app.GroundButton.Value = config.GroundButton;
                        
                        % Range
                        app.InputRangeDropDown.Value = config.InputRangeDropDown;
                        
                        % Current Input Mode
                        app.I1E6Button.Value = config.I1E6Button;
                        app.I1E8Button.Value = config.I1E8Button;
                        
                    % Gain and Time Constant
                        % Sensitivity
                        app.DropDown.Value = config.DropDown;
                        
                        % Time Constant
                        app.TimeConstantDropDown.Value = config.TimeConstantDropDown;
                        
                        % Low Pass Filter
                        app.LowPassFilterDropDown.Value = config.LowPassFilterDropDown;
                        
                        % Synchronous Filter
                        app.SyncCheckBox.Value = config.SyncCheckBox;
                        
                        % Advance Filter
                        app.AdvancedCheckBox.Value = config.AdvancedCheckBox;
        
                    % Channel 1 and Channel 2 Outputs
                        % Channel 1 Output
                        app.CH1OutputXButton.Value = config.CH1OutputXButton;
                        app.CH1OutputRButton.Value = config.CH1OutputRButton;
                        
                        % Channel 2 Output
                        app.CH2OutputYButton.Value = config.CH2OutputYButton;
                        app.CH2OutputThetaButton.Value = config.CH2OutputThetaButton;
                        
                        % Channel 1 Expand
                        app.CH1Outputx1Button.Value = config.CH1Outputx1Button;
                        app.CH1Outputx10Button.Value = config.CH1Outputx10Button;
                        app.CH1Outputx100Button.Value = config.CH1Outputx100Button;
                        
                        % Channel 2 Expand
                        app.CH2Outputx1Button.Value = config.CH2Outputx1ButtonValue;
                        app.CH2Outputx10Button.Value = config.CH2Outputx10ButtonValue;
                        app.CH2Outputx100Button.Value = config.CH2Outputx100Button.Value;
                        app.CH2Outputx1Button.Enable = config.CH2Outputx1ButtonEnable;
                        app.CH2Outputx10Button.Enable = config.CH2Outputx10ButtonEnable;
                        app.CH2Outputx100Button.Enable = config.CH2Outputx100ButtonEnable;
        
                        % Channel 1 Offset
                        app.CH1OutputOffsetOffButton.Value = config.CH1OutputOffsetOffButton;
                        app.CH1OutputOffsetOnButton.Value = config.CH1OutputOffsetOnButton;
                        
                        % Channel 2 Offset
                        app.CH2OutputOffsetOffButton.Value = config.CH2OutputOffsetOffButton;
                        app.CH2OutputOffsetOnButton.Value = config.CH2OutputOffsetOnButton;
                        
                        % Channel 1 Offset Value
                        app.CH1OutputOffsetValueEditField.Value = config.CH1OutputOffsetValueEditFieldValue;
                        app.CH1OutputOffsetValueEditField.Editable = config.CH1OutputOffsetValueEditFieldEditable;
        
                        % Channel 2 Offset Value
                        app.CH2OutputOffsetValueEditField.Value = config.CH2OutputOffsetValueEditFieldValue;
                        app.CH2OutputOffsetValueEditField.Editable = config.CH2OutputOffsetValueEditFieldEditable;
                        
                        % Channel 2 Offset Auto Button
                        app.CH1OutputOffsetAutoButton.Enable = config.CH1OutputOffsetAutoButton;
                        
                        % Channel 2 Offset Auto Button
                        app.CH2OutputOffsetAutoButton.Enable = config.CH2OutputOffsetAutoButton;
                        
                        % Channel 1 Ratio
                        config.CH1OutputRatioOffButtonValue = app.CH1OutputRatioOffButton.Value;
                        config.CH1OutputRatioOnButtonValue = app.CH1OutputRatioOnButton.Value;
                        config.CH1OutputRatioOffButtonEnable = app.CH1OutputRatioOffButton.Enable;
                        config.CH1OutputRatioOnButtonEnable = app.CH1OutputRatioOnButton.Enable;
                        
                        % Channel 2 Ratio
                        app.CH2OutputRatioOffButton.Value = config.CH2OutputRatioOffButtonValue;
                        app.CH2OutputRatioOnButton.Value = config.CH2OutputRatioOnButtonValue;
                        app.CH2OutputRatioOffButton.Enable = config.CH2OutputRatioOffButtonEnable;
                        app.CH2OutputRatioOnButton.Enable = config.CH2OutputRatioOnButtonEnable;
                    
                    % Auxiliar Outputs
                        % Auxiliar Output 1
                        app.Output1EditField.Value = config.Output1EditField;
                        
                        % Auxiliar Output 2
                        app.Output2EditField.Value = config.Output2EditField;
                        
                        % Auxiliar Output 3
                        app.Output3EditField.Value = config.Output3EditField;
                        
                        % Auxiliar Output 4
                        app.Output4EditField.Value = config.Output4EditField;
                        
                    % Display Commands
                        % Panel Banking
                        app.BlankPanelBlankingCheckBox.Value = config.BlankPanelBlankingCheckBox;
                        
                        % Data Channel 1 (Green)
                        app.DisplayGreenDropDown.Value = config.DisplayGreenDropDown;
                        
                        % Data Channel 2 (Blue)
                        app.DisplayYellowDropDown.Value = config.DisplayBlueDropDown;
                        
                        % Data Channel 3 (Yellow)
                        app.DisplayBlueDropDown.Value = config.DisplayYellowDropDown;
                        
                        % Data Channel 4 (Orange)
                        app.DisplayOrangeDropDown.Value = config.DisplayOrangeDropDown;
                       
                    % Plot Buttons
                    app.TIMEAQUISITIONINTERVAL = config.TIMEAQUISITIONINTERVAL;
                    app.IntervalsSpinner.Value = config.IntervalsSpinner;
                    app.TIMEAQUISITIONTOTAL = config.TIMEAQUISITIONTOTAL;
                    app.TimesSpinner.Value = config.TimesSpinner;
                    
                end
            end
            
            % Signal Strengh Indicator
            fprintf(app.portObject,'ILVL?');
            answer = fscanf(app.portObject);
            app.InputRangeSignalStrength.Value = str2double(answer(1:end-1));   % Remove terminator caracter.
            
            % Check Overload
            app.lockinLIASStatus();
        end

        % Value changed function: ConnectDeviceButton
        function ConnectDeviceButtonValueChanged(app, event)
            value = app.ConnectDeviceButton.Value;
            
            if value
                app.ConnectDeviceButton.Text = {'Disconnect' 'Device'};
                app.ConnectDeviceButton.Enable = false;
                % Open the options dialog and pass inputs
                app.lockinConnectApp = lockinConnect(app,'SR865');
                
                app.ConnectDeviceButton.Enable = true;

                if isempty(app.lockinConnectApp)
                    app.ConnectDeviceButton.Value = false;
                    app.ConnectDeviceButton.Text = {'Connect' 'Device'};
                end
            else
                app.ConnectDeviceButton.Text = {'Connect' 'Device'};
                
                % If timer is running, stop it
                if strcmp(app.dataTimer.Running, 'on')
                    stop(app.dataTimer);
                end
                
                % If Device Connected
                if app.isconnected
                    fclose(app.portObject);
                end
            end
            
        end

        % Value changed function: DisplayGreenDropDown
        function DisplayGreenDropDownValueChanged(app, event)
            value = app.DisplayGreenDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['CDSP 0,' value]);
        end

        % Value changed function: DisplayBlueDropDown
        function DisplayBlueDropDownValueChanged(app, event)
            value = app.DisplayBlueDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['CDSP 1,' value]);
        end

        % Value changed function: DisplayYellowDropDown
        function DisplayYellowDropDownValueChanged(app, event)
            value = app.DisplayYellowDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['CDSP 2,' value]);
        end

        % Value changed function: DisplayOrangeDropDown
        function DisplayOrangeDropDownValueChanged(app, event)
            value = app.DisplayOrangeDropDown.Value;
            
            flushinput(app.portObject);                   % Remove data from port input buffer.
            fprintf(app.portObject,['CDSP 3,' value]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StanfordResearchSystemsModelSR865AUIFigure = uifigure('Visible', 'off');
            app.StanfordResearchSystemsModelSR865AUIFigure.Name = 'Stanford Research Systems Model SR865A';
            app.StanfordResearchSystemsModelSR865AUIFigure.CloseRequestFcn = createCallbackFcn(app, @StanfordResearchSystemsModelSR865AUIFigureCloseRequest, true);
            app.SensitivityPanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.SensitivityPanel.Title = 'Sensitivity';
            app.DropDown = uidropdown(app.SensitivityPanel);
            app.DropDown.Items = {'1 V/ uA', '500 mV / nA', '200 mV / nA', '100 mV / nA', '50 mV / nA', '20 mV / nA', '10 mV / nA', '5 mV / nA', '2 mV / nA', '1 mV / nA', '500 uV / pA', '200 uV / pA', '100 uV / pA', '50 uV / pA', '20 uV / pA', '10 uV / pA', '5 uV / pA', '2 uV / pA', '1 uV / pA', '500 nV / fA', '200 nV / fA', '100 nV / fA', '50 nV / fA', '20 nV / fA', '10 nV / fA', '5 nV / fA', '2 nV / fA', '1 nV / fA'};
            app.DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = '0';
            app.TimeConstantPanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.TimeConstantPanel.Title = 'Time Constant';
            app.TimeConstantDropDown = uidropdown(app.TimeConstantPanel);
            app.TimeConstantDropDown.Items = {'1 us', '3 us', '10 us', '30 us', '100 us', '300 us', '1 ms', '3 ms', '10 ms', '30 ms', '100 ms', '300 ms', '1 s', '3 s', '10 s', '30 s', '100 s', '300 s', '1 ks', '3 ks', '10 ks', '30 ks'};
            app.TimeConstantDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.TimeConstantDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeConstantDropDownValueChanged, true);
            app.TimeConstantDropDown.Enable = 'off';
            app.TimeConstantDropDown.Value = '10';
            app.InputRangePanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.InputRangePanel.Title = 'Input Range';
            app.InputRangeDropDown = uidropdown(app.InputRangePanel);
            app.InputRangeDropDown.Items = {'1 V', '300 mV', '100 mV', '30 mV', '10 mV'};
            app.InputRangeDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.InputRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputRangeDropDownValueChanged, true);
            app.InputRangeDropDown.Enable = 'off';
            app.InputRangeDropDown.Value = '0';
            app.InputRangeSignalStrength = uigauge(app.InputRangePanel, 'linear');
            app.InputRangeSignalStrength.Limits = [0 4];
            app.InputRangeSignalStrength.MajorTicks = [0 1 2 3 4];
            app.InputRangeSignalStrength.MinorTicks = [0 1 2 3 4];
            app.OverloadLamp_3Label_3 = uilabel(app.InputRangePanel);
            app.OverloadLamp_3Label_3.Text = 'Overload';
            app.InputRangeOverloadLamp = uilamp(app.InputRangePanel);
            app.VoltagePanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.VoltagePanel.Title = 'Voltage';
            app.InputButtonGroup = uibuttongroup(app.VoltagePanel);
            app.InputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputButtonGroupSelectionChanged, true);
            app.InputButtonGroup.Title = 'Input';
            app.AButton = uiradiobutton(app.InputButtonGroup);
            app.AButton.Enable = 'off';
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.ABButton = uiradiobutton(app.InputButtonGroup);
            app.ABButton.Enable = 'off';
            app.ABButton.Text = 'A-B';
            app.CouplingButtonGroup = uibuttongroup(app.VoltagePanel);
            app.CouplingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CouplingButtonGroupSelectionChanged, true);
            app.CouplingButtonGroup.Title = 'Coupling';
            app.ACButton = uiradiobutton(app.CouplingButtonGroup);
            app.ACButton.Enable = 'off';
            app.ACButton.Text = 'AC';
            app.ACButton.Value = true;
            app.DCButton = uiradiobutton(app.CouplingButtonGroup);
            app.DCButton.Enable = 'off';
            app.DCButton.Text = 'DC';
            app.ShieldGroundingButtonGroup = uibuttongroup(app.VoltagePanel);
            app.ShieldGroundingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShieldGroundingButtonGroupSelectionChanged, true);
            app.ShieldGroundingButtonGroup.Title = 'Shield Grounding';
            app.FloatButton = uiradiobutton(app.ShieldGroundingButtonGroup);
            app.FloatButton.Enable = 'off';
            app.FloatButton.Text = 'Float';
            app.FloatButton.Value = true;
            app.GroundButton = uiradiobutton(app.ShieldGroundingButtonGroup);
            app.GroundButton.Enable = 'off';
            app.GroundButton.Text = 'Ground';
            app.InputButtonGroup_3 = uibuttongroup(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.InputButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @InputButtonGroup_3SelectionChanged, true);
            app.InputButtonGroup_3.Title = 'Input';
            app.VoltageButton = uiradiobutton(app.InputButtonGroup_3);
            app.VoltageButton.Enable = 'off';
            app.VoltageButton.Text = 'Voltage';
            app.VoltageButton.Value = true;
            app.CurrentButton = uiradiobutton(app.InputButtonGroup_3);
            app.CurrentButton.Enable = 'off';
            app.CurrentButton.Text = 'Current';
            app.CurrentButtonGroup = uibuttongroup(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.CurrentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CurrentButtonGroupSelectionChanged, true);
            app.CurrentButtonGroup.Title = 'Current';
            app.I1E6Button = uiradiobutton(app.CurrentButtonGroup);
            app.I1E6Button.Enable = 'off';
            app.I1E6Button.Text = 'I (1E6)';
            app.I1E6Button.Value = true;
            app.I1E8Button = uiradiobutton(app.CurrentButtonGroup);
            app.I1E8Button.Enable = 'off';
            app.I1E8Button.Text = 'I (1E8)';
            app.FiltersPanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.FiltersPanel.Title = 'Filters';
            app.LowPassFilterDropDown = uidropdown(app.FiltersPanel);
            app.LowPassFilterDropDown.Items = {'6 dB / oct', '12 dB / oct', '18 dB / oct', '24 dB / oct'};
            app.LowPassFilterDropDown.ItemsData = {'0', '1', '2', '3'};
            app.LowPassFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterDropDownValueChanged, true);
            app.LowPassFilterDropDown.Enable = 'off';
            app.LowPassFilterDropDown.Value = '1';
            app.SyncCheckBox = uicheckbox(app.FiltersPanel);
            app.SyncCheckBox.ValueChangedFcn = createCallbackFcn(app, @SyncCheckBoxValueChanged, true);
            app.SyncCheckBox.Enable = 'off';
            app.SyncCheckBox.Text = 'Sync';
            app.AdvancedCheckBox = uicheckbox(app.FiltersPanel);
            app.AdvancedCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedCheckBoxValueChanged, true);
            app.AdvancedCheckBox.Enable = 'off';
            app.AdvancedCheckBox.Text = 'Advanced';
            app.OvldSyncLabel = uilabel(app.FiltersPanel);
            app.OvldSyncLabel.Text = 'Ovld. Sync.';
            app.SyncOverloadLamp = uilamp(app.FiltersPanel);
            app.DisplayPanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.DisplayPanel.Title = 'Display';
            app.DisplayGreenEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DisplayGreenEditField.Editable = 'off';
            app.DisplayBlueEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DisplayBlueEditField.Editable = 'off';
            app.DisplayYellowEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DisplayYellowEditField.Editable = 'off';
            app.DisplayOrangeEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DisplayOrangeEditField.Editable = 'off';
            app.DisplayGreenDropDown = uidropdown(app.DisplayPanel);
            app.DisplayGreenDropDown.Items = {'X output', 'Y output', 'R output', 'Theta output', 'Auxiliar Input 1', 'Auxiliar Input 2', 'Auxiliar Input 3', 'Auxiliar Input 4', 'X noise', 'Y noise', 'Auxiliar Output 1', 'Auxiliar Output 2', 'Reference Phase', 'Sine Out Amplitude', 'DC Level', 'Internal Reference Frequency', 'External Reference Frequency'};
            app.DisplayGreenDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.DisplayGreenDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayGreenDropDownValueChanged, true);
            app.DisplayGreenDropDown.Value = '0';
            app.DisplayBlueDropDown = uidropdown(app.DisplayPanel);
            app.DisplayBlueDropDown.Items = {'X output', 'Y output', 'R output', 'Theta output', 'Auxiliar Input 1', 'Auxiliar Input 2', 'Auxiliar Input 3', 'Auxiliar Input 4', 'X noise', 'Y noise', 'Auxiliar Output 1', 'Auxiliar Output 2', 'Reference Phase', 'Sine Out Amplitude', 'DC Level', 'Internal Reference Frequency', 'External Reference Frequency'};
            app.DisplayBlueDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.DisplayBlueDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayBlueDropDownValueChanged, true);
            app.DisplayBlueDropDown.Value = '1';
            app.DisplayYellowDropDown = uidropdown(app.DisplayPanel);
            app.DisplayYellowDropDown.Items = {'X output', 'Y output', 'R output', 'Theta output', 'Auxiliar Input 1', 'Auxiliar Input 2', 'Auxiliar Input 3', 'Auxiliar Input 4', 'X noise', 'Y noise', 'Auxiliar Output 1', 'Auxiliar Output 2', 'Reference Phase', 'Sine Out Amplitude', 'DC Level', 'Internal Reference Frequency', 'External Reference Frequency'};
            app.DisplayYellowDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.DisplayYellowDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayYellowDropDownValueChanged, true);
            app.DisplayYellowDropDown.Value = '2';
            app.DisplayOrangeDropDown = uidropdown(app.DisplayPanel);
            app.DisplayOrangeDropDown.Items = {'X output', 'Y output', 'R output', 'Theta output', 'Auxiliar Input 1', 'Auxiliar Input 2', 'Auxiliar Input 3', 'Auxiliar Input 4', 'X noise', 'Y noise', 'Auxiliar Output 1', 'Auxiliar Output 2', 'Reference Phase', 'Sine Out Amplitude', 'DC Level', 'Internal Reference Frequency', 'External Reference Frequency'};
            app.DisplayOrangeDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.DisplayOrangeDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayOrangeDropDownValueChanged, true);
            app.DisplayOrangeDropDown.Value = '3';
            app.BlankPanelBlankingCheckBox = uicheckbox(app.DisplayPanel);
            app.BlankPanelBlankingCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlankPanelBlankingCheckBoxValueChanged, true);
            app.BlankPanelBlankingCheckBox.Enable = 'off';
            app.BlankPanelBlankingCheckBox.Text = 'Blank (Panel Blanking)';
            app.OverloadGreenLamp = uilamp(app.DisplayPanel);
            app.OverloadYellowLamp = uilamp(app.DisplayPanel);
            app.OverloadBlueLamp = uilamp(app.DisplayPanel);
            app.OverloadOrangeLamp = uilamp(app.DisplayPanel);
            app.AutoPanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.AutoPanel.Title = 'Auto';
            app.PhaseButton = uibutton(app.AutoPanel, 'push');
            app.PhaseButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseButtonPushed, true);
            app.PhaseButton.Enable = 'off';
            app.PhaseButton.Text = 'Phase';
            app.ScaleButton = uibutton(app.AutoPanel, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Enable = 'off';
            app.ScaleButton.Text = 'Scale';
            app.RangeButton = uibutton(app.AutoPanel, 'push');
            app.RangeButton.ButtonPushedFcn = createCallbackFcn(app, @RangeButtonPushed, true);
            app.RangeButton.Enable = 'off';
            app.RangeButton.Text = 'Range';
            app.LampPhase = uilamp(app.AutoPanel);
            app.LampScale = uilamp(app.AutoPanel);
            app.LampRange = uilamp(app.AutoPanel);
            app.AuxOutPanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.AuxOutPanel.Title = 'Aux Out';
            app.Output2EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output2EditFieldLabel.Text = 'Output 2';
            app.Output2EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output2EditField.Limits = [-10.5 10.5];
            app.Output2EditField.ValueDisplayFormat = '%11.5g';
            app.Output2EditField.ValueChangedFcn = createCallbackFcn(app, @Output2EditFieldValueChanged, true);
            app.Output2EditField.Editable = 'off';
            app.Output3EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output3EditFieldLabel.Text = 'Output 3';
            app.Output3EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output3EditField.Limits = [-10.5 10.5];
            app.Output3EditField.ValueDisplayFormat = '%11.5g';
            app.Output3EditField.ValueChangedFcn = createCallbackFcn(app, @Output3EditFieldValueChanged, true);
            app.Output3EditField.Editable = 'off';
            app.Output4EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output4EditFieldLabel.Text = 'Output 4';
            app.Output4EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output4EditField.Limits = [-10.5 10.5];
            app.Output4EditField.ValueDisplayFormat = '%11.5g';
            app.Output4EditField.ValueChangedFcn = createCallbackFcn(app, @Output4EditFieldValueChanged, true);
            app.Output4EditField.Editable = 'off';
            app.Output1EditFieldLabel = uilabel(app.AuxOutPanel);
            app.Output1EditFieldLabel.Text = 'Output 1';
            app.Output1EditField = uieditfield(app.AuxOutPanel, 'numeric');
            app.Output1EditField.Limits = [-10.5 10.5];
            app.Output1EditField.ValueDisplayFormat = '%11.5g';
            app.Output1EditField.ValueChangedFcn = createCallbackFcn(app, @Output1EditFieldValueChanged, true);
            app.Output1EditField.Editable = 'off';
            app.DeviceIdendificationEditField = uieditfield(app.StanfordResearchSystemsModelSR865AUIFigure, 'text');
            app.DeviceIdendificationEditField.Editable = 'off';
            app.ReferencePanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.ReferencePanel.Title = 'Reference';
            app.TriggerButtonGroup = uibuttongroup(app.ReferencePanel);
            app.TriggerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TriggerButtonGroupSelectionChanged, true);
            app.TriggerButtonGroup.Title = 'Trigger';
            app.SineButton = uiradiobutton(app.TriggerButtonGroup);
            app.SineButton.Enable = 'off';
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.PosEdgeButton = uiradiobutton(app.TriggerButtonGroup);
            app.PosEdgeButton.Enable = 'off';
            app.PosEdgeButton.Text = 'Pos. Edge';
            app.NegEdgeButton = uiradiobutton(app.TriggerButtonGroup);
            app.NegEdgeButton.Enable = 'off';
            app.NegEdgeButton.Text = 'Neg. Edge';
            app.SourceButtonGroup = uibuttongroup(app.ReferencePanel);
            app.SourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceButtonGroupSelectionChanged, true);
            app.SourceButtonGroup.Title = 'Source';
            app.InternalButton = uiradiobutton(app.SourceButtonGroup);
            app.InternalButton.Enable = 'off';
            app.InternalButton.Text = 'Internal';
            app.InternalButton.Value = true;
            app.ExternalButton = uiradiobutton(app.SourceButtonGroup);
            app.ExternalButton.Enable = 'off';
            app.ExternalButton.Text = 'External';
            app.DualButton = uiradiobutton(app.SourceButtonGroup);
            app.DualButton.Enable = 'off';
            app.DualButton.Text = 'Dual';
            app.ChopButton = uiradiobutton(app.SourceButtonGroup);
            app.ChopButton.Enable = 'off';
            app.ChopButton.Text = 'Chop';
            app.PhaseLabel = uilabel(app.ReferencePanel);
            app.PhaseLabel.Text = 'Phase(º)';
            app.PhaseEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.PhaseEditField.Limits = [-360000 360000];
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.PhaseEditField.Editable = 'off';
            app.FreqExternalHzEditFieldLabel = uilabel(app.ReferencePanel);
            app.FreqExternalHzEditFieldLabel.Text = 'Freq. External (Hz)';
            app.FreqExternalHzEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.FreqExternalHzEditField.Limits = [0.001 4000000];
            app.FreqExternalHzEditField.Editable = 'off';
            app.FreqExternalHzEditField.Value = 225;
            app.AmplitudeVEditFieldLabel = uilabel(app.ReferencePanel);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.AmplitudeVEditField.Limits = [1e-09 2];
            app.AmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditFieldValueChanged, true);
            app.AmplitudeVEditField.Editable = 'off';
            app.AmplitudeVEditField.Value = 1;
            app.HarmonicEditFieldLabel = uilabel(app.ReferencePanel);
            app.HarmonicEditFieldLabel.Text = 'Harmonic';
            app.HarmonicEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.HarmonicEditField.Limits = [1 99];
            app.HarmonicEditField.RoundFractionalValues = 'on';
            app.HarmonicEditField.ValueDisplayFormat = '%.0f';
            app.HarmonicEditField.ValueChangedFcn = createCallbackFcn(app, @HarmonicEditFieldValueChanged, true);
            app.HarmonicEditField.Editable = 'off';
            app.HarmonicEditField.Value = 1;
            app.InputResistorButtonGroup = uibuttongroup(app.ReferencePanel);
            app.InputResistorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputResistorButtonGroupSelectionChanged, true);
            app.InputResistorButtonGroup.Title = 'Input Resistor';
            app.OhmsButton = uiradiobutton(app.InputResistorButtonGroup);
            app.OhmsButton.Enable = 'off';
            app.OhmsButton.Text = '50 Ohms';
            app.OhmsButton.Value = true;
            app.MOhmsButton = uiradiobutton(app.InputResistorButtonGroup);
            app.MOhmsButton.Enable = 'off';
            app.MOhmsButton.Text = '1 MOhms';
            app.DCLevelVEditFieldLabel = uilabel(app.ReferencePanel);
            app.DCLevelVEditFieldLabel.Text = 'DC Level (V)';
            app.DCLevelVEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.DCLevelVEditField.Limits = [-5 5];
            app.DCLevelVEditField.ValueChangedFcn = createCallbackFcn(app, @DCLevelVEditFieldValueChanged, true);
            app.DCLevelVEditField.Editable = 'off';
            app.DCLevelVEditField.Value = 1;
            app.DCLevelButtonGroup = uibuttongroup(app.ReferencePanel);
            app.DCLevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DCLevelButtonGroupSelectionChanged, true);
            app.DCLevelButtonGroup.Title = 'DC Level';
            app.DifferenceButton = uiradiobutton(app.DCLevelButtonGroup);
            app.DifferenceButton.Enable = 'off';
            app.DifferenceButton.Text = 'Difference';
            app.DifferenceButton.Value = true;
            app.CommonButton = uiradiobutton(app.DCLevelButtonGroup);
            app.CommonButton.Enable = 'off';
            app.CommonButton.Text = 'Common';
            app.ChopperBladeButtonGroup = uibuttongroup(app.ReferencePanel);
            app.ChopperBladeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChopperBladeButtonGroupSelectionChanged, true);
            app.ChopperBladeButtonGroup.Title = 'Chopper Blade';
            app.ChopperWheel6SlotButton = uiradiobutton(app.ChopperBladeButtonGroup);
            app.ChopperWheel6SlotButton.Enable = 'off';
            app.ChopperWheel6SlotButton.Text = '6 slot';
            app.ChopperWheel6SlotButton.Value = true;
            app.ChopperWheel30SlotButton = uiradiobutton(app.ChopperBladeButtonGroup);
            app.ChopperWheel30SlotButton.Enable = 'off';
            app.ChopperWheel30SlotButton.Text = '30 slot';
            app.ChopperWheelPhaseLabel = uilabel(app.ChopperBladeButtonGroup);
            app.ChopperWheelPhaseLabel.Text = 'Phase(º)';
            app.ChopperWheelPhaseEditField = uieditfield(app.ChopperBladeButtonGroup, 'numeric');
            app.ChopperWheelPhaseEditField.Limits = [-360000 360000];
            app.ChopperWheelPhaseEditField.ValueChangedFcn = createCallbackFcn(app, @ChopperWheelPhaseEditFieldValueChanged, true);
            app.ChopperWheelPhaseEditField.Editable = 'off';
            app.TimeBaseButtonGroup = uibuttongroup(app.ReferencePanel);
            app.TimeBaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeBaseButtonGroupSelectionChanged, true);
            app.TimeBaseButtonGroup.Title = 'Time Base';
            app.TimeBaseAutoButton = uiradiobutton(app.TimeBaseButtonGroup);
            app.TimeBaseAutoButton.Enable = 'off';
            app.TimeBaseAutoButton.Text = 'Auto';
            app.TimeBaseAutoButton.Value = true;
            app.TimeBaseInternalButton = uiradiobutton(app.TimeBaseButtonGroup);
            app.TimeBaseInternalButton.Enable = 'off';
            app.TimeBaseInternalButton.Text = 'Internal';
            app.FreqInternalHzEditFieldLabel = uilabel(app.ReferencePanel);
            app.FreqInternalHzEditFieldLabel.Text = 'Freq. Internal (Hz)';
            app.FreqInternalHzEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.FreqInternalHzEditField.Limits = [0.001 4000000];
            app.FreqInternalHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqInternalHzEditFieldValueChanged, true);
            app.FreqInternalHzEditField.Editable = 'off';
            app.FreqInternalHzEditField.Value = 225;
            app.UIAxesLeft = uiaxes(app.StanfordResearchSystemsModelSR865AUIFigure);
            title(app.UIAxesLeft, '')
            xlabel(app.UIAxesLeft, '')
            ylabel(app.UIAxesLeft, '')
            app.ChannelOutput1Panel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.ChannelOutput1Panel.Title = 'Channel Output 1';
            app.CH1OutputOutputButtonGroup = uibuttongroup(app.ChannelOutput1Panel);
            app.CH1OutputOutputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH1OutputOutputButtonGroupSelectionChanged, true);
            app.CH1OutputOutputButtonGroup.Title = 'Output';
            app.CH1OutputXButton = uiradiobutton(app.CH1OutputOutputButtonGroup);
            app.CH1OutputXButton.Enable = 'off';
            app.CH1OutputXButton.Text = 'X';
            app.CH1OutputXButton.Value = true;
            app.CH1OutputRButton = uiradiobutton(app.CH1OutputOutputButtonGroup);
            app.CH1OutputRButton.Enable = 'off';
            app.CH1OutputRButton.Text = 'R';
            app.CH1OutputRatioButtonGroup = uibuttongroup(app.ChannelOutput1Panel);
            app.CH1OutputRatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH1OutputRatioButtonGroupSelectionChanged, true);
            app.CH1OutputRatioButtonGroup.Title = 'Ratio';
            app.CH1OutputRatioOffButton = uiradiobutton(app.CH1OutputRatioButtonGroup);
            app.CH1OutputRatioOffButton.Enable = 'off';
            app.CH1OutputRatioOffButton.Text = 'Off';
            app.CH1OutputRatioOffButton.Value = true;
            app.CH1OutputRatioOnButton = uiradiobutton(app.CH1OutputRatioButtonGroup);
            app.CH1OutputRatioOnButton.Enable = 'off';
            app.CH1OutputRatioOnButton.Text = 'On';
            app.CH1OutputExpandButtonGroup = uibuttongroup(app.ChannelOutput1Panel);
            app.CH1OutputExpandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH1OutputExpandButtonGroupSelectionChanged, true);
            app.CH1OutputExpandButtonGroup.Title = 'Expand';
            app.CH1Outputx1Button = uiradiobutton(app.CH1OutputExpandButtonGroup);
            app.CH1Outputx1Button.Enable = 'off';
            app.CH1Outputx1Button.Text = 'x 1';
            app.CH1Outputx1Button.Value = true;
            app.CH1Outputx10Button = uiradiobutton(app.CH1OutputExpandButtonGroup);
            app.CH1Outputx10Button.Enable = 'off';
            app.CH1Outputx10Button.Text = 'x 10';
            app.CH1Outputx100Button = uiradiobutton(app.CH1OutputExpandButtonGroup);
            app.CH1Outputx100Button.Enable = 'off';
            app.CH1Outputx100Button.Text = 'x 100';
            app.CH1OutputOffsetButtonGroup = uibuttongroup(app.ChannelOutput1Panel);
            app.CH1OutputOffsetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH1OutputOffsetButtonGroupSelectionChanged, true);
            app.CH1OutputOffsetButtonGroup.Title = 'Offset';
            app.CH1OutputOffsetOnButton = uiradiobutton(app.CH1OutputOffsetButtonGroup);
            app.CH1OutputOffsetOnButton.Enable = 'off';
            app.CH1OutputOffsetOnButton.Text = 'On';
            app.CH1OutputOffsetOffButton = uiradiobutton(app.CH1OutputOffsetButtonGroup);
            app.CH1OutputOffsetOffButton.Enable = 'off';
            app.CH1OutputOffsetOffButton.Text = 'Off';
            app.CH1OutputOffsetOffButton.Value = true;
            app.OffsetCH1ModifyEditFieldLabel = uilabel(app.CH1OutputOffsetButtonGroup);
            app.OffsetCH1ModifyEditFieldLabel.Enable = 'off';
            app.OffsetCH1ModifyEditFieldLabel.Text = 'Offset (%)';
            app.CH1OutputOffsetValueEditField = uieditfield(app.CH1OutputOffsetButtonGroup, 'numeric');
            app.CH1OutputOffsetValueEditField.Limits = [-105 105];
            app.CH1OutputOffsetValueEditField.ValueChangedFcn = createCallbackFcn(app, @CH1OutputOffsetValueEditFieldValueChanged, true);
            app.CH1OutputOffsetValueEditField.Enable = 'off';
            app.OverloadLamp_3Label = uilabel(app.ChannelOutput1Panel);
            app.OverloadLamp_3Label.Text = 'Overload';
            app.CH1OutputOverloadLamp = uilamp(app.ChannelOutput1Panel);
            app.CH1OutputOffsetAutoButton = uibutton(app.ChannelOutput1Panel, 'push');
            app.CH1OutputOffsetAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CH1OutputOffsetAutoButtonPushed, true);
            app.CH1OutputOffsetAutoButton.Enable = 'off';
            app.CH1OutputOffsetAutoButton.Text = 'Auto';
            app.ChannelOutput2Panel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.ChannelOutput2Panel.Title = 'Channel Output 2';
            app.CH2OutputOutputButtonGroup = uibuttongroup(app.ChannelOutput2Panel);
            app.CH2OutputOutputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH2OutputOutputButtonGroupSelectionChanged, true);
            app.CH2OutputOutputButtonGroup.Title = 'Output';
            app.CH2OutputYButton = uiradiobutton(app.CH2OutputOutputButtonGroup);
            app.CH2OutputYButton.Enable = 'off';
            app.CH2OutputYButton.Text = 'Y';
            app.CH2OutputYButton.Value = true;
            app.CH2OutputThetaButton = uiradiobutton(app.CH2OutputOutputButtonGroup);
            app.CH2OutputThetaButton.Enable = 'off';
            app.CH2OutputThetaButton.Text = 'Theta';
            app.CH2OutputRatioButtonGroup = uibuttongroup(app.ChannelOutput2Panel);
            app.CH2OutputRatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH2OutputRatioButtonGroupSelectionChanged, true);
            app.CH2OutputRatioButtonGroup.Title = 'Ratio';
            app.CH2OutputRatioOffButton = uiradiobutton(app.CH2OutputRatioButtonGroup);
            app.CH2OutputRatioOffButton.Enable = 'off';
            app.CH2OutputRatioOffButton.Text = 'Off';
            app.CH2OutputRatioOffButton.Value = true;
            app.CH2OutputRatioOnButton = uiradiobutton(app.CH2OutputRatioButtonGroup);
            app.CH2OutputRatioOnButton.Enable = 'off';
            app.CH2OutputRatioOnButton.Text = 'On';
            app.CH2OutputExpandButtonGroup = uibuttongroup(app.ChannelOutput2Panel);
            app.CH2OutputExpandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH2OutputExpandButtonGroupSelectionChanged, true);
            app.CH2OutputExpandButtonGroup.Title = 'Expand';
            app.CH2Outputx1Button = uiradiobutton(app.CH2OutputExpandButtonGroup);
            app.CH2Outputx1Button.Enable = 'off';
            app.CH2Outputx1Button.Text = 'x 1';
            app.CH2Outputx1Button.Value = true;
            app.CH2Outputx10Button = uiradiobutton(app.CH2OutputExpandButtonGroup);
            app.CH2Outputx10Button.Enable = 'off';
            app.CH2Outputx10Button.Text = 'x 10';
            app.CH2Outputx100Button = uiradiobutton(app.CH2OutputExpandButtonGroup);
            app.CH2Outputx100Button.Enable = 'off';
            app.CH2Outputx100Button.Text = 'x 100';
            app.CH2OutputOffsetButtonGroup = uibuttongroup(app.ChannelOutput2Panel);
            app.CH2OutputOffsetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH2OutputOffsetButtonGroupSelectionChanged, true);
            app.CH2OutputOffsetButtonGroup.Title = 'Offset';
            app.CH2OutputOffsetOnButton = uiradiobutton(app.CH2OutputOffsetButtonGroup);
            app.CH2OutputOffsetOnButton.Enable = 'off';
            app.CH2OutputOffsetOnButton.Text = 'On';
            app.CH2OutputOffsetOnButton.Value = true;
            app.CH2OutputOffsetOffButton = uiradiobutton(app.CH2OutputOffsetButtonGroup);
            app.CH2OutputOffsetOffButton.Enable = 'off';
            app.CH2OutputOffsetOffButton.Text = 'Off';
            app.OffsetCH1ModifyEditFieldLabel_2 = uilabel(app.CH2OutputOffsetButtonGroup);
            app.OffsetCH1ModifyEditFieldLabel_2.Enable = 'off';
            app.OffsetCH1ModifyEditFieldLabel_2.Text = 'Offset (%)';
            app.CH2OutputOffsetValueEditField = uieditfield(app.CH2OutputOffsetButtonGroup, 'numeric');
            app.CH2OutputOffsetValueEditField.Limits = [-105 105];
            app.CH2OutputOffsetValueEditField.ValueChangedFcn = createCallbackFcn(app, @CH2OutputOffsetValueEditFieldValueChanged, true);
            app.CH2OutputOffsetValueEditField.Enable = 'off';
            app.OverloadLamp_3Label_2 = uilabel(app.ChannelOutput2Panel);
            app.OverloadLamp_3Label_2.Text = 'Overload';
            app.CH2OutputOverloadLamp = uilamp(app.ChannelOutput2Panel);
            app.CH2OutputOffsetAutoButton = uibutton(app.ChannelOutput2Panel, 'push');
            app.CH2OutputOffsetAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CH2OutputOffsetAutoButtonPushed, true);
            app.CH2OutputOffsetAutoButton.Enable = 'off';
            app.CH2OutputOffsetAutoButton.Text = 'Auto';
            app.UIAxesRight = uiaxes(app.StanfordResearchSystemsModelSR865AUIFigure);
            title(app.UIAxesRight, '')
            xlabel(app.UIAxesRight, '')
            ylabel(app.UIAxesRight, '')
            app.ForceUpdateButton = uibutton(app.StanfordResearchSystemsModelSR865AUIFigure, 'push');
            app.ForceUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ForceUpdateButtonPushed, true);
            app.ForceUpdateButton.Enable = 'off';
            app.ForceUpdateButton.Text = {'Force'; 'Update'};
            app.SaveConfigButton = uibutton(app.StanfordResearchSystemsModelSR865AUIFigure, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Enable = 'off';
            app.SaveConfigButton.Text = {'Save'; 'Config.'};
            app.LoadConfigButton = uibutton(app.StanfordResearchSystemsModelSR865AUIFigure, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Enable = 'off';
            app.LoadConfigButton.Text = {'Load'; 'Config.'};
            app.AquisitionPlotPanel = uipanel(app.StanfordResearchSystemsModelSR865AUIFigure);
            app.AquisitionPlotPanel.Title = 'Aquisition / Plot';
            app.StartButton = uibutton(app.AquisitionPlotPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AquisitionPlotPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.CleanButton = uibutton(app.AquisitionPlotPanel, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Enable = 'off';
            app.CleanButton.Text = 'Clean';
            app.TimesSpinnerLabel = uilabel(app.AquisitionPlotPanel);
            app.TimesSpinnerLabel.Enable = 'off';
            app.TimesSpinnerLabel.Text = 'Time(s)';
            app.TimesSpinner = uispinner(app.AquisitionPlotPanel);
            app.TimesSpinner.Limits = [10 3200];
            app.TimesSpinner.RoundFractionalValues = 'on';
            app.TimesSpinner.ValueDisplayFormat = '%.0f';
            app.TimesSpinner.ValueChangedFcn = createCallbackFcn(app, @TimesSpinnerValueChanged, true);
            app.TimesSpinner.Editable = 'off';
            app.TimesSpinner.Enable = 'off';
            app.TimesSpinner.Tooltip = {'Time internal between data points.'};
            app.TimesSpinner.Value = 60;
            app.IntervalsSpinnerLabel = uilabel(app.AquisitionPlotPanel);
            app.IntervalsSpinnerLabel.Enable = 'off';
            app.IntervalsSpinnerLabel.Text = 'Interval (s)';
            app.IntervalsSpinner = uispinner(app.AquisitionPlotPanel);
            app.IntervalsSpinner.Step = 0.1;
            app.IntervalsSpinner.Limits = [0.1 60];
            app.IntervalsSpinner.ValueChangedFcn = createCallbackFcn(app, @IntervalsSpinnerValueChanged, true);
            app.IntervalsSpinner.Editable = 'off';
            app.IntervalsSpinner.Enable = 'off';
            app.IntervalsSpinner.Tooltip = {'Time internal  between data points.'};
            app.IntervalsSpinner.Value = 0.3;
            app.ConnectDeviceButton = uibutton(app.StanfordResearchSystemsModelSR865AUIFigure, 'state');
            app.ConnectDeviceButton.ValueChangedFcn = createCallbackFcn(app, @ConnectDeviceButtonValueChanged, true);
            app.ConnectDeviceButton.Text = {'Connect'; 'Device'};
            % Show the figure after all components are created
            app.StanfordResearchSystemsModelSR865AUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lockinSR865

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StanfordResearchSystemsModelSR865AUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StanfordResearchSystemsModelSR865AUIFigure)
        end
    end
end